classdef AOGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AOIUIFigure                     matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadCondifurationsMenu          matlab.ui.container.Menu
        SaveConfigurationsMenu          matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        DebugMenu                       matlab.ui.container.Menu
        DeactivatePeripheralsMenu       matlab.ui.container.Menu
        ExtraFiguresMenu                matlab.ui.container.Menu
        PMTSaturationInspector          matlab.ui.container.Menu
        ScanAssistantMenu               matlab.ui.container.Menu
        FindMyinstrumentPortMenu        matlab.ui.container.Menu
        InstrumentsMenu                 matlab.ui.container.Menu
        StagesMenu                      matlab.ui.container.Menu
        ScanningAssistantMenu           matlab.ui.container.Menu
        AFGMenu                         matlab.ui.container.Menu
        OscilloscopeMenu                matlab.ui.container.Menu
        ResetInstrumentsCommunicationMenu  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        AcoustoOpticSystemLabel         matlab.ui.control.Label
        ScanPlotTab                     matlab.ui.container.TabGroup
        MainAxisTab                     matlab.ui.container.Tab
        axMainAxis                      matlab.ui.control.UIAxes
        MainAxisLogTab                  matlab.ui.container.Tab
        axMainAxisLog                   matlab.ui.control.UIAxes
        MainPlaneTab                    matlab.ui.container.Tab
        axMainPlane                     matlab.ui.control.UIAxes
        MainPlaneLogTab                 matlab.ui.container.Tab
        axMainPlaneLog                  matlab.ui.control.UIAxes
        NavigatorTab                    matlab.ui.container.Tab
        axNav                           matlab.ui.control.UIAxes
        MainAxisAveragedTab             matlab.ui.container.Tab
        axMainAxisAvg                   matlab.ui.control.UIAxes
        MainPlaneAveragedTab            matlab.ui.container.Tab
        axMainPlaneAvg                  matlab.ui.control.UIAxes
        NavigatorAveragedTab            matlab.ui.container.Tab
        axNavAvg                        matlab.ui.control.UIAxes
        DeepViewPlotTab                 matlab.ui.container.Tab
        axDeepView                      matlab.ui.control.UIAxes
        ConsistencyPlotTab              matlab.ui.container.Tab
        axConsistency                   matlab.ui.control.UIAxes
        CurrentFrameTab                 matlab.ui.container.Tab
        axCurFrame                      matlab.ui.control.UIAxes
        FramesNavigatorTab              matlab.ui.container.Tab
        axFrameNav                      matlab.ui.control.UIAxes
        BPTab                           matlab.ui.container.Tab
        axOABP                          matlab.ui.control.UIAxes
        SinogramTab                     matlab.ui.container.Tab
        axOASinogram                    matlab.ui.control.UIAxes
        OANavSinogramTab                matlab.ui.container.Tab
        axOANavSinogram                 matlab.ui.control.UIAxes
        OANavBPTab                      matlab.ui.container.Tab
        axOANavBP                       matlab.ui.control.UIAxes
        PEUSRawTab                      matlab.ui.container.Tab
        axPEUSRaw                       matlab.ui.control.UIAxes
        PEUSCorrectedTab                matlab.ui.container.Tab
        axPEUSCorrected                 matlab.ui.control.UIAxes
        CorrectedOATab                  matlab.ui.container.Tab
        axCorrectedOA                   matlab.ui.control.UIAxes
        SimulationSavePanel             matlab.ui.container.Panel
        MeasNameLabel                   matlab.ui.control.Label
        MeasNameEditField               matlab.ui.control.EditField
        PathLabel                       matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        saveResultsandVarsCheckBox      matlab.ui.control.CheckBox
        saveFigsCheckBox                matlab.ui.control.CheckBox
        saveRawDataCheckBox             matlab.ui.control.CheckBox
        saveNetSignalCheckBox           matlab.ui.control.CheckBox
        saveDeMultiplexedCheckBox       matlab.ui.control.CheckBox
        saveReshapedCheckBox            matlab.ui.control.CheckBox
        saveFFTCheckBox                 matlab.ui.control.CheckBox
        SavedDataSpecificationsLabel    matlab.ui.control.Label
        saveUSCmplxCheckBox             matlab.ui.control.CheckBox
        dirBrowseButton                 matlab.ui.control.Button
        SaveCurrentMeasButton           matlab.ui.control.Button
        MeasMethod                      matlab.ui.container.TabGroup
        SingleMeasurementTab            matlab.ui.container.Tab
        AORunButton                     matlab.ui.control.Button
        AOLiveRunButton                 matlab.ui.control.Button
        StagesPanel_3                   matlab.ui.container.Panel
        mmLabel_17                      matlab.ui.control.Label
        mmLabel_18                      matlab.ui.control.Label
        XpositionEditField_2Label       matlab.ui.control.Label
        AOXpositionEditField            matlab.ui.control.NumericEditField
        YpositionEditField_2Label       matlab.ui.control.Label
        AOYpositionEditField            matlab.ui.control.NumericEditField
        AOMeasHereCheckBox              matlab.ui.control.CheckBox
        LiveAcoustoOpticsPanel          matlab.ui.container.Panel
        LimitLiveAOCheckBox             matlab.ui.control.CheckBox
        NEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        ImportsavedResultsPanel         matlab.ui.container.Panel
        DataEditFieldLabel              matlab.ui.control.Label
        DataEditField                   matlab.ui.control.EditField
        analysisDataPathButton          matlab.ui.control.Button
        AnalyzeOrigButton               matlab.ui.control.Button
        VarsEditFieldLabel              matlab.ui.control.Label
        VarsEditField                   matlab.ui.control.EditField
        analysisVarsPathButton          matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        AnalyizeNewButton               matlab.ui.control.Button
        UseVirtualDataCheckBox          matlab.ui.control.CheckBox
        NoiesStdEditFieldLabel          matlab.ui.control.Label
        NoiesStdEditField               matlab.ui.control.NumericEditField
        ImportVarsButton                matlab.ui.control.Button
        ExportVarsButton                matlab.ui.control.Button
        ReCalcDataButton                matlab.ui.control.Button
        Scan2DTab                       matlab.ui.container.Tab
        S2DGridPanel                    matlab.ui.container.Panel
        S2DFirstScanAxisLabel           matlab.ui.control.Label
        AxisDropDownLabel_2             matlab.ui.control.Label
        S2DFirstAxisDropDown            matlab.ui.control.DropDown
        mmLabel_20                      matlab.ui.control.Label
        StartEditFieldLabel_2           matlab.ui.control.Label
        S2DFirstStartEditField          matlab.ui.control.NumericEditField
        mmLabel_21                      matlab.ui.control.Label
        StrideEditFieldLabel_2          matlab.ui.control.Label
        S2DFirstStrideEditField         matlab.ui.control.NumericEditField
        mmLabel_22                      matlab.ui.control.Label
        EndEditFieldLabel_2             matlab.ui.control.Label
        S2DFirstEndEditField            matlab.ui.control.NumericEditField
        SecondAxisLabel                 matlab.ui.control.Label
        AxisDropDown_2Label_2           matlab.ui.control.Label
        S2DSecondAxisDropDown           matlab.ui.control.DropDown
        mmLabel_23                      matlab.ui.control.Label
        PosLabel                        matlab.ui.control.Label
        S2DSecondPosEditField           matlab.ui.control.NumericEditField
        DontmovenonscanningstagesCheckBox  matlab.ui.control.CheckBox
        ThirdAxisLabel                  matlab.ui.control.Label
        mmLabel_52                      matlab.ui.control.Label
        PosLabel_6                      matlab.ui.control.Label
        S2DThirdPosEditField            matlab.ui.control.NumericEditField
        AxisDropDown_2Label_4           matlab.ui.control.Label
        S2DThirdAxisDropDown            matlab.ui.control.DropDown
        S2DRunButton                    matlab.ui.control.Button
        S2DRepeatsPanel                 matlab.ui.container.Panel
        RepeatsEditFieldLabel_2         matlab.ui.control.Label
        S2DRepeatsEditField             matlab.ui.control.NumericEditField
        Scan3DTab                       matlab.ui.container.Tab
        s3DGridPanel                    matlab.ui.container.Panel
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        mmLabel_7                       matlab.ui.control.Label
        mmLabel_8                       matlab.ui.control.Label
        mmLabel_9                       matlab.ui.control.Label
        StartEditFieldLabel             matlab.ui.control.Label
        S3DFirstStartEditField          matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        S3DFirstEndEditField            matlab.ui.control.NumericEditField
        StartEditField_2Label           matlab.ui.control.Label
        S3DSecondStartEditField         matlab.ui.control.NumericEditField
        EndEditField_2Label             matlab.ui.control.Label
        S3DSecondEndEditField           matlab.ui.control.NumericEditField
        StrideEditFieldLabel            matlab.ui.control.Label
        S3DFirstStrideEditField         matlab.ui.control.NumericEditField
        StrideEditField_2Label          matlab.ui.control.Label
        S3DSecondStrideEditField        matlab.ui.control.NumericEditField
        AxisDropDownLabel               matlab.ui.control.Label
        S3DFirstAxisDropDown            matlab.ui.control.DropDown
        S3DFirstScanAxisLabel           matlab.ui.control.Label
        S3DSecondScanAxisLabel          matlab.ui.control.Label
        AxisDropDown_2Label             matlab.ui.control.Label
        S3DSecondAxisDropDown           matlab.ui.control.DropDown
        S3DSecondScanAxisLabel_2        matlab.ui.control.Label
        AxisDropDownLabel_4             matlab.ui.control.Label
        S3DThirdAxisDropDown            matlab.ui.control.DropDown
        PosEditFieldLabel               matlab.ui.control.Label
        S3DThirdPosEditField            matlab.ui.control.NumericEditField
        mmLabel_53                      matlab.ui.control.Label
        S3DDontMoveNonScanninStages     matlab.ui.control.CheckBox
        S3DRunButton                    matlab.ui.control.Button
        S3DRepeatsPanel                 matlab.ui.container.Panel
        RepeatsEditFieldLabel           matlab.ui.control.Label
        S3DRepeatsEditField             matlab.ui.control.NumericEditField
        S3DImportResultsButton          matlab.ui.control.Button
        S3DExportScanVarsButton         matlab.ui.control.Button
        S3DImportScanVarsButton         matlab.ui.control.Button
        S3DImportLimitsfromfileButton   matlab.ui.control.Button
        OptoAcousticsTab                matlab.ui.container.Tab
        OARunScanButton                 matlab.ui.control.Button
        OARunSingleButton               matlab.ui.control.Button
        StartSinglePosEditFieldLabel    matlab.ui.control.Label
        StartSinglePosEditField         matlab.ui.control.NumericEditField
        StrideEditFieldLabel_5          matlab.ui.control.Label
        StrideEditField                 matlab.ui.control.NumericEditField
        EndEditFieldLabel_5             matlab.ui.control.Label
        EndEditField                    matlab.ui.control.NumericEditField
        FirstAxisEditFieldLabel         matlab.ui.control.Label
        FirstAxisEditField              matlab.ui.control.EditField
        SecondAxisEditFieldLabel        matlab.ui.control.Label
        SecondAxisEditField             matlab.ui.control.EditField
        ScanAxisEditFieldLabel          matlab.ui.control.Label
        ScanAxisEditField               matlab.ui.control.EditField
        OARunLiveButton                 matlab.ui.control.Button
        mmLabel_47                      matlab.ui.control.Label
        mmLabel_48                      matlab.ui.control.Label
        mmLabel_49                      matlab.ui.control.Label
        PulseEchoUSTab                  matlab.ui.container.Tab
        DistancefromImageCenterLabel    matlab.ui.control.Label
        PEUSDistancefromImageCenterEditField  matlab.ui.control.NumericEditField
        ImageWidthEditField_2Label      matlab.ui.control.Label
        PEUSImageWidthEditField         matlab.ui.control.NumericEditField
        cmLabel_4                       matlab.ui.control.Label
        mmLabel_50                      matlab.ui.control.Label
        StartEditField_2Label_2         matlab.ui.control.Label
        PEUSStartEditField              matlab.ui.control.NumericEditField
        StrideEditField_2Label_2        matlab.ui.control.Label
        PEUSStrideEditField             matlab.ui.control.NumericEditField
        EndEditField_2Label_2           matlab.ui.control.Label
        PEUSEndEditField                matlab.ui.control.NumericEditField
        ScanAxisEditField_3Label        matlab.ui.control.Label
        PEUSScanAxisEditField           matlab.ui.control.EditField
        DepthAxisEditFieldLabel         matlab.ui.control.Label
        PEUSDepthAxisEditField          matlab.ui.control.EditField
        ThirdsAxisEditFieldLabel        matlab.ui.control.Label
        PEUSThirdsAxisEditField         matlab.ui.control.EditField
        mmLabel_43                      matlab.ui.control.Label
        mmLabel_44                      matlab.ui.control.Label
        mmLabel_45                      matlab.ui.control.Label
        PEUSRunScanButton               matlab.ui.control.Button
        PEUSDontmovesecondscanaxisCheckBox  matlab.ui.control.CheckBox
        IntSystemTab                    matlab.ui.container.Tab
        AOScanAxisEditFieldLabel        matlab.ui.control.Label
        AOScanAxisEditField             matlab.ui.control.EditField
        AODepthAxisEditFieldLabel       matlab.ui.control.Label
        AODepthAxisEditField            matlab.ui.control.EditField
        OAScanAxisEditFieldLabel        matlab.ui.control.Label
        OAScanAxisEditField             matlab.ui.control.EditField
        AOScanStartEditFieldLabel       matlab.ui.control.Label
        AOScanStartEditField            matlab.ui.control.NumericEditField
        AOScanStrideEditFieldLabel      matlab.ui.control.Label
        AOScanStrideEditField           matlab.ui.control.NumericEditField
        AOScanEndEditFieldLabel         matlab.ui.control.Label
        AOScanEndEditField              matlab.ui.control.NumericEditField
        OAScanStartEditFieldLabel       matlab.ui.control.Label
        OAScanStartEditField            matlab.ui.control.NumericEditField
        OAScanStrideEditFieldLabel      matlab.ui.control.Label
        OAScanStrideEditField           matlab.ui.control.NumericEditField
        OAScanEndEditFieldLabel         matlab.ui.control.Label
        OAScanEndEditField              matlab.ui.control.NumericEditField
        ImageWidthEditField_2Label_2    matlab.ui.control.Label
        IntSysImageWidthEditField       matlab.ui.control.NumericEditField
        DistanceFromImageCenterLabel    matlab.ui.control.Label
        IntSysDistanceFromImageCenterEditField  matlab.ui.control.NumericEditField
        cmLabel_6                       matlab.ui.control.Label
        cmLabel_7                       matlab.ui.control.Label
        RunIntImagingButton             matlab.ui.control.Button
        ConsistencyTab                  matlab.ui.container.Tab
        RunButtonConsistency            matlab.ui.control.Button
        StagesPanel_2                   matlab.ui.container.Panel
        mmLabel_10                      matlab.ui.control.Label
        mmLabel_11                      matlab.ui.control.Label
        XpositionEditFieldLabel         matlab.ui.control.Label
        XpositionEditField              matlab.ui.control.NumericEditField
        YpositionEditFieldLabel         matlab.ui.control.Label
        YpositionEditField              matlab.ui.control.NumericEditField
        TimeFramePropertiesPanel        matlab.ui.container.Panel
        sLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        MaxTimeFrameDurationEditFieldLabel  matlab.ui.control.Label
        MaxTimeFrameDurationEditField   matlab.ui.control.NumericEditField
        MinTimeFrameDurationEditFieldLabel  matlab.ui.control.Label
        MinTimeFrameDurationEditField   matlab.ui.control.NumericEditField
        TimeStrideEditFieldLabel        matlab.ui.control.Label
        TimeStrideEditField             matlab.ui.control.NumericEditField
        SetsperTimeFrameEditFieldLabel  matlab.ui.control.Label
        SetsperTimeFrameEditField       matlab.ui.control.NumericEditField
        ImportResultsButton_2           matlab.ui.control.Button
        ExportScanVarsButton_2          matlab.ui.control.Button
        ImportScanVarsButton_2          matlab.ui.control.Button
        FiguresPanel_2                  matlab.ui.container.Panel
        FrameToPlotSpinnerLabel         matlab.ui.control.Label
        FrameToPlotSpinner              matlab.ui.control.Spinner
        SettoplotSpinnerLabel           matlab.ui.control.Label
        SettoplotSpinner                matlab.ui.control.Spinner
        ZCoorSpinnerLabel               matlab.ui.control.Label
        ZCoorConsSpinner                matlab.ui.control.Spinner
        sLabel_6                        matlab.ui.control.Label
        mmLabel_15                      matlab.ui.control.Label
        DeepViewTab                     matlab.ui.container.Tab
        DVGridPanel                     matlab.ui.container.Panel
        DVFirstScanAxisLabel            matlab.ui.control.Label
        AxisDropDownLabel_3             matlab.ui.control.Label
        DVFirstAxisDropDown             matlab.ui.control.DropDown
        mmLabel_25                      matlab.ui.control.Label
        StartEditFieldLabel_3           matlab.ui.control.Label
        DVFirstStartEditField           matlab.ui.control.NumericEditField
        mmLabel_26                      matlab.ui.control.Label
        StrideEditFieldLabel_3          matlab.ui.control.Label
        DVFirstStrideEditField          matlab.ui.control.NumericEditField
        mmLabel_27                      matlab.ui.control.Label
        EndEditFieldLabel_3             matlab.ui.control.Label
        DVFirstEndEditField             matlab.ui.control.NumericEditField
        DVSecondScanAxisLabel           matlab.ui.control.Label
        AxisDropDown_2Label_3           matlab.ui.control.Label
        DVSecondAxisDropDown            matlab.ui.control.DropDown
        mmLabel_28                      matlab.ui.control.Label
        PosLabel_2                      matlab.ui.control.Label
        DVSecondPosEditField            matlab.ui.control.NumericEditField
        DVFiguresPanel                  matlab.ui.container.Panel
        mmLabel_29                      matlab.ui.control.Label
        DepthCoorMainAxisLabel_4        matlab.ui.control.Label
        DVCurDepthCoorSpinner           matlab.ui.control.Spinner
        TimePanel                       matlab.ui.container.Panel
        sLabel_7                        matlab.ui.control.Label
        StartEditFieldLabel_4           matlab.ui.control.Label
        DVTimeStartEditField            matlab.ui.control.NumericEditField
        sLabel_8                        matlab.ui.control.Label
        StrideEditFieldLabel_4          matlab.ui.control.Label
        DVTimeStrideEditField           matlab.ui.control.NumericEditField
        sLabel_9                        matlab.ui.control.Label
        EndEditFieldLabel_4             matlab.ui.control.Label
        DVTimeEndEditField              matlab.ui.control.NumericEditField
        DVRunButton                     matlab.ui.control.Button
        Tab_2                           matlab.ui.container.Tab
        GlobalParameters                matlab.ui.container.TabGroup
        SignalExcitationTab             matlab.ui.container.Tab
        SamplingPanel                   matlab.ui.container.Panel
        MHzLabel_2                      matlab.ui.control.Label
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        secLabel                        matlab.ui.control.Label
        TimetosampleEditFieldLabel      matlab.ui.control.Label
        TimetosampleEditField           matlab.ui.control.NumericEditField
        secLabel_2                      matlab.ui.control.Label
        FrameTimeEditFieldLabel         matlab.ui.control.Label
        FrameTimeEditField              matlab.ui.control.NumericEditField
        secLabel_3                      matlab.ui.control.Label
        MeasurementtimeimitEditFieldLabel  matlab.ui.control.Label
        MeasurementtimeimitEditField    matlab.ui.control.NumericEditField
        Label_18                        matlab.ui.control.Label
        NumberOfChannelsEditFieldLabel  matlab.ui.control.Label
        NumberOfChannelsEditField       matlab.ui.control.NumericEditField
        UltrasoundSignalPropertiesPanel  matlab.ui.container.Panel
        MHzLabel                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        KHzLabel_3                      matlab.ui.control.Label
        SineFreqEditFieldLabel          matlab.ui.control.Label
        SineFreqEditField               matlab.ui.control.NumericEditField
        SquenceFreqEditFieldLabel       matlab.ui.control.Label
        SquenceFreqEditField            matlab.ui.control.NumericEditField
        CyclesPerPulseEditFieldLabel    matlab.ui.control.Label
        CyclesPerPulseEditField         matlab.ui.control.NumericEditField
        Label_19                        matlab.ui.control.Label
        USPowerEditFieldLabel           matlab.ui.control.Label
        USPowerEditField                matlab.ui.control.NumericEditField
        GeometryPanel                   matlab.ui.container.Panel
        TransducerdistanceLabel         matlab.ui.control.Label
        TransducerdistanceEditField     matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        SpeedinMediumEditFieldLabel     matlab.ui.control.Label
        SpeedinMediumEditField          matlab.ui.control.NumericEditField
        cmLabel_3                       matlab.ui.control.Label
        GeneralOnlineOperationPanel     matlab.ui.container.Panel
        UseTimeFrameCheckBox            matlab.ui.control.CheckBox
        HadamardCodeCheckBox            matlab.ui.control.CheckBox
        HighResAOCheckBox               matlab.ui.control.CheckBox
        SeparateChCheckBox              matlab.ui.control.CheckBox
        ContSpeckleCheckBox             matlab.ui.control.CheckBox
        ContHadamardCheckBox            matlab.ui.control.CheckBox
        UseGPUCheckBox                  matlab.ui.control.CheckBox
        ACCouplingCheckBox              matlab.ui.control.CheckBox
        AutoCalibrationCheckBox         matlab.ui.control.CheckBox
        CalcMuEffCheckBox               matlab.ui.control.CheckBox
        UseCalibrationCheckBox          matlab.ui.control.CheckBox
        FittingEnviromentsPanel         matlab.ui.container.Panel
        DCEditFieldLabel                matlab.ui.control.Label
        envDC                           matlab.ui.control.NumericEditField
        USEditFieldLabel                matlab.ui.control.Label
        envUS                           matlab.ui.control.NumericEditField
        KHzLabel_6                      matlab.ui.control.Label
        KHzLabel_7                      matlab.ui.control.Label
        QuickPlotControlPanel           matlab.ui.container.Panel
        SignalChannelSpinnerLabel       matlab.ui.control.Label
        SignalChannelSpinner            matlab.ui.control.Spinner
        FrameLabel                      matlab.ui.control.Label
        FrameAOSpinner                  matlab.ui.control.Spinner
        SeparateChannelSpinnerLabel     matlab.ui.control.Label
        SeparateChannelSpinner          matlab.ui.control.Spinner
        mmLabelPlotPos                  matlab.ui.control.Label
        DepthCoorLabel                  matlab.ui.control.Label
        AODepthIdxSpinner               matlab.ui.control.Spinner
        ArtifactsPanel                  matlab.ui.container.Panel
        CutArtifactsCheckBox            matlab.ui.control.CheckBox
        IndexesEditFieldLabel           matlab.ui.control.Label
        IndexesEditField                matlab.ui.control.EditField
        OfflineCutButton                matlab.ui.control.Button
        CalibrationPanel                matlab.ui.container.Panel
        DurationEditFieldLabel          matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        secLabel_4                      matlab.ui.control.Label
        CalibrateButton                 matlab.ui.control.Button
        MuEffCalculationPanel           matlab.ui.container.Panel
        CalculateButton                 matlab.ui.control.Button
        IndexesEditField_2Label         matlab.ui.control.Label
        muEffIndexesEditField           matlab.ui.control.EditField
        ConfigurationDropDownLabel      matlab.ui.control.Label
        ConfigurationDropDown           matlab.ui.control.DropDown
        PeripheralsTab                  matlab.ui.container.Tab
        FunctionGeneratorPanel          matlab.ui.container.Panel
        SamplesClkFreqEditFieldLabel    matlab.ui.control.Label
        SamplesClkFreqEditField         matlab.ui.control.NumericEditField
        MSsLabel                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        DutyCycleEditFieldLabel         matlab.ui.control.Label
        DutyCycleEditField              matlab.ui.control.NumericEditField
        TurnOffAFGButton                matlab.ui.control.Button
        CloseIOPortButton               matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        DAQPanel                        matlab.ui.container.Panel
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        SamplingRateEditField           matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        sLabel_10                       matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        SamplesEditFieldLabel           matlab.ui.control.Label
        SamplesEditField                matlab.ui.control.NumericEditField
        AveragesEditFieldLabel          matlab.ui.control.Label
        AveragesEditField               matlab.ui.control.NumericEditField
        FramesEditFieldLabel            matlab.ui.control.Label
        FramesEditField                 matlab.ui.control.NumericEditField
        DelayEditFieldLabel             matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        OsciloscopePanel                matlab.ui.container.Panel
        ChannelNoEditFieldLabel         matlab.ui.control.Label
        PEUSChannelNoEditField          matlab.ui.control.NumericEditField
        TrigChannelEditFieldLabel       matlab.ui.control.Label
        PEUSTrigChannelEditField        matlab.ui.control.NumericEditField
        NumofAvgEditFieldLabel          matlab.ui.control.Label
        PEUSNumofAvgEditField           matlab.ui.control.NumericEditField
        RepetitionRateEditFieldLabel    matlab.ui.control.Label
        PEUSRepetitionRateEditField     matlab.ui.control.NumericEditField
        IntValEditFieldLabel            matlab.ui.control.Label
        PEUSIntValEditField             matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        BackProjectionPanel             matlab.ui.container.Panel
        mmLabel_46                      matlab.ui.control.Label
        GeometryDropDownLabel           matlab.ui.control.Label
        GeometryDropDown                matlab.ui.control.DropDown
        ImageWidthEditFieldLabel        matlab.ui.control.Label
        ImageWidthEditField             matlab.ui.control.NumericEditField
        BPModeDropDownLabel             matlab.ui.control.Label
        BPModeDropDown                  matlab.ui.control.DropDown
        ResetDaqButton                  matlab.ui.control.Button
        DigitizerPanel                  matlab.ui.container.Panel
        CroppedSamplsEditFieldLabel     matlab.ui.control.Label
        CroppedSamplsEditField          matlab.ui.control.NumericEditField
        MonitorButton                   matlab.ui.control.Button
        UseusersetcropsamplesCheckBox   matlab.ui.control.CheckBox
        PlotsDataTab                    matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        AOTab                           matlab.ui.container.Tab
        AcoustoOpticsPanel              matlab.ui.container.Panel
        ExternalClkCheckBox             matlab.ui.control.CheckBox
        UltrasoundSignalCheckBox        matlab.ui.control.CheckBox
        MeasuredCheckBox_2              matlab.ui.control.CheckBox
        SignalCheckBox_2                matlab.ui.control.CheckBox
        ReshapedCheckBox                matlab.ui.control.CheckBox
        PhiCheckBox                     matlab.ui.control.CheckBox
        FittedPowerFFTCheckBox          matlab.ui.control.CheckBox
        DemultiplexedCheckBox           matlab.ui.control.CheckBox
        FreqEnviromentEditFieldLabel    matlab.ui.control.Label
        FreqEnviromentEditField         matlab.ui.control.NumericEditField
        KHzLabel_9                      matlab.ui.control.Label
        dispFullFFTCheckBox             matlab.ui.control.CheckBox
        ExcitationSignalsLabel          matlab.ui.control.Label
        TimeDomainSignalsLabel          matlab.ui.control.Label
        FrequencyDomainLabel            matlab.ui.control.Label
        SpatialReconLabel               matlab.ui.control.Label
        PhiRawCheckBox                  matlab.ui.control.CheckBox
        RawFFTCheckBox                  matlab.ui.control.CheckBox
        FittingModelCheckBox            matlab.ui.control.CheckBox
        FinalFFTCheckBox                matlab.ui.control.CheckBox
        FFTDisplayPropertiesLabel       matlab.ui.control.Label
        CroppedCheckBox                 matlab.ui.control.CheckBox
        PhiLogCheckBox                  matlab.ui.control.CheckBox
        TimeTablePanel                  matlab.ui.container.Panel
        DisplayTimeTableCheckBox        matlab.ui.control.CheckBox
        ExportDataPanel                 matlab.ui.container.Panel
        MeasuredCheckBox                matlab.ui.control.CheckBox
        SignalCheckBox                  matlab.ui.control.CheckBox
        DeMultiplexedCheckBox           matlab.ui.control.CheckBox
        ReshapedExportCheckBox          matlab.ui.control.CheckBox
        FFTCheckBox                     matlab.ui.control.CheckBox
        ComplexComponentsUSCheckBox     matlab.ui.control.CheckBox
        OthersTab                       matlab.ui.container.Tab
        OAAxespropertiesPanel           matlab.ui.container.Panel
        FirstReconAxLabel               matlab.ui.control.Label
        OAFirstScanAxTypeDropDown       matlab.ui.control.DropDown
        SecondReconAxLabel              matlab.ui.control.Label
        OASecondScanAxTypeDropDown      matlab.ui.control.DropDown
        ScanAxLabel                     matlab.ui.control.Label
        OAScanAxTypeDropDown            matlab.ui.control.DropDown
        TimeAxLabel                     matlab.ui.control.Label
        OATimeAxTypeDropDown            matlab.ui.control.DropDown
        OAScanNavigatorPanel            matlab.ui.container.Panel
        NavPlainDropDownLabel_4         matlab.ui.control.Label
        OANavPlainDropDown              matlab.ui.control.DropDown
        NavIdxSpinnerLabel_5            matlab.ui.control.Label
        OANavIdxSpinner                 matlab.ui.control.Spinner
        mmLabel_42                      matlab.ui.control.Label
        OANormalizeColorstoDisplayedPlaneCheckBox  matlab.ui.control.CheckBox
        PEUSAxespropertiesPanel         matlab.ui.container.Panel
        ScanReconAxLabel                matlab.ui.control.Label
        PEUSFirstScanAxTypeDropDown     matlab.ui.control.DropDown
        DepthReconAxLabel               matlab.ui.control.Label
        PEUSSecondScanAxTypeDropDown    matlab.ui.control.DropDown
        ScanPropertiesPanel             matlab.ui.container.Panel
        NavPlainDropDownLabel_5         matlab.ui.control.Label
        S3DNavPlainDropDown             matlab.ui.control.DropDown
        ReapeatSpinnerLabel             matlab.ui.control.Label
        S3DRepeatSpinner                matlab.ui.control.Spinner
        NavIdxSpinnerLabel_6            matlab.ui.control.Label
        S3DNavIdxSpinner                matlab.ui.control.Spinner
        s3DNavIdxLabel                  matlab.ui.control.Label
        NormalizeColorstoDisplayedPlaneCheckBox  matlab.ui.control.CheckBox
        s3DMainAxisIdxLabel             matlab.ui.control.Label
        MainAxisDepthCoorLabel          matlab.ui.control.Label
        S2DCurDepthCoorSpinner          matlab.ui.control.Spinner
        GeneralTab                      matlab.ui.container.Tab
        PopoutFiguresPanel              matlab.ui.container.Panel
        PopButton                       matlab.ui.control.Button
        FiguresToPopDropDown            matlab.ui.control.DropDown
        ExternalFiguresCheckBox         matlab.ui.control.CheckBox
        ReOpenFiguresCheckBox           matlab.ui.control.CheckBox
        AxespropertiesPanel             matlab.ui.container.Panel
        FirstScanAxDropDownLabel        matlab.ui.control.Label
        AOFirstScanAxDropDown           matlab.ui.control.DropDown
        SecondScanAxDropDownLabel       matlab.ui.control.Label
        AOSecondScanAxDropDown          matlab.ui.control.DropDown
        DepthDropDownLabel              matlab.ui.control.Label
        AODepthDropDown                 matlab.ui.control.DropDown
        DropDown                        matlab.ui.control.DropDown
        RefreshButton                   matlab.ui.control.Button
        NotificationsScansPanel         matlab.ui.container.Panel
        SendmeemailwhendoneCheckBox     matlab.ui.control.CheckBox
        emailEditFieldLabel             matlab.ui.control.Label
        emailEditField                  matlab.ui.control.EditField
        KeepmeupdatedviaTelegramCheckBox  matlab.ui.control.CheckBox
        ChatIDEditFieldLabel            matlab.ui.control.Label
        ChatIDEditField                 matlab.ui.control.EditField
        StagesTab                       matlab.ui.container.Tab
        AxisAllocationPanel             matlab.ui.container.Panel
        DepthAxisDropDownLabel          matlab.ui.control.Label
        DepthAxisDropDown               matlab.ui.control.DropDown
        stage1AxLabel                   matlab.ui.control.Label
        stage1AxDropDown                matlab.ui.control.DropDown
        stage2AxLabel                   matlab.ui.control.Label
        stage2AxDropDown                matlab.ui.control.DropDown
        SetStagesGeometryButton         matlab.ui.control.Button
        TestAssignmentButton            matlab.ui.control.Button
        stage3AxLabel                   matlab.ui.control.Label
        stage3AxDropDown                matlab.ui.control.DropDown
        Stage1Panel                     matlab.ui.container.Panel
        PosLabel_3                      matlab.ui.control.Label
        Stage1PositionEditField         matlab.ui.control.NumericEditField
        mmLabel_30                      matlab.ui.control.Label
        Stage1MoveToPositionButton      matlab.ui.control.Button
        Stage1Increase                  matlab.ui.control.Button
        Stage1Decrease                  matlab.ui.control.Button
        mmLabel_31                      matlab.ui.control.Label
        Stage1SetStartButton            matlab.ui.control.Button
        Stage1SetEndButton              matlab.ui.control.Button
        Stage1SetStepButton             matlab.ui.control.Button
        StepLabel                       matlab.ui.control.Label
        Stage1StrideEditField           matlab.ui.control.NumericEditField
        HomeStage1                      matlab.ui.control.Button
        HighEditFieldLabel              matlab.ui.control.Label
        HighLimitStage1                 matlab.ui.control.NumericEditField
        LowEditFieldLabel               matlab.ui.control.Label
        LowLimitStage1                  matlab.ui.control.NumericEditField
        mmLabelStage1                   matlab.ui.control.Label
        SetLimitsButtonStage1           matlab.ui.control.Button
        stagesTable                     matlab.ui.control.Table
        ExportPositionPanel             matlab.ui.container.Panel
        ExportToFileButton              matlab.ui.control.Button
        ExporttoscanDropDownLabel       matlab.ui.control.Label
        ExporttoscanDropDown            matlab.ui.control.DropDown
        CopytoScanButton                matlab.ui.control.Button
        AllStagesHomeButton             matlab.ui.control.Button
        RefreshPositionButton           matlab.ui.control.Button
        Stage2Panel                     matlab.ui.container.Panel
        PosLabel_4                      matlab.ui.control.Label
        Stage2PositionEditField         matlab.ui.control.NumericEditField
        mmLabel_32                      matlab.ui.control.Label
        Stage2MoveToPositionButton      matlab.ui.control.Button
        Stage2Increase                  matlab.ui.control.Button
        Stage2Decrease                  matlab.ui.control.Button
        mmLabel_33                      matlab.ui.control.Label
        StepLabel_2                     matlab.ui.control.Label
        Stage2StrideEditField           matlab.ui.control.NumericEditField
        Stage2SetStartButton            matlab.ui.control.Button
        Stage2SetEndButton              matlab.ui.control.Button
        Stage2SetStepButton             matlab.ui.control.Button
        HomeStage2                      matlab.ui.control.Button
        HighEditFieldLabel_2            matlab.ui.control.Label
        HighLimitStage2                 matlab.ui.control.NumericEditField
        LowEditFieldLabel_2             matlab.ui.control.Label
        LowLimitStage2                  matlab.ui.control.NumericEditField
        mmLabelStage2                   matlab.ui.control.Label
        SetLimitsButtonStage2           matlab.ui.control.Button
        Stage3Panel                     matlab.ui.container.Panel
        PosLabel_5                      matlab.ui.control.Label
        Stage3PositionEditField         matlab.ui.control.NumericEditField
        mmLabel_34                      matlab.ui.control.Label
        Stage3MoveToPositionButton      matlab.ui.control.Button
        Stage3Increase                  matlab.ui.control.Button
        Stage3Decrease                  matlab.ui.control.Button
        mmLabel_35                      matlab.ui.control.Label
        StepLabel_3                     matlab.ui.control.Label
        Stage3StrideEditField           matlab.ui.control.NumericEditField
        Stage3SetStartButton            matlab.ui.control.Button
        Stage3SetEndButton              matlab.ui.control.Button
        Stage3SetStepButton             matlab.ui.control.Button
        HomeStage3                      matlab.ui.control.Button
        HighEditFieldLabel_3            matlab.ui.control.Label
        HighLimitStage3                 matlab.ui.control.NumericEditField
        LowEditFieldLabel_3             matlab.ui.control.Label
        LowLimitStage3                  matlab.ui.control.NumericEditField
        mmLabelStage3                   matlab.ui.control.Label
        SetLimitsButtonStage2_2         matlab.ui.control.Button
        ReferenceAllButton              matlab.ui.control.Button
        acoustoOpticsPlotsTab           matlab.ui.container.TabGroup
        TimeTable                       matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        UITable2_2                      matlab.ui.control.Table
        UITable2_3                      matlab.ui.control.Table
        AOVarsTab                       matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        SystemClkTab                    matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        USSignalTab                     matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        SamplesTab                      matlab.ui.container.Tab
        UITable_3                       matlab.ui.control.Table
        TimingTab                       matlab.ui.container.Tab
        UITable_4                       matlab.ui.control.Table
        DigitizerTab                    matlab.ui.container.Tab
        UITable_5                       matlab.ui.control.Table
        LengthTab                       matlab.ui.container.Tab
        UITable_6                       matlab.ui.control.Table
        FreqTab                         matlab.ui.container.Tab
        UITable_7                       matlab.ui.control.Table
        USTab                           matlab.ui.container.Tab
        axUsSignal                      matlab.ui.control.UIAxes
        ReshapedTab                     matlab.ui.container.Tab
        axReshape                       matlab.ui.control.UIAxes
        SignalTab                       matlab.ui.container.Tab
        axNetSignal                     matlab.ui.control.UIAxes
        DeMulTab                        matlab.ui.container.Tab
        axDeMul                         matlab.ui.control.UIAxes
        MeasSigTab                      matlab.ui.container.Tab
        axMeasSig                       matlab.ui.control.UIAxes
        ExtClkTab                       matlab.ui.container.Tab
        axExtClk                        matlab.ui.control.UIAxes
        CroppedTab                      matlab.ui.container.Tab
        axCropped                       matlab.ui.control.UIAxes
        InvisiblePlotTabs               matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        PhiTabGroup                     matlab.ui.container.TabGroup
        PhiTab                          matlab.ui.container.Tab
        axPhi                           matlab.ui.control.UIAxes
        PhiRawTab                       matlab.ui.container.Tab
        axRawPhi                        matlab.ui.control.UIAxes
        PhiLogTab                       matlab.ui.container.Tab
        axPhiLog                        matlab.ui.control.UIAxes
        FFTTabGroup                     matlab.ui.container.TabGroup
        FinalSpectrumTab                matlab.ui.container.Tab
        axFittedFFT                     matlab.ui.control.UIAxes
        FittedPowerFFTTab               matlab.ui.container.Tab
        axFittedPowerFFT                matlab.ui.control.UIAxes
        FittingModelTab                 matlab.ui.container.Tab
        axFittingModel                  matlab.ui.control.UIAxes
        RawFFTTab                       matlab.ui.container.Tab
        axRawFFT                        matlab.ui.control.UIAxes
        CalibrationSpectrumTab          matlab.ui.container.Tab
        axCalibration                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        %parameters
        importedLimits % Description

        %--------------------------
        % New Variables
        %--------------------------
        
        %objects
        algo;
        ao;
        oa;
        consistency;
        s3D;
        s2D;
        dv;
        peus;
        intSys;
        
        %peripherals
        aoStages;
        scope
                
        %Graphics Objects Handles
        aoGraphics;
        s2DGraphics;
        s3DGraphics;
        dvGraphics;
        hConsGraph;
        oaGraphics;
        peusGraphics
        intSysGraphics
        
        % AO Measurement Vars
        consVars
        
        % Data vars
        algoData;
        aoData;
        s3DData;
        s2DData;
        dvData;
        consData;
        stagesData;
        oaData;
        peusData;
        intSysData;
        
        %GUI control Variables
        GUIctrl;
    end
    
    events
        updateFrame
        updateSet
%         updateFrame
        
        updatePhiRepEvent
        updatePhiEvent
        
        timeTable
    end
    
    methods (Access = public)
        
        function displayAOTimeTable(app, ~, ~)
%             tic;
            timeTable = app.ao.getTimeTable();
            dataAlgo  = app.extractStructDataToTable(timeTable.algo);
            dataDigi  = app.extractStructDataToTable(timeTable.digitizer);
            dataAO(1,1:2) = {'Overall',sum([timeTable.acq timeTable.reconstruct timeTable.moveData])}; 
            dataAO(2,1:2) = {'Overall Sampling',timeTable.acq}; 
            dataAO(3,1:2) = {'Overall Analysis',timeTable.reconstruct}; 
            dataAO(4,1:2) = {'Gather Raw Data', timeTable.moveData};
    
            app.UITable2.Data = dataAlgo(:,1:2);
            app.UITable2_2.Data = dataDigi(:,1:2);
            app.UITable2_3.Data = dataAO;  
%             toc
        end
    end

    methods (Access = private)

        function applyImportedLimits(app)
             app.S3DFirstStartEditField.Value   = app.importedLimits.startPosX; 
             app.S3DFirstEndEditField.Value     = app.importedLimits.endPosX; 
             app.S3DSecondStartEditField.Value  = app.importedLimits.startPosY; 
             app.S3DSecondEndEditField.Value    = app.importedLimits.endPosY; 
             app.S3DFirstStrideEditField.Value  = app.importedLimits.Xstride; 
             app.S3DSecondStrideEditField.Value = app.importedLimits.Ystride; 
        end
    
        function data = extractStructDataToTable(~, curStruct)
            names= fieldnames(curStruct);
            j=1;
            num = numel(names);
            data = cell(num,3);
            for i=1:num
                %this function requires mapping toolbox
                fieldVal  = extractfield(curStruct, names{i});
                if length(fieldVal)> 1 || (~isa(fieldVal, 'double') && ~isa(fieldVal, 'logical')) 
                    continue;
                end
                data{j,1} = names{i};
                data{j,2} = fieldVal;
                data{j,3} = 'unit';
                j=j+1;
            end
        end

        % ------------ Consistency GUI Components Update Functions ------------------%
        function updateConsFrameLabel(app)
            app.sLabel_6.Text = sprintf("%.2f[mm]",app.consData.timeFrames(app.FrameToPlotSpinner.Value));
        end

        function setPositionsFromCalib(app, stages)
            app.importedLimits = stages;
        end
        
        function updateConsZLabel(app)
            app.mmLabel_15.Text = sprintf("%.2f[mm]",app.consData.zVecSpinner(app.ZCoorConsSpinner.Value));
        end
    

        %---------------- Algo Functions ---------------%
        function initAlgoVars(app)
           app.algoData.uVars = Algo.createUserVars(); 
           app.collectAlgoVars();

           app.algoData.uVars.bufferSizeBytes   = 8*2^20;
           app.algoData.uVars.bytesPerSample    = 2;
           app.algoData.uVars.preTriggerSamples = 14;
        end
        
        function collectAlgoVars(app) 
            % US Signal
            app.algoData.uVars.cycPerPulse     = app.CyclesPerPulseEditField.Value;
            app.algoData.uVars.fSin            = app.SineFreqEditField.Value*1e6; 
            app.algoData.uVars.fSqnc           = app.SquenceFreqEditField.Value*1e3;
            app.algoData.uVars.frameTime       = app.FrameTimeEditField.Value;
            
            % Sampling Clk
            app.algoData.uVars.fs              = app.FrequencyEditField.Value*1e6; %fs
            app.algoData.uVars.sClkDcyc        = app.DutyCycleEditField.Value;
            app.algoData.uVars.fgClk           = app.SamplesClkFreqEditField.Value*1e6;
            
            % Geometry
            app.algoData.uVars.c               = app.SpeedinMediumEditField.Value; 
            app.algoData.uVars.distFromPhantom = app.TransducerdistanceEditField.Value*1e-2;              
            
            % Digitizer 
            app.algoData.uVars.timeToSample    = app.TimetosampleEditField.Value;
            app.algoData.uVars.channels        = app.NumberOfChannelsEditField.Value;
            
            % Frequency
            app.algoData.uVars.envDC           = app.envDC.Value*1e3;
            app.algoData.uVars.envUS           = app.envUS.Value*1e3;
            
            % General Operation
            app.algoData.uVars.useFrame            = app.UseTimeFrameCheckBox.Value;
            app.algoData.uVars.useGPU              = false;
            app.algoData.uVars.useHadamard         = app.HadamardCodeCheckBox.Value;
            app.algoData.uVars.contHadamard        = app.ContHadamardCheckBox.Value;
            app.algoData.uVars.highResAO           = app.HighResAOCheckBox.Value;
            app.algoData.uVars.analyzeSingleCh     = app.SeparateChCheckBox.Value;
            app.algoData.uVars.contSpeckleAnalysis = app.ContSpeckleCheckBox.Value;

            %Artifacts
            app.algoData.uVars.cutArtfct    = app.CutArtifactsCheckBox.Value;
            app.algoData.uVars.artfctIdxVec = str2num(app.IndexesEditField.Value);
            
            % Export data
            app.algoData.uVars.exportData.meas        = false;
            app.algoData.uVars.exportData.signal      = false;
            app.algoData.uVars.exportData.deMul       = false;
            app.algoData.uVars.exportData.reshaped    = false;
            app.algoData.uVars.exportData.fft         = false;
            app.algoData.uVars.exportData.usCompCmplx = false;
       end
            
        function setAlgoValuesTable(app)
            data = app.extractStructDataToTable(app.algoData.curRes.sClk);
            app.UITable_6.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.usSignal);
            app.UITable_5.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.samples);
            app.UITable.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.timing);
            app.UITable_3.Data = data;
            
%             data = app.extractStructDataToTable(app.algoData.curRes.digitizer);
%             app.UITable_4.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.len);
            app.UITable_2.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.freq);
            app.UITable_7.Data = data;
        end

        %---------------- Acousto Optics Functions ---------------%       
        % Variables
        function initAOVars(app)
            % These are static variables that shouldn't be changed during operation
            % These variables are initialized and never changed again.
            app.aoData.uVars = acoustoOptics.createUserVars();
            
%             app.aoData.uVars.ao.IOPort            = 1;
%             app.aoData.uVars.ao.IOLine            = 4;

            app.collectAOVars();
            app.collectAOGraphicVars();
            
            app.aoData.uVars.figs.fonts.titleSize  = 14;
            app.aoData.uVars.figs.fonts.labelsSize = 14;
            app.aoData.uVars.figs.fonts.axisSize   = 14;
            
            % Handles
            app.aoData.uVars.figs.extH.extClk.fig   = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.extClk.ax    = app.axExtClk;
            app.aoData.uVars.figs.extH.usSignal.fig = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.usSignal.ax  = app.axUsSignal;
            
            app.aoData.uVars.figs.extH.cropped.fig  = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.cropped.ax   = app.axCropped;
            app.aoData.uVars.figs.extH.meas.fig     = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.meas.ax      = app.axMeasSig;
            app.aoData.uVars.figs.extH.signal.fig   = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.signal.ax    = app.axNetSignal;
            app.aoData.uVars.figs.extH.deMul.fig    = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.deMul.ax     = app.axDeMul;
            app.aoData.uVars.figs.extH.reshaped.fig = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.reshaped.ax  = app.axReshape;
            
            app.aoData.uVars.figs.extH.rawFFT.fig         = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.rawFFT.ax          = app.axRawFFT;
            app.aoData.uVars.figs.extH.fittingModel.fig   = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.fittingModel.ax    = app.axFittingModel;
            app.aoData.uVars.figs.extH.fittedPowerFFT.fig = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.fittedPowerFFT.ax  = app.axFittedPowerFFT;
            app.aoData.uVars.figs.extH.finalFFT.fig      = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.finalFFT.ax       = app.axFittedFFT;
            app.aoData.uVars.figs.extH.calibration.fig    = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.calibration.ax     = app.axCalibration;
            
            app.aoData.uVars.figs.extH.phi.fig    = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.phi.ax     = app.axPhi;
            app.aoData.uVars.figs.extH.rawphi.fig = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.rawPhi.ax  = app.axRawPhi;
            app.aoData.uVars.figs.extH.phiLog.fig = app.AOIUIFigure;
            app.aoData.uVars.figs.extH.phiLog.ax  = app.axPhiLog;
            
        end
        
        function uVars = collectAOVars(app)
            
            % US Signal
            app.aoData.uVars.ao.cycPerPulse      = app.CyclesPerPulseEditField.Value;
            app.aoData.uVars.ao.fSin             = app.SineFreqEditField.Value*1e6; 
            app.aoData.uVars.ao.fSqnc            = app.SquenceFreqEditField.Value*1e3;
            app.aoData.uVars.ao.frameTime        = app.FrameTimeEditField.Value;          
            
            % Sampling Clk
            app.aoData.uVars.ao.fs       = app.FrequencyEditField.Value*1e6;
            app.aoData.uVars.ao.sClkDcyc = app.DutyCycleEditField.Value;
            app.aoData.uVars.ao.fgClk    = app.SamplesClkFreqEditField.Value*1e6;
            
            % Digitizer
            app.aoData.uVars.ao.timeToSample = app.TimetosampleEditField.Value;
            app.aoData.uVars.ao.channels     = app.NumberOfChannelsEditField.Value;
            
            % Frequency
            app.aoData.uVars.ao.envDC             = app.envDC.Value*1e3;
            app.aoData.uVars.ao.envUS             = app.envUS.Value*1e3;
            
            % Geometry & Length
            app.aoData.uVars.ao.c                 = app.SpeedinMediumEditField.Value;
            app.aoData.uVars.ao.distFromPhantom   = app.TransducerdistanceEditField.Value*1e-2;

            % General Operation
            app.aoData.uVars.ao.useFrame            = app.UseTimeFrameCheckBox.Value;
            app.aoData.uVars.ao.useGPU              = app.UseGPUCheckBox.Value;
            app.aoData.uVars.ao.useHadamard         = app.HadamardCodeCheckBox.Value;
            app.aoData.uVars.ao.contHadamard        = app.ContHadamardCheckBox.Value;
            app.aoData.uVars.ao.highResAO           = app.HighResAOCheckBox.Value;
            app.aoData.uVars.ao.analyzeSingleCh     = app.SeparateChCheckBox.Value;
            app.aoData.uVars.ao.contSpeckleAnalysis = app.ContSpeckleCheckBox.Value;
            app.aoData.uVars.ao.useCalibration      = app.UseCalibrationCheckBox.Value;
            app.aoData.uVars.ao.acCoupling          = app.ACCouplingCheckBox.Value;
            
            app.aoData.uVars.ao.autoCalibration     = app.AutoCalibrationCheckBox.Value;
            app.aoData.uVars.ao.timeToSampleCal     = app.DurationEditField.Value;
            
            app.aoData.uVars.ao.cutArtfct    = app.CutArtifactsCheckBox.Value;
            app.aoData.uVars.ao.artfctIdxVec = str2num(app.IndexesEditField.Value);
            
            app.aoData.uVars.ao.calcMuEff     = app.CalcMuEffCheckBox.Value;
            app.aoData.uVars.ao.muEffModel    = app.ConfigurationDropDown.Value;
            app.aoData.uVars.ao.muEffIdxs     = str2num(app.muEffIndexesEditField.Value);
            
            app.aoData.uVars.ao.usPower = app.USPowerEditField.Value;
            
            % Export
            app.aoData.uVars.ao.exportData.meas       = app.MeasuredCheckBox.Value;       
            app.aoData.uVars.ao.exportData.signal     = app.SignalCheckBox.Value;
            app.aoData.uVars.ao.exportData.deMul = app.DeMultiplexedCheckBox.Value;
            app.aoData.uVars.ao.exportData.reshaped      = app.ReshapedExportCheckBox.Value;
            app.aoData.uVars.ao.exportData.fft           = app.FFTCheckBox.Value;
            app.aoData.uVars.ao.exportData.usCompCmplx   = app.ComplexComponentsUSCheckBox.Value;

            app.collectAOGraphicVars(); 
            
            % set file system properties
            app.collectAOFileSystemVars();
            
            % Set Acousto Optics Object Control Vars
            app.aoData.uVars.ao.useVirtualData       = app.UseVirtualDataCheckBox.Value;
            app.aoData.uVars.ao.virtualDataNoiseSTD  = app.NoiesStdEditField.Value;
            
            app.aoData.uVars.ao.limitByN         = app.LimitLiveAOCheckBox.Value;
            app.aoData.uVars.ao.N                = app.NEditField.Value;
            app.aoData.uVars.ao.dispTimeTable    = app.DisplayTimeTableCheckBox.Value;
            
            % Calibration
            
            uVars = app.aoData.uVars;

        end
        
        function fileSystem = collectAOFileSystemVars(app)
            app.aoData.uVars.fileSystem.saveRawData        = app.saveRawDataCheckBox.Value;
            app.aoData.uVars.fileSystem.saveNetSignal      = app.saveNetSignalCheckBox.Value;
            app.aoData.uVars.fileSystem.saveDemultiplexed  = app.saveDeMultiplexedCheckBox.Value;
            app.aoData.uVars.fileSystem.saveReshapedSignal = app.saveReshapedCheckBox.Value;
            app.aoData.uVars.fileSystem.saveFFT            = app.saveFFTCheckBox.Value;
            app.aoData.uVars.fileSystem.savePhiChCmplx     = app.saveUSCmplxCheckBox.Value;
            
            app.aoData.uVars.fileSystem.saveResults = app.saveResultsandVarsCheckBox.Value;
            app.aoData.uVars.fileSystem.saveFigs    = app.saveFigsCheckBox.Value;
            app.aoData.uVars.fileSystem.saveVars    = app.saveResultsandVarsCheckBox.Value;
            
            app.aoData.uVars.fileSystem.projName    = app.MeasNameEditField.Value;
            app.aoData.uVars.fileSystem.dirPath     = app.PathEditField.Value;
            
            app.aoData.uVars.fileSystem.extProject         = false;
            app.aoData.uVars.fileSystem.extProjPath        = [];
            app.aoData.uVars.fileSystem.extProjResultsPath = [];
            app.aoData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.aoData.uVars.fileSystem;
        end
    
        function figs = collectAOGraphicVars(app)
            % Choose channel and position            
            app.aoData.uVars.figs.ch             = app.SignalChannelSpinner.Value;
            app.aoData.uVars.figs.singleChIdx    = app.SeparateChannelSpinner.Value;
            app.aoData.uVars.figs.depthIdx       = app.AODepthIdxSpinner.Value;
            app.aoData.uVars.figs.depthAxType    = app.AODepthDropDown.Value;
            app.aoData.uVars.figs.frame          = app.FrameAOSpinner.Value;
            app.aoData.uVars.figs.FFTenv         = app.FreqEnviromentEditField.Value*1e3;
            app.aoData.uVars.figs.displayFullFFT = app.dispFullFFTCheckBox.Value;
            
            app.aoData.uVars.figs.reopenFigures  = app.ReOpenFiguresCheckBox.Value;

            % Choose internal or external
            if app.ExternalFiguresCheckBox.Value
                app.aoData.uVars.figs.intExt = 'int';
            else
                app.aoData.uVars.figs.intExt = 'ext';
            end
            
            % Turn figures on and off
            app.aoData.uVars.figs.validStruct.extClk   = app.ExternalClkCheckBox.Value;
            app.aoData.uVars.figs.validStruct.usSignal = app.UltrasoundSignalCheckBox.Value;
            
            app.aoData.uVars.figs.validStruct.cropped  = app.CroppedCheckBox.Value;
            app.aoData.uVars.figs.validStruct.meas     = app.MeasuredCheckBox_2.Value;
            app.aoData.uVars.figs.validStruct.signal   = app.SignalCheckBox_2.Value;
            app.aoData.uVars.figs.validStruct.deMul    = app.DemultiplexedCheckBox.Value;
            app.aoData.uVars.figs.validStruct.reshaped = app.ReshapedCheckBox.Value;
            
            app.aoData.uVars.figs.validStruct.rawFFT         = app.RawFFTCheckBox.Value;
            app.aoData.uVars.figs.validStruct.fittingModel   = app.FittingModelCheckBox.Value;
            app.aoData.uVars.figs.validStruct.fittedPowerFFT = app.FittedPowerFFTCheckBox.Value;
            app.aoData.uVars.figs.validStruct.finalFFT       = app.FinalFFTCheckBox.Value;
            app.aoData.uVars.figs.validStruct.calibration    = true;
            
            app.aoData.uVars.figs.validStruct.phi    = app.PhiCheckBox.Value;
            app.aoData.uVars.figs.validStruct.rawPhi = app.PhiRawCheckBox.Value;
            app.aoData.uVars.figs.validStruct.phiLog = app.PhiLogCheckBox.Value;
            
            figs = app.aoData.uVars.figs;
        end
        
        function figs = collectAOGraphicOwnerVars(app)
            % Choose channel and position
            figs = app.ao.measVars.figs;
            
            figs.ch    = app.SignalChannelSpinner.Value;
            figs.depthIdx  = app.AODepthIdxSpinner.Value;
            figs.frame = app.FrameAOSpinner.Value;
            figs.singleChIdx    = app.SeparateChannelSpinner.Value;
            
            figs.reopenFigures  = app.ReOpenFiguresCheckBox.Value;
            figs.depthAxType    = app.AODepthDropDown.Value;
            figs.FFTenv         = app.FreqEnviromentEditField.Value*1e3;
            figs.displayFullFFT = app.dispFullFFTCheckBox.Value;
        end

        function setAOVarsToGUI(app, vars)
            app.SpeedinMediumEditField.Value  = vars.c;
            app.SineFreqEditField.Value       = vars.fSin/1e6;             
            app.SquenceFreqEditField.Value    = vars.fTrain/1e3;
            app.CyclesPerPulseEditField.Value = vars.cycInPulse; 
            
            app.PhantomdepthEditField.Value       = vars.phantomDepth/1e-2;
            app.TransducerdistanceEditField.Value = vars.distFromPhantom/1e-2;
            app.FrequencyEditField.Value          = vars.fExtClk/1e6;
            app.TimetosampleEditField.Value       = vars.timeToSample;
            app.DutyCycleEditField.Value          = vars.extClkDcyc;
            app.FastAnalysisCheckBox.Value        = vars.fastAnalysis;
             
            app.UseTimeFrameCheckBox.Value    = vars.useFrame;
            app.FrameTimeEditField.Value      = vars.frameTime;
            app.HadamardCodeCheckBox.Value    = vars.useHadamard;
            
            app.SamplesClkFreqEditField.Value = vars.fSclk/1e6;
            
            app.NumberOfChannelsEditField.Value = vars.channels;
            
            app.UseGPUCheckBox.Value            = vars.useGPU;
        end
        
        % GUI Control
        function updateAOPlotChSpinnerLimits(app)
            channels = app.aoData.curVars.measVars.algo.general.channels;
            if channels == 1
                app.SignalChannelSpinner.Value = 1;
                app.SignalChannelSpinner.Enable = false;
                app.SeparateChannelSpinner.Value = 1;
                app.SeparateChannelSpinner.Enable = false;
                return
            end
            app.SignalChannelSpinner.Enable = true;
            app.SeparateChannelSpinner.Enable = true;
            if app.SignalChannelSpinner.Value > channels
                app.SignalChannelSpinner.Value = 1;
            end
            if app.SeparateChannelSpinner.Value > channels
                app.SeparateChannelSpinner.Value = 1;
            end
            app.SignalChannelSpinner.Limits = [1, channels];
            app.SeparateChannelSpinner.Limits = [1, channels];
        end
        
        function updateAOPlotZIdxSpinnerLimits(app)
                zVec = app.aoData.curVars.measVars.algo.len.depthVec;
                app.AODepthIdxSpinner.Step  = 1;
                if  app.AODepthIdxSpinner.Value > length(zVec)
                    app.AODepthIdxSpinner.Value = 1;
                    app.updateAOZIdxSpinnerLabel()
                end  
                app.AODepthIdxSpinner.Limits = [1, length(zVec)];
        end
        
        function updateFrameSpinnerLimits(app)
            maxFrame = app.aoData.curVars.measVars.algo.samples.framesPerSignal;
            if maxFrame == 1
                app.FrameAOSpinner.Value  = 1;
                app.FrameAOSpinner.Enable = false;
                return;
            end
            app.FrameAOSpinner.Enable = true;
            if app.FrameAOSpinner.Value > maxFrame
                app.FrameAOSpinner.Value  = 1;
                app.FrameAOSpinner.Step   = 1;
            end
            app.FrameAOSpinner.Limits = [1, maxFrame]; 
        end
        
        function updateAOZIdxSpinnerLabel(app)
            app.mmLabelPlotPos.Text = sprintf("%.2f[mm]", app.aoData.curVars.measVars.algo.len.depthVec(app.AODepthIdxSpinner.Value)*1e3);
        end
       
        %Figures
        function updateAOFigs(app)
            if ~isempty(app.aoData.curRes)
                figsVars = app.collectAOGraphicOwnerVars();
                app.ao.setFigsVars(figsVars);
            end
        end
        
        %---------------- Scan 2D Functions ---------------%
        % Variables
        function staticVars = uVarsS2DInit(app)
           app.s2DData.uVars = scan2DAO.createUserVars();
           
           app.s2DData.uVars.figs.extH.curMainAxis.fig     = app.AOIUIFigure;
           app.s2DData.uVars.figs.extH.curMainAxis.ax      = app.axMainAxis;
           app.s2DData.uVars.figs.extH.curMainAxisLog.fig  = app.AOIUIFigure;
           app.s2DData.uVars.figs.extH.curMainAxisLog.ax   = app.axMainAxisLog;
           app.s2DData.uVars.figs.extH.curMainAxisAvg.fig  = app.AOIUIFigure;
           app.s2DData.uVars.figs.extH.curMainAxisAvg.ax   = app.axMainAxisAvg;
           app.s2DData.uVars.figs.extH.curMainPlane.fig    = app.AOIUIFigure;
           app.s2DData.uVars.figs.extH.curMainPlane.ax     = app.axMainPlane;
           app.s2DData.uVars.figs.extH.curMainPlaneLog.fig = app.AOIUIFigure;
           app.s2DData.uVars.figs.extH.curMainPlaneLog.ax  = app.axMainPlaneLog;
           app.s2DData.uVars.figs.extH.curMainPlaneAvg.fig = app.AOIUIFigure;
           app.s2DData.uVars.figs.extH.curMainPlaneAvg.ax  = app.axMainPlaneAvg;

           app.s2DData.uVars.figs.fonts.type = [];
           app.s2DData.uVars.figs.fonts.titleSize  = 14;
           app.s2DData.uVars.figs.fonts.labelsSize = 14;
           app.s2DData.uVars.figs.fonts.axisSize   = 14;

           app.s2DData.uVars.figs.validStruct.curMainAxis     = true;
           app.s2DData.uVars.figs.validStruct.curMainAxisLog  = true;
           app.s2DData.uVars.figs.validStruct.curMainAxisAvg  = true;
           app.s2DData.uVars.figs.validStruct.curMainPlane    = true;
           app.s2DData.uVars.figs.validStruct.curMainPlaneLog = true;
           app.s2DData.uVars.figs.validStruct.curMainPlaneAvg = true;
           
           staticVars.validStruct = app.s2DData.uVars.figs.validStruct;
           staticVars.intExt      = app.s2DData.uVars.figs.intExt;
           staticVars.extH        = app.s2DData.uVars.figs.extH;
        end 
        
        function collectS2DVars(app)         
            % Acousto Optics variables
            app.s2DData.uVars.ao = app.collectAOVars;
            
            % General scan variables
            app.s2DData.uVars.general.externalScan = false;
            
            % Grid scan variables
            app.s2DData.uVars.grid.scanStart  = app.S2DFirstStartEditField.Value;
            app.s2DData.uVars.grid.scanEnd    = app.S2DFirstEndEditField.Value;
            app.s2DData.uVars.grid.scanStride = app.S2DFirstStrideEditField.Value;
            
            app.s2DData.uVars.grid.secondPos  = app.S2DSecondPosEditField.Value;
            app.s2DData.uVars.grid.thirdPos   = app.S2DThirdPosEditField.Value;
            
            app.s2DData.uVars.grid.scanAxis   = app.S2DFirstAxisDropDown.Value;
            app.s2DData.uVars.grid.secondAxis = app.S2DSecondAxisDropDown.Value;
            app.s2DData.uVars.grid.thirdAxis  = app.S2DThirdAxisDropDown.Value;
            app.s2DData.uVars.grid.depthAxis  = app.DepthAxisDropDown.Value;
            
            app.s2DData.uVars.grid.repeats    = app.S2DRepeatsEditField.Value;
            
            % Stages control variables
            app.s2DData.uVars.stages.stagesOwnedByParent = true;
            app.s2DData.uVars.stages.moveNonScanStages = ~app.DontmovenonscanningstagesCheckBox.Value;
            
            % Figures variables
            app.collectS2DGraphicsVars();
            
            % File system variables
            app.collectS2DFileSystemVars;
        end
        
        function figs = collectS2DGraphicsVars(app)
            app.s2DData.uVars.figs.depthIdx      = app.S2DCurDepthCoorSpinner.Value;
            app.s2DData.uVars.figs.useExtClims   = false;
            app.s2DData.uVars.figs.reopenFigures = app.ReOpenFiguresCheckBox.Value;
            app.s2DData.uVars.figs.sepChIdx = app.SeparateChannelSpinner.Value;
            
            % Axes Types
            switch app.S2DFirstAxisDropDown.Value
                case 'X'
                    app.s2DData.uVars.figs.scanAxType = app.AOFirstScanAxDropDown.Value;
                    switch app.S2DSecondAxisDropDown.Value
                        case 'Y'
                            app.s2DData.uVars.figs.depthAxType = app.AODepthDropDown.Value;
                        case 'Z'
                            app.s2DData.uVars.figs.depthAxType = app.AOSecondScanAxDropDown.Value;
                    end
                case 'Y'
                    app.s2DData.uVars.figs.scanAxType = app.AOSecondScanAxDropDown.Value;
                    switch app.S2DSecondAxisDropDown.Value
                        case 'X'
                            app.s2DData.uVars.figs.depthAxType = app.AODepthDropDown.Value;
                        case 'Z'
                            app.s2DData.uVars.figs.depthAxType = app.AOFirstScanAxDropDown.Value;
                    end
                case 'Z'
                    app.s2DData.uVars.figs.scanAxType = app.AODepthDropDown.Value;
                    switch app.S2DSecondAxisDropDown.Value
                        case 'X'
                            app.s2DData.uVars.figs.depthAxType = app.AOSecondScanAxDropDown.Value;
                        case 'Y'
                            app.s2DData.uVars.figs.depthAxType = app.AOFirstScanAxDropDown.Value;
                    end
            end
            
            if app.ExternalFiguresCheckBox.Value
                app.s2DData.uVars.figs.intExt = 'int';
            else
                app.s2DData.uVars.figs.intExt = 'ext';
            end
            
            figs = app.s2DData.uVars.figs;
            
        end
        
        function fileSystem = collectS2DFileSystemVars(app)
            app.s2DData.uVars.fileSystem.saveResults  = app.saveResultsandVarsCheckBox.Value;
            app.s2DData.uVars.fileSystem.saveFigs     = app.saveFigsCheckBox.Value;
            app.s3DData.uVars.fileSystem.dontSaveVars = false;
            
            app.s2DData.uVars.fileSystem.projName    = app.MeasNameEditField.Value;
            app.s2DData.uVars.fileSystem.dirPath     = app.PathEditField.Value;
            
            app.s2DData.uVars.fileSystem.extProject         = false;
            app.s2DData.uVars.fileSystem.extProjPath        = [];
            app.s2DData.uVars.fileSystem.extProjResultsPath = [];
            app.s2DData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.s2DData.uVars.fileSystem; 
        end
        
        % GUI
        function updateS2DDepthAxisSpinnerLabel(app)
            idx = app.S2DDepthCoorSpinner.Value;
            app.mmLabel_24.Text = sprintf("%.2f[mm]", app.s2DData.curVars.ao.measVars.algo.len.depthVec(idx)*1e3);
        end
        
        function updateS2DDepthCoorSpinnerLimits(app)
            maxValue = length(app.s2DData.curVars.ao.measVars.algo.len.depthVec);
            app.S2DCurDepthCoorSpinner.LowerLimitInclusive = 'on';
            app.S2DCurDepthCoorSpinner.Step   = 1;
            if app.S2DCurDepthCoorSpinner.Value > maxValue
                app.S2DCurDepthCoorSpinner.Value  = 1;
                app.updateS2DDepthAxisSpinnerLabel()
            end
            app.S2DCurDepthCoorSpinner.Limits = [1, maxValue];
        end 

        function updateS2DGUIComponents(app)
            app.S2DCurDepthCoorSpinner.Enable  = false;
            app.updateS2DDepthCoorSpinnerLimits();
            app.S2DCurDepthCoorSpinner.Enable  = true;
        end

        %---------------- Scan 3D Functions ---------------%
        % Variables
        function staticVars = uVarsS3DInit(app)
           app.s3DData.uVars = scan3DAO.uVarsCreate();
           
           app.s3DData.uVars.figs.extH.nav.fig             = app.AOIUIFigure;
           app.s3DData.uVars.figs.extH.nav.ax              = app.axNav;
           app.s3DData.uVars.figs.extH.navAvg.fig          = app.AOIUIFigure;
           app.s3DData.uVars.figs.extH.navAvg.ax           = app.axNavAvg;
           
           app.s3DData.uVars.figs.fonts.type = [];
           app.s3DData.uVars.figs.fonts.titleSize  = 14;
           app.s3DData.uVars.figs.fonts.labelsSize = 14;
           app.s3DData.uVars.figs.fonts.axisSize   = 14;

           app.s3DData.uVars.figs.validStruct.nav             = true;
           app.s3DData.uVars.figs.validStruct.navAvg          = true;
           
           % Set handles of s2D object;
           app.s3DData.uVars.s2D.figs = app.collectS2DGraphicsVars();
           
           staticVars.validStruct = app.s3DData.uVars.figs.validStruct;
           staticVars.intExt      = app.s3DData.uVars.figs.intExt;
           staticVars.extH        = app.s3DData.uVars.figs.extH;

        end 

        function collectS3DVars(app)         
            % Acousto Optics variables
            app.s3DData.uVars.ao = app.collectAOVars;
            
            % Grid scan variables
            app.s3DData.uVars.grid.scan1Start   = app.S3DFirstStartEditField.Value;
            app.s3DData.uVars.grid.scan1Stride  = app.S3DFirstStrideEditField.Value;
            app.s3DData.uVars.grid.scan1End     = app.S3DFirstEndEditField.Value;

            app.s3DData.uVars.grid.scan2Start  = app.S3DSecondStartEditField.Value;
            app.s3DData.uVars.grid.scan2Stride = app.S3DSecondStrideEditField.Value;
            app.s3DData.uVars.grid.scan2End    = app.S3DSecondEndEditField.Value;
            
            app.s3DData.uVars.grid.thirdPos    = app.S3DThirdPosEditField.Value;
            
            app.s3DData.uVars.grid.scan1Axis  = app.S3DFirstAxisDropDown.Value;
            app.s3DData.uVars.grid.scan2Axis  = app.S3DSecondAxisDropDown.Value;
            app.s3DData.uVars.grid.thirdAxis  = app.S3DThirdAxisDropDown.Value;
            app.s3DData.uVars.grid.depthAxis  = app.DepthAxisDropDown.Value;
            
            app.s3DData.uVars.grid.repeats = app.S3DRepeatsEditField.Value;
            
            % Stages control variables
            app.s3DData.uVars.stages.dontMoveNonScaning  = app.S3DDontMoveNonScanninStages.Value;
            app.s3DData.uVars.stages.stagesOwnedByParent = true;
            
            % Figures variables
            app.collectS3DGraphicsVars();
            
            % File system variables
            app.collectS3DFileSystemVars;
        end
        
        function figs = collectS3DGraphicsVars(app)
            app.s3DData.uVars.figs.depthIdx          = app.S2DCurDepthCoorSpinner.Value;
            app.s3DData.uVars.figs.normColorsToPlane = app.NormalizeColorstoDisplayedPlaneCheckBox.Value;
            app.s3DData.uVars.figs.reopenFigures     = app.ReOpenFiguresCheckBox.Value;
            
            % Axes Types
            switch app.S3DFirstAxisDropDown.Value
                case 'X'
                    app.s3DData.uVars.figs.scan1AxType = app.AOFirstScanAxDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'Y'
                            app.s3DData.uVars.figs.depthAxType = app.AODepthDropDown.Value;
                            app.s3DData.uVars.figs.scan2AxType = app.AOSecondScanAxDropDown.Value;
                        case 'Z'
                            app.s3DData.uVars.figs.depthAxType = app.AOSecondScanAxDropDown.Value;
                            app.s3DData.uVars.figs.scan2AxType = app.AODepthDropDown.Value;
                    end
                case 'Y'
                    app.s3DData.uVars.figs.scan1AxType = app.AOSecondScanAxDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'X'
                            app.s3DData.uVars.figs.depthAxType = app.AODepthDropDown.Value;
                            app.s3DData.uVars.figs.scan2AxType = app.AOFirstScanAxDropDown.Value;
                        case 'Z'
                            app.s3DData.uVars.figs.depthAxType = app.AOFirstScanAxDropDown.Value;
                            app.s3DData.uVars.figs.scan2AxType = app.AODepthDropDown.Value;
                    end
                case 'Z'
                    app.s3DData.uVars.figs.scan1AxType = app.AODepthDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'X'
                            app.s3DData.uVars.figs.depthAxType = app.AOSecondScanAxDropDown.Value;
                            app.s3DData.uVars.figs.scan2AxType = app.AOFirstScanAxDropDown.Value;
                        case 'Y'
                            app.s3DData.uVars.figs.depthAxType = app.AOFirstScanAxDropDown.Value;
                            app.s3DData.uVars.figs.scan2AxType = app.AOSecondScanAxDropDown.Value;
                    end
            end
            
            
            % Choose internal or external
            if app.ExternalFiguresCheckBox.Value
                app.s3DData.uVars.figs.intExt = 'int';
            else
                app.s3DData.uVars.figs.intExt = 'ext';
            end
            
            figs = app.s3DData.uVars.figs;
        end
        
        function setS3DVarsToGUI(app, vars)
            % Grid scan variables
            app.S3DFirstStartEditField.Value   = vars.grid.startFirst;
            app.S3DSecondStartEditField.Value  = vars.grid.startSecond;
            app.S3DFirstEndEditField.Value     = vars.grid.endFirst;
            app.S3DSecondEndEditField.Value    = vars.grid.endSecond;
            app.S3DFirstStrideEditField.Value  = vars.grid.strideFirst;
            app.S3DSecondStrideEditField.Value = vars.grid.strideSecond;
            app.S3DFirstAxisDropDown.Value     = vars.grid.firstAxis;
            switch app.S3DFirstAxisDropDown.Value
                case 'X'
                    app.S3DSecondAxisDropDown.Items = {'Y', 'Z'};
                    app.S3DSecondAxisDropDown.Value = 'Z';
                case 'Y'
                    app.S3DSecondAxisDropDown.Items = {'X', 'Z'};
                    app.S3DSecondAxisDropDown.Value = 'Z';
                case 'Z'
                    app.S3DSecondAxisDropDown.Items = {'X', 'Y'};
                    app.S3DSecondAxisDropDown.Value = 'Y';   
            end
            app.S3DSecondAxisDropDown.Value = vars.grid.secondAxis;
        end
        
        function navVars = collectS3DNavVars(app)
            navVars = scan3DGraphics.createNavigatorVars();
            navVars.navAx  = app.S3DNavPlainDropDown.Value;
            navVars.navIdx = app.S3DNavIdxSpinner.Value;
            navVars.navRep = app.S3DRepeatSpinner.Value;
            app.s3DData.navVars = navVars;
        end
        
        function fileSystem = collectS3DFileSystemVars(app)
            app.s3DData.uVars.fileSystem.saveResults  = app.saveResultsandVarsCheckBox.Value;
            app.s3DData.uVars.fileSystem.saveFigs     = app.saveFigsCheckBox.Value;
            app.s3DData.uVars.fileSystem.dontSaveVars = false;
            
            app.s3DData.uVars.fileSystem.projName    = app.MeasNameEditField.Value;
            app.s3DData.uVars.fileSystem.dirPath     = app.PathEditField.Value;
            
            app.s3DData.uVars.fileSystem.extProject         = false;
            app.s3DData.uVars.fileSystem.extProjPath        = [];
            app.s3DData.uVars.fileSystem.extProjResultsPath = [];
            app.s3DData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.s3DData.uVars.fileSystem; 
        end
        
        % GUI Control
        function updateS3DNavIdxSpinnerLimits(app)
           plain = app.S3DNavPlainDropDown.Value;
           app.S3DNavIdxSpinner.LowerLimitInclusive = 'on';
           app.S3DNavIdxSpinner.Step  = 1;
           switch plain
                case 'X'
                    maxLim = length(app.s3DData.curVars.grid.xVec);
                case 'Y'
                    maxLim = length(app.s3DData.curVars.grid.yVec);
                case 'Z'
                    maxLim = length(app.s3DData.curVars.grid.zVec);
           end     
           app.S3DNavIdxSpinner.Value = 1;
           app.updateS3DNavIdxLabel();
           app.S3DNavIdxSpinner.Limits = [1, maxLim];
        end
        
        function updateS3DRepeatsSpinnerLimits(app)
            app.S3DRepeatSpinner.LowerLimitInclusive = 'on';
            app.S3DRepeatSpinner.Step   = 1;
            if app.s3DData.curVars.grid.repeats > 1
               maxRep = app.s3DData.curVars.general.repeats;
               app.S3DRepeatSpinner.Value  = 1;
               app.S3DRepeatSpinner.Limits = [1, maxRep];
               app.S3DRepeatSpinner.Enable = true;
            else
               app.S3DRepeatSpinner.Value  = 1;
               app.S3DRepeatSpinner.Limits = [0,1];
               app.S3DRepeatSpinner.Enable = false;
            end
        end

        function updateS3DNavIdxLabel(app)
           plain = app.S3DNavPlainDropDown.Value;
           idx = app.S3DNavIdxSpinner.Value;
           switch plain
               case 'X'
                   app.s3DNavIdxLabel.Text = sprintf("%.2f[mm]", app.s3DData.curVars.grid.xVec(idx));
               case 'Y'
                   app.s3DNavIdxLabel.Text = sprintf("%.2f[mm]", app.s3DData.curVars.grid.yVec(idx));
               case 'Z'
                   app.s3DNavIdxLabel.Text = sprintf("%.2f[mm]", app.aoData.curVars.measVars.algo.len.depthVec(idx)*1e3);
           end
        end
    
        function updateS3DMainAxisZSpinnerLabel(app)
            idx = app.S3DCurDepthCoorSpinner.Value;
            app.mmLabel_19.Text = sprintf("%.2f[mm]", app.s3DData.curVars.ao.measVars.algo.len.depthVec(idx)*1e3);
        end
        
        function updateS3DGUIComponents(app)
            app.S3DNavPlainDropDown.Enable = false;
            app.S3DNavIdxSpinner.Enable    = false;
            app.S3DRepeatSpinner.Enable    = false;

            app.updateS3DRepeatsSpinnerLimits();
            app.updateS3DNavIdxSpinnerLimits();

            app.S3DNavPlainDropDown.Enable = true;
            app.S3DNavIdxSpinner.Enable    = true;

            app.updateS2DGUIComponents();
        end

        %Figures
        function updateS3DNavigator(app)
            navVars = app.collectS3DNavVars();
            app.s3DGraphics.setNavVars(navVars);
            app.s3DGraphics.dispNavPlane();
            app.s3DGraphics.dispNavPlaneAvg();
        end
        
        function updateS3DCurFigures(app)
            idx = app.S3DCurDepthCoorSpinner.Value;
            app.s3DGraphics.setDepthToMainAxis(idx);
            app.s3DGraphics.dispCurMainAxis();
            app.s3DGraphics.dispCurMainAxisAvg();
        end
        
        %---------------- Deep View Functions ---------------%
        % Variables
        function staticVars = uVarsDVInit(app)
           app.s3DData.uVars = scan3DAO.uVarsCreate();
           
%            app.s3DData.uVars.figs.extH.curMainAxis.fig     = app.AOIUIFigure;
%            app.s3DData.uVars.figs.extH.curMainAxis.ax      = app.axMainAxis;
%            app.s3DData.uVars.figs.extH.curMainAxisAvg.fig  = app.AOIUIFigure;
%            app.s3DData.uVars.figs.extH.curMainAxisAvg.ax   = app.axMainAxisAvg;
%            app.s3DData.uVars.figs.extH.curMainPlane.fig    = app.AOIUIFigure;
%            app.s3DData.uVars.figs.extH.curMainPlane.ax     = app.axMainPlane;
%            app.s3DData.uVars.figs.extH.curMainPlaneAvg.fig = app.AOIUIFigure;
%            app.s3DData.uVars.figs.extH.curMainPlaneAvg.ax  = app.axMainPlaneAvg;
           app.s3DData.uVars.figs.extH.nav.fig             = app.AOIUIFigure;
           app.s3DData.uVars.figs.extH.nav.ax              = app.axNav;
           app.s3DData.uVars.figs.extH.navAvg.fig          = app.AOIUIFigure;
           app.s3DData.uVars.figs.extH.navAvg.ax           = app.axNavAvg;
           
           app.s3DData.uVars.figs.fonts.type = [];
           app.s3DData.uVars.figs.fonts.titleSize  = 14;
           app.s3DData.uVars.figs.fonts.labelsSize = 14;
           app.s3DData.uVars.figs.fonts.axisSize   = 14;

           app.s3DData.uVars.figs.validStruct.deepView    = true;
           app.s3DData.uVars.figs.validStruct.deepViewLog = true;
           
           % Set handles of s2D object;
           app.s3DData.uVars.s2D.figs = app.collectS2DGraphicsVars();
           
           staticVars.validStruct = app.s3DData.uVars.figs.validStruct;
           staticVars.intExt      = app.s3DData.uVars.figs.intExt;
           staticVars.extH        = app.s3DData.uVars.figs.extH;

        end 

        function collectDVVars(app)         
            % Acousto Optics variables
            app.s3DData.uVars.ao = app.collectAOVars;
            
            % Grid scan variables
            app.s3DData.uVars.grid.firstStart   = app.S3DFirstStartEditField.Value;
            app.s3DData.uVars.grid.firstStride  = app.S3DFirstStrideEditField.Value;
            app.s3DData.uVars.grid.firstEnd     = app.S3DFirstEndEditField.Value;

            app.s3DData.uVars.grid.secondStart  = app.S3DSecondStartEditField.Value;
            app.s3DData.uVars.grid.secondStride = app.S3DSecondStrideEditField.Value;
            app.s3DData.uVars.grid.secondEnd    = app.S3DSecondEndEditField.Value;
            
            app.s3DData.uVars.grid.firstAxis  = app.S3DFirstAxisDropDown.Value;
            app.s3DData.uVars.grid.secondAxis = app.S3DSecondAxisDropDown.Value;
            
            app.s3DData.uVars.grid.repeats = app.S3DRepeatsEditField.Value;
            
            % Stages control variables
            app.s2DData.uVars.stages.stagesOwnedByParent = true;
            
            % Figures variables
            app.collectS3DGraphicsVars();
            
            % File system variables
            app.collectS3DFileSystemVars;
        end
        
        function figs = collectDVGraphicsVars(app)
            app.s3DData.uVars.figs.depthIdx          = app.S2DCurDepthCoorSpinner.Value;
            app.s3DData.uVars.figs.normColorsToPlane = app.NormalizeColorstoDisplayedPlaneCheckBox.Value;
            app.s3DData.uVars.figs.reopenFigures     = app.ReOpenFiguresCheckBox.Value;
            
            % Axes Types
            switch app.S3DFirstAxisDropDown.Value
                case 'X'
                    app.s3DData.uVars.figs.firstAxType = app.AOFirstScanAxDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'Y'
                            app.s3DData.uVars.figs.depthAxType = app.AODepthDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.AOSecondScanAxDropDown.Value;
                        case 'Z'
                            app.s3DData.uVars.figs.depthAxType = app.AOSecondScanAxDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.AODepthDropDown.Value;
                    end
                case 'Y'
                    app.s3DData.uVars.figs.firstAxType = app.AOSecondScanAxDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'X'
                            app.s3DData.uVars.figs.depthAxType = app.AODepthDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.AOFirstScanAxDropDown.Value;
                        case 'Z'
                            app.s3DData.uVars.figs.depthAxType = app.AOFirstScanAxDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.AODepthDropDown.Value;
                    end
                case 'Z'
                    app.s3DData.uVars.figs.firstAxType = app.AODepthDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'X'
                            app.s3DData.uVars.figs.depthAxType = app.AOSecondScanAxDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.AOFirstScanAxDropDown.Value;
                        case 'Y'
                            app.s3DData.uVars.figs.depthAxType = app.AOFirstScanAxDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.AOSecondScanAxDropDown.Value;
                    end
            end
            
            
            % Choose internal or external
            if app.ExternalFiguresCheckBox.Value
                app.s3DData.uVars.figs.intExt = 'int';
            else
                app.s3DData.uVars.figs.intExt = 'ext';
            end
            
            
            figs = app.s3DData.uVars.figs;
        end
        
        function setDVVarsToGUI(app, vars)
            % Grid scan variables
            app.S3DFirstStartEditField.Value   = vars.grid.startFirst;
            app.S3DSecondStartEditField.Value  = vars.grid.startSecond;
            app.S3DFirstEndEditField.Value     = vars.grid.endFirst;
            app.S3DSecondEndEditField.Value    = vars.grid.endSecond;
            app.S3DFirstStrideEditField.Value  = vars.grid.strideFirst;
            app.S3DSecondStrideEditField.Value = vars.grid.strideSecond;
            app.S3DFirstAxisDropDown.Value     = vars.grid.firstAxis;
            switch app.S3DFirstAxisDropDown.Value
                case 'X'
                    app.S3DSecondAxisDropDown.Items = {'Y', 'Z'};
                    app.S3DSecondAxisDropDown.Value = 'Z';
                case 'Y'
                    app.S3DSecondAxisDropDown.Items = {'X', 'Z'};
                    app.S3DSecondAxisDropDown.Value = 'Z';
                case 'Z'
                    app.S3DSecondAxisDropDown.Items = {'X', 'Y'};
                    app.S3DSecondAxisDropDown.Value = 'Y';   
            end
            app.S3DSecondAxisDropDown.Value = vars.grid.secondAxis;
        end
        
        function navVars = collectDVNavVars(app)
            navVars = scan3DGraphics.createNavigatorVars();
            navVars.navAx  = app.S3DNavPlainDropDown.Value;
            navVars.navIdx = app.S3DNavIdxSpinner.Value;
            navVars.navRep = app.S3DRepeatSpinner.Value;
            app.s3DData.navVars = navVars;
        end
        
        function fileSystem = collectDVFileSystemVars(app)
            app.s3DData.uVars.fileSystem.saveResults  = app.saveResultsandVarsCheckBox.Value;
            app.s3DData.uVars.fileSystem.saveFigs     = app.saveFigsCheckBox.Value;
            app.s3DData.uVars.fileSystem.dontSaveVars = false;
            
            app.s3DData.uVars.fileSystem.scanName    = app.MeasNameEditField.Value;
            app.s3DData.uVars.fileSystem.dirPath     = app.PathEditField.Value;
            
            app.s3DData.uVars.fileSystem.extProject         = false;
            app.s3DData.uVars.fileSystem.extProjPath        = [];
            app.s3DData.uVars.fileSystem.extProjResultsPath = [];
            app.s3DData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.s3DData.uVars.fileSystem; 
        end 

        %---------------- Consistency Functions ---------------%    
%         function uVarsConsisInit(app)
%            app.uVarsConsistency = Consistency.uVarsCreate();
%            
%            app.uVarsConsistency.gReq.extH.allFrames.fig  = app.AOIUIFigure;
%            app.uVarsConsistency.gReq.extH.allFrames.ax   = app.axAllFrames;
%            app.uVarsConsistency.gReq.extH.curFrame.fig   = app.AOIUIFigure;
%            app.uVarsConsistency.gReq.extH.curFrame.ax    = app.axCurFrame;
%            app.uVarsConsistency.gReq.extH.curSet.fig     = app.AOIUIFigure;
%            app.uVarsConsistency.gReq.extH.curSet.ax      = app.axCurSet;
%            app.uVarsConsistency.gReq.extH.navFrame.fig   = app.AOIUIFigure;
%            app.uVarsConsistency.gReq.extH.navFrame.ax    = app.axNavFrame;
%            app.uVarsConsistency.gReq.extH.navSet.fig     = app.AOIUIFigure;
%            app.uVarsConsistency.gReq.extH.navSet.ax      = app.axNavSet;
%            
%         end
%     
%         function uVarsConsisUpdate(app)
%             
%             app.collectAOVars;
%             app.deactivateAOfs();
%             app.uVarsConsistency.acoustoOptics = app.aoData.uVars;
% 
%             app.uVarsConsistency.stages.xPos = app.XpositionEditField.Value;
%             app.uVarsConsistency.stages.yPos = app.YpositionEditField.Value;
%             
%             app.uVarsConsistency.fileSystem.scanName        = app.MeasNameEditField.Value;
%             app.uVarsConsistency.fileSystem.resDirPath      = app.PathEditField.Value;
%             app.uVarsConsistency.fileSystem.saveFullData    = false;
%             app.uVarsConsistency.fileSystem.saveReducedData = false;
%             app.uVarsConsistency.fileSystem.saveFigs        = app.saveFigsCheckBox.Value;
%             app.uVarsConsistency.fileSystem.saveResults     = app.saveResultsandVarsCheckBox.Value;
%             
%             app.uVarsConsistency.scan.startTime = app.MinTimeFrameDurationEditField.Value;
%             app.uVarsConsistency.scan.stride    = app.TimeStrideEditField.Value;
%             app.uVarsConsistency.scan.endTime   = app.MaxTimeFrameDurationEditField.Value;
%             app.uVarsConsistency.scan.useQuant  = app.UseTimeFrameCheckBox.Value;
%             app.uVarsConsistency.scan.quantTime = app.FrameTimeEditField.Value;
%             app.uVarsConsistency.scan.numOfSets = app.SetsperTimeFrameEditField.Value;
% 
%             app.uVarsConsistency.gReq.ch    = app.SignalChannelSpinner.Value;
%             app.uVarsConsistency.gReq.zIdx  = app.ZCoorConsSpinner.Value;
%             app.uVarsConsistency.gReq.quant = 1;
%             
%             % Choose internal or external
%             if app.ExternalFiguresCheckBox.Value
%                 app.uVarsConsistency.gReq.intExt = 'int';
%             else
%                 app.uVarsConsistency.gReq.intExt = 'ext';
%             end
% 
%             names = fieldnames(app.uVarsConsistency.gReq.validStruct);
%             for i=1:length(names)
%                 app.uVarsConsistency.gReq.validStruct.(names{i}) = true;
%             end
%         end
        
        function staticVars = uVarsOAInit(app)
           app.oaData.uVars = optoAcoustic.uVarsCreate();
           
           app.oaData.uVars.figs.extH.sinogram.fig     = app.AOIUIFigure;
           app.oaData.uVars.figs.extH.sinogram.ax      = app.axOASinogram;
           app.oaData.uVars.figs.extH.recon.fig        = app.AOIUIFigure;
           app.oaData.uVars.figs.extH.recon.ax         = app.axOABP;
           app.oaData.uVars.figs.extH.navSinogram.fig  = app.AOIUIFigure;
           app.oaData.uVars.figs.extH.navSinogram.ax   = app.axOANavSinogram;
           app.oaData.uVars.figs.extH.navRecon.fig     = app.AOIUIFigure;
           app.oaData.uVars.figs.extH.navRecon.ax      = app.axOANavBP;

           app.oaData.uVars.figs.fonts.type = [];
           app.oaData.uVars.figs.fonts.titleSize  = 14;
           app.oaData.uVars.figs.fonts.labelsSize = 14;
           app.oaData.uVars.figs.fonts.axisSize   = 14;

           app.oaData.uVars.figs.validStruct.sinogram    = true;
           app.oaData.uVars.figs.validStruct.recon        = true;
           app.oaData.uVars.figs.validStruct.navSinogram  = true;
           app.oaData.uVars.figs.validStruct.navRecon     = true;

           staticVars.validStruct = app.oaData.uVars.figs.validStruct;
           staticVars.intExt      = app.oaData.uVars.figs.intExt;
           staticVars.extH        = app.oaData.uVars.figs.extH;
        end

        function uVars = collectOAVars(app)
            %DAQ
            app.oaData.uVars.activeChannels  = 1:256; 
            app.oaData.uVars.numOfSamples    = app.SamplesEditField.Value;
            app.oaData.uVars.numOfAvg        = app.AveragesEditField.Value;
            app.oaData.uVars.numOfFrames     = app.FramesEditField.Value;
            
            %BP
            app.oaData.uVars.c  = app.SpeedinMediumEditField.Value;
            app.oaData.uVars.fs = app.SamplingRateEditField.Value;
            app.oaData.uVars.geometry = app.GeometryDropDown.Value;
            app.oaData.uVars.imageWidth = app.ImageWidthEditField.Value*1e-3; %[m]
            switch app.BPModeDropDown.Value
                case 'Direct'
                    app.oaData.uVars.BPmode = 1;
                case 'Derivative'
                    app.oaData.uVars.BPmode = 2;
                case 'Full'
                    app.oaData.uVars.BPmode = 3;
            end
            
            % GRID and Stages
            app.oaData.uVars.scanStart  = app.StartSinglePosEditField.Value;
            app.oaData.uVars.scanStride = app.StrideEditField.Value;
            app.oaData.uVars.scanEnd    = app.EndEditField.Value;
            
            app.oaData.uVars.firstAxis  = app.FirstAxisEditField.Value;
            app.oaData.uVars.secondAxis = app.SecondAxisEditField.Value;
            app.oaData.uVars.scanAxis   = app.ScanAxisEditField.Value;
            
            % Graphics
            app.collectOAGraphicsVars();
            
            uVars = app.oaData.uVars;
        end
        
        function figs = collectOAGraphicsVars(app)

            app.oaData.uVars.figs.useExtClims   = false;
            app.oaData.uVars.figs.reopenFigures = app.ReOpenFiguresCheckBox.Value;

            app.oaData.uVars.figs.firstAxType  = app.OAFirstScanAxTypeDropDown.Value;
            app.oaData.uVars.figs.secondAxType = app.OASecondScanAxTypeDropDown.Value;
            app.oaData.uVars.figs.scanAxType   = app.OAScanAxTypeDropDown.Value;
            app.oaData.uVars.figs.timeAxType   = app.OATimeAxTypeDropDown.Value;
            
            app.oaData.uVars.figs.scanAxCoor   = app.ScanAxisEditField.Value;

            app.oaData.uVars.figs.useExtClims = false;
            app.oaData.uVars.figs.reopenFigures = app.ReOpenFiguresCheckBox.Value;
            
            if app.ExternalFiguresCheckBox.Value
                app.oaData.uVars.figs.intExt = 'int';
            else
                app.oaData.uVars.figs.intExt = 'ext';
            end
            
            figs = app.oaData.uVars.figs;
        end
        
        function updateOANAvigatorSpinner(app)
            app.OANavIdxSpinner.Limits = [1, app.oaData.curVars.measVars.grid.scanIdxLen];
            app.OANavIdxSpinner.Value = 1;
            app.updateOANavigatorLabel();
        end
        
        function updateOANavigatorLabel(app)
           idx = app.OANavIdxSpinner.Value;
           app.mmLabel_42.Text = sprintf("%.2f[mm]", app.oaGraphics.figs.scanAxVec(idx));
        end
        
        
        function staticVars = uVarsPEUSInit(app)
           app.peusData.uVars = pulseEchoUS.uVarsCreate();
           
           app.peusData.uVars.stagesOwnedByParent = true;
           app.peusData.uVars.figs.extH.rawData.fig     = app.AOIUIFigure;
           app.peusData.uVars.figs.extH.rawData.ax      = app.axPEUSRaw;
           app.peusData.uVars.figs.extH.recon.fig       = app.AOIUIFigure;
           app.peusData.uVars.figs.extH.recon.ax        = app.axPEUSCorrected;

           app.peusData.uVars.figs.fonts.type = [];
           app.peusData.uVars.figs.fonts.titleSize  = 14;
           app.peusData.uVars.figs.fonts.labelsSize = 14;
           app.peusData.uVars.figs.fonts.axisSize   = 14;

           app.peusData.uVars.figs.validStruct.rawData     = true;
           app.peusData.uVars.figs.validStruct.recon       = true;

           staticVars.validStruct = app.peusData.uVars.figs.validStruct;
           staticVars.intExt      = app.peusData.uVars.figs.intExt;
           staticVars.extH        = app.peusData.uVars.figs.extH;
        end
        
        function uVars = collectPEUSVars(app)
            %DAQ
            app.peusData.uVars.distFromCenter = app.PEUSDistancefromImageCenterEditField.Value*1e-2; 
            app.peusData.uVars.imageWidth     = app.PEUSImageWidthEditField.Value*1e-3;
            
            app.peusData.uVars.signalChannel = app.PEUSChannelNoEditField.Value;
            app.peusData.uVars.trigCh        = app.PEUSTrigChannelEditField.Value;
            app.peusData.uVars.avg           = app.PEUSNumofAvgEditField.Value;
            app.peusData.uVars.usRepRate     = app.PEUSRepetitionRateEditField.Value;
            app.peusData.uVars.pulseIntVal   = app.PEUSIntValEditField.Value;
            
            app.peusData.uVars.c              = app.SpeedinMediumEditField.Value;
            app.peusData.uVars.usFreq         = app.SineFreqEditField.Value*1e6;
            app.peusData.uVars.trigCh           = app.PEUSTrigChannelEditField.Value;
            app.peusData.uVars.avg              = app.PEUSNumofAvgEditField.Value;
            app.peusData.uVars.usRepRate        = app.PEUSRepetitionRateEditField.Value;
            app.peusData.uVars.pulseIntVal      = app.PEUSIntValEditField.Value; %[m]
 
            % GRID and Stages
            app.peusData.uVars.moveSecondStage = ~app.PEUSDontmovesecondscanaxisCheckBox.Value;
            
            app.peusData.uVars.scanStart  = app.PEUSStartEditField.Value;
            app.peusData.uVars.scanStride = app.PEUSStrideEditField.Value;
            app.peusData.uVars.scanEnd    = app.PEUSEndEditField.Value;
            
            app.peusData.uVars.scanAxis  = app.PEUSScanAxisEditField.Value;
            app.peusData.uVars.depthAxis = app.PEUSDepthAxisEditField.Value;
            app.peusData.uVars.thirdAxis = app.PEUSThirdsAxisEditField.Value;
            
            app.peusData.uVars.thirdPos = 0;
            
            % Graphics
            app.collectPEUSGraphicsVars();
            
            uVars = app.peusData.uVars;
        end
        
        function figs = collectPEUSGraphicsVars(app)
            if app.ExternalFiguresCheckBox.Value
                app.peusData.uVars.figs.intExt = 'int';
            else
                app.peusData.uVars.figs.intExt = 'ext';
            end
            
            app.peusData.uVars.figs.useExtClims   = false;
            app.peusData.uVars.figs.reopenFigures = app.ReOpenFiguresCheckBox.Value;
            
            app.peusData.uVars.figs.depthAxType   = app.PEUSFirstScanAxTypeDropDown.Value;
            app.peusData.uVars.figs.scanAxType    = app.PEUSSecondScanAxTypeDropDown.Value;

            figs = app.peusData.uVars.figs;
        end
       
        function staticVars = uVarsIntSysInit(app)
           app.intSysData.uVars = IntSys.uVarsCreate();
           
           app.intSysData.uVars.figs.extH.corrected.fig     = app.AOIUIFigure;
           app.intSysData.uVars.figs.extH.corrected.ax      = app.axCorrectedOA;

           app.intSysData.uVars.figs.fonts.type = [];
           app.intSysData.uVars.figs.fonts.titleSize  = 14;
           app.intSysData.uVars.figs.fonts.labelsSize = 14;
           app.intSysData.uVars.figs.fonts.axisSize   = 14;

           app.intSysData.uVars.figs.validStruct.corrected    = true;

           staticVars.validStruct = app.intSysData.uVars.figs.validStruct;
           staticVars.intExt      = app.intSysData.uVars.figs.intExt;
           staticVars.extH        = app.intSysData.uVars.figs.extH;
        end
        
        function uVars = collectIntSysVars(app)
            %DAQ
            app.intSysData.uVars.distFromCenter    = app.IntSysDistanceFromImageCenterEditField.Value*1e-3;
            app.intSysData.uVars.imageWidth        = app.IntSysImageWidthEditField.Value*1e-2; 
            app.intSysData.uVars.c                 = app.SpeedinMediumEditField.Value;
            
            app.intSysData.uVars.grid.aoScanStart  = app.AOScanStartEditField.Value; 
            app.intSysData.uVars.grid.aoScanStride = app.AOScanStrideEditField.Value;
            app.intSysData.uVars.grid.aoScanEnd    = app.AOScanEndEditField.Value;
            
            app.intSysData.uVars.grid.oaScanStart  = app.OAScanStartEditField.Value;
            app.intSysData.uVars.grid.oaScanStride = app.OAScanStrideEditField.Value;
            app.intSysData.uVars.grid.oaScanEnd    = app.OAScanEndEditField.Value;
            
            app.intSysData.uVars.grid.aoScanAxis   = app.AOScanAxisEditField.Value; % should be constantlu defined
            app.intSysData.uVars.grid.oaScanAxis   = app.OAScanAxisEditField.Value; % should be constantlu defined
            app.intSysData.uVars.grid.depthAxis    = app.AODepthAxisEditField.Value;
            
            app.intSysData.uVars.aoS2D = app.collectS2DVars();
            app.intSysData.uVars.oa    = app.collectOAVars();
            app.intSysData.uVars.peus  = app.collectPEUSVars();
            
            % Graphics
            app.collectIntSysGraphicsVars();
            
            uVars = app.intSysData.uVars;
        end
        
        function figs = collectIntSysGraphicsVars(app)
            if app.ExternalFiguresCheckBox.Value
                app.intSysData.uVars.figs.intExt = 'int';
            else
                app.intSysData.uVars.figs.intExt = 'ext';
            end
            
            app.intSysData.uVars.figs.useExtClims   = false;
            app.intSysData.uVars.figs.reopenFigures = app.ReOpenFiguresCheckBox.Value;
            
            app.peusData.uVars.figs.depthAxType   = app.PEUSFirstScanAxTypeDropDown.Value;
            app.peusData.uVars.figs.scanAxType    = app.PEUSSecondScanAxTypeDropDown.Value;

            figs = app.peusData.uVars.figs;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            f = waitbar(0,'Starting GUI: Resetting Peripherals');
            if evalin('base', "exist('ao')"); evalin('base', "clear('ao')"); end
            if evalin('base', "exist('oa')"); evalin('base', "clear('oa')"); end
            if evalin('base', "exist('s2D')"); evalin('base', "clear('s2D')"); end
            if evalin('base', "exist('s3D')"); evalin('base', "clear('s3D')"); end
            if evalin('base', "exist('stagesAO')"); evalin('base', "clear('stagesAO')"); end
%             if evalin('base', "exist('dv')"); evalin('base', "clear('dv')"); end
            if evalin('base', "exist('peus')"); evalin('base', "clear('peus')"); end
            if evalin('base', "exist('intSys')"); evalin('base', "clear('intSys')"); end
            if evalin('base', "exist('app')"); evalin('base', "clear('app')"); end

            instrreset;

            %-------- Acousto Optics ---------%
            waitbar(.1,f,'Initing Acousto Optics Object');
            app.initAOVars();
            app.ao = acoustoOptics();
            app.ao.init(app);
            app.aoGraphics = app.ao.getGraphicsHandle();

            app.aoData.importedVars = [];
            app.aoData.importedRes  = [];
            app.aoData.curVars      = [];
            app.aoData.curRes       = [];
       
            app.GUIctrl.restartAO = true;  % to init AO object in the beginning of the first run
            app.GUIctrl.liveAO    = false; % for toggling the button Run Live \ Stop Live
            
            % --------- Algorithm ------- %
            waitbar(.2,f,'Initing Algorithm Object');
            app.initAlgoVars();
            app.algo = Algo();
            
            %-------- Stages ---------%
            waitbar(.3,f,'Connecting to Stages');
            
            prompt = {'Choose Stages to connect:'; 'Port'};
            dlgtitle = 'Choose Your Weapon';
            dims = [1 35];
            definput = {'Zaber'; 'COM3'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            app.aoStages = stages(answer{1}, answer{2});
            app.aoStages.connect();
            app.stagesData.avail = app.aoStages.hardwareAvailable;
            app.stagesData.assigned = false;
            if ~app.stagesData.avail
                uiwait(msgbox('NOTICE: No stages Are available'));
                app.stagesData.numOfStages = 0;
                app.stage1AxDropDown.Items = 'No Stages';
                app.stage1AxDropDown.Value = 'No Stages';
                app.stage2AxDropDown.Items = 'No Stages';
                app.stage2AxDropDown.Value = 'No Stages';
                app.stagesData.stage1Id = 0;
                app.stagesData.stage1Ax = 'X';
                app.stagesData.stage2Id = 0;
                app.stagesData.stage2Ax = 'Y';
                app.SetStagesGeometryButton.Enable = false;
                app.stagesTable.Data = zeros(4,1);
                app.stagesTable.Enable = false;
            else
                app.stagesData.devicesIDs  = app.aoStages.getLogicIDs();
                app.stagesData.numOfStages = app.aoStages.vars.numOfStages;
                devicesIdsStr = cell (1,app.stagesData.numOfStages);
                for i = 1:app.stagesData.numOfStages
                    devicesIdsStr{i} = num2str(app.stagesData.devicesIDs(i));
                end
                
                axOrder = app.aoStages.getAssignment();
                for i = 1:length(axOrder)
                    app.stagesData.axOrder{i} = axOrder(i);
                end
                
                for i = 1:3 
                    panelName = sprintf("Stage%dPanel", i);
                    dropName  = sprintf("stage%dAxDropDown", i);
                    if i <= app.stagesData.numOfStages
                        app.(panelName).Title = sprintf("Stage %d (%s)", i, app.stagesData.axOrder{i});
                        
                        for j =1:length(app.(panelName).Children)
                            app.(panelName).Children(j).Enable = true;
                        end
                        
                        app.(dropName).Enable = true;
                        app.(dropName).Items = ['Not Assigned', app.stagesData.axOrder];
                        app.(dropName).Value = app.stagesData.axOrder{i};
                    else
                        for j =1:length(app.(panelName).Children)
                            app.(panelName).Children(j).Enable = false;
                        end
                        app.(dropName).Enable = false;
                    end
                end
    
                app.stagesData.assigned = true;
                app.SetStagesGeometryButton.Enable = false;
                app.stagesTable.Data = zeros(4,app.stagesData.numOfStages);
                app.stagesTable.Data(1,1:app.stagesData.numOfStages) = app.aoStages.getPosition();
            end
            % --------- Scope -------%
%             waitbar(.3,f,'Connecting to Stages');
            Scope_visa_address = 'USB0::0x1AB1::0x04CE::DS1ZA172115085::0::INSTR';
            app.scope = Rigol_DS1074z('ni',Scope_visa_address);
            
            %-------- OptoAcoustics -------%
            waitbar(.4,f,'Creatign Opto Acoustics Object');
            app.uVarsOAInit();
            app.oa = optoAcoustic(app.aoStages);
            app.oaGraphics = app.oa.graphics;
            
            app.oaData.importedVars = [];
            app.oaData.importedRes  = [];
            app.oaData.curVars      = [];
            app.oaData.curRes       = [];

            %-------- Scan 2D --------%
            waitbar(.5,f,'Creatign 2D Scan Object');
            app.uVarsS2DInit();
            app.s2D         = scan2DAO(app.ao, app.aoStages);
            app.s2DGraphics = app.s2D.getGraphicsHandle();
            
            app.s2DData.importedVars = [];
            app.s2DData.importedRes  = [];
            app.s2DData.curVars     = [];
            app.s2DData.curRes       = [];
            
            % ------- Scan 3D --------%
            waitbar(.6,f,'Creatign 3D Scan Object');
            handles.stages = app.aoStages;
            handles.s2D = app.s2D;
            app.uVarsS3DInit();
            app.s3D            = scan3DAO(handles);
            app.s3DGraphics    = app.s3D.getGraphicsHandle();
            
            app.s3DData.importedVars = [];
            app.s3DData.importedRes  = [];
            app.s3DData.curVars     = [];
            app.s3DData.curRes       = [];
            
            % ------- Deep View --------%
            waitbar(.7,f,'Creatign Deep View Scan Object');
            app.uVarsS2DInit();
            app.dv            = deepView(app.s2D);
            app.dvGraphics    = app.dv.getGraphicsHandle();
            
            app.dvData.importedVars = [];
            app.dvData.importedRes  = [];
            app.dvData.curVars     = [];
            app.dvData.curRes       = [];
            
            % ------- PEUS --------%
            waitbar(.8,f,'Creatign PEUS Scan Object');
            
            app.uVarsPEUSInit();
            app.peus = pulseEchoUS(app.scope, app.aoStages);
            app.peusGraphics = app.peus.graphics;
            
            app.peusData.importedVars = [];
            app.peusData.importedRes  = [];
            app.peusData.curVars     = [];
            app.peusData.curRes       = [];
            
            % ------- Int-Sys --------%
            waitbar(.9,f,'Creatign Int Sys Scan Object');
            
            app.uVarsIntSysInit();
            
            handles.aoS2D  = app.s2D;
            handles.stages = app.aoStages;
            handles.oa     = app.oa;
            handles.peUS   = app.peus;
            
            app.intSys = IntSys(handles);
%             app.intSysGraphics = app.intSys.graphics;
            
            app.intSysData.importedVars = [];
            app.intSysData.importedRes  = [];
            app.intSysData.curVars     = [];
            app.intSysData.curRes       = [];
            
            %-------- Managing GUI ------%
            app.MainAxisTab.Parent          = app.InvisiblePlotTabs;
            app.MainPlaneTab.Parent         = app.InvisiblePlotTabs;
            
           
            app.MainAxisAveragedTab.Parent  = app.InvisiblePlotTabs;
            app.MainPlaneAveragedTab.Parent = app.InvisiblePlotTabs;
            
            app.MainAxisTab.Parent          = app.ScanPlotTab;
            app.MainAxisLogTab.Parent       = app.ScanPlotTab;
            app.MainAxisAveragedTab.Parent  = app.InvisiblePlotTabs;
            app.MainPlaneTab.Parent         = app.ScanPlotTab;
            app.MainPlaneLogTab.Parent      = app.ScanPlotTab;
            app.MainPlaneAveragedTab.Parent = app.InvisiblePlotTabs;
            
            app.NavigatorTab.Parent         = app.ScanPlotTab;
            app.NavigatorAveragedTab.Parent = app.ScanPlotTab;
            
            app.DeepViewPlotTab.Parent      = app.InvisiblePlotTabs;
            
            app.ConsistencyPlotTab.Parent   = app.InvisiblePlotTabs;
            app.CurrentFrameTab.Parent      = app.InvisiblePlotTabs;
            app.FramesNavigatorTab.Parent   = app.InvisiblePlotTabs;
            
            app.BPTab.Parent            = app.InvisiblePlotTabs;
            app.SinogramTab.Parent      = app.InvisiblePlotTabs;
            app.OANavBPTab.Parent       = app.InvisiblePlotTabs;
            app.OANavSinogramTab.Parent = app.InvisiblePlotTabs;
            
            app.PEUSRawTab.Parent            = app.InvisiblePlotTabs;
            app.PEUSCorrectedTab.Parent      = app.InvisiblePlotTabs;
            
            app.CorrectedOATab.Parent        = app.InvisiblePlotTabs;
            
            % ---------- Assigning object to base workspace --------%
            waitbar(1, f, 'Assigining Objects to workspace');
            assignin('base', 'ao',      app.ao);
            assignin('base', 'oa',      app.oa);
            assignin('base', 's2D',     app.s2D);
            assignin('base', 's3D',     app.s3D);
            assignin('base', 'stagesAO',  app.aoStages);
%             assignin('base', 'dv',      app.dv);
            assignin('base', 'peus',    app.peus);
            assignin('base', 'intSys',  app.intSys);
            assignin('base', 'app',     app);
            
            close(f)
        end

        % Close request function: AOIUIFigure
        function AOIUIFigureCloseRequest(app, event)
            try
                app.aoStages.delete();
                if app.oa.daq.vars.connected
                    app.oa.daq.disconnect();
                end
            catch
                
            end
            delete(app)
        end

        % Size changed function: AOIUIFigure
        function AOIUIFigureSizeChanged(app, event)
%             position = app.AOIUIFigure.Position;
            
        end

        % Button pushed function: AORunButton
        function AORunButtonPushed(app, event)
            if app.stagesData.avail && ~app.AOMeasHereCheckBox.Value && ~app.stagesData.assigned
                uiwait(msgbox('NOTICE: Stages are not assigned!'));
                return
            end
            
            if ~app.AOMeasHereCheckBox.Value
                app.aoStages.moveStageAxisAbs('X', app.AOXpositionEditField.Value)
                app.aoStages.moveStageAxisAbs('Y', app.AOYpositionEditField.Value)
            end
            
            app.collectAOVars();
            app.ao.setVars(app.aoData.uVars);
            app.ao.configPeripherals();
            
            t = tic;
            app.aoData.curRes    = app.ao.runAcoustoOptics();
            t = toc(t);
            
            app.aoData.curVars = app.ao.getVars();
            
            app.updateAOPlotChSpinnerLimits();
            app.updateAOPlotZIdxSpinnerLimits();
            app.updateFrameSpinnerLimits();
            
            timeTable = app.ao.getTimeTable();
            dataAlgo  = app.extractStructDataToTable(timeTable.algo);
            dataDigi  = app.extractStructDataToTable(timeTable.digitizer);
            dataAO(1,1:2) = {'Overall',t}; 
            dataAO(2,1:2) = {'Overall Sampling',timeTable.acq}; 
            dataAO(3,1:2) = {'Overall Analysis',timeTable.reconstruct}; 
            dataAO(4,1:2) = {'Gather Raw Data', timeTable.moveData};
            dataAO(5,1:2) = {'Data to Graphics', timeTable.setResultsToGraphics};
            dataAO(6,1:2) = {'Plots', timeTable.plotAll};
            dataAO(7,1:2) = {'Save data', timeTable.moveData};
            
            app.UITable2.Data   = dataAlgo(:,1:2);
            app.UITable2_2.Data = dataDigi(:,1:2);
            app.UITable2_3.Data = dataAO;
        end

        % Button pushed function: AOLiveRunButton
        function AOLiveRunButtonPushed(app, event)
            if app.GUIctrl.liveAO 
                fprintf("GUI: Stopping Live AO, Please wait...\n");
                app.ao.runLive = false;
                app.AOLiveRunButton.Text = 'Run Live';
                app.GUIctrl.liveAO = false;
            else
                if app.GUIctrl.restartAO
                    app.ao.init();
                    app.GUIctrl.restartAO = false;
                end
                app.collectAOVars();
                
                app.ao.setVars(app.aoData.uVars);
                app.ao.configPeripherals();
                
                % For spinner update:
                app.aoData.curVars = app.ao.getVars();
                %insert function that update the spiner limits
                
                app.AOLiveRunButton.Text = 'Stop Live';
                
                app.GUIctrl.liveAO = true;
                app.aoData.curRes = app.ao.liveAcoustoOptics();
                app.AOLiveRunButton.Text = 'Run Live';
                app.GUIctrl.liveAO = false;
            end
        end

        % Button pushed function: analysisVarsPathButton
        function analysisVarsPathButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            app.VarsEditField.Value = [path, filename]; 
            app.AnalyzeOrigButton.Enable = false;
            app.AnalyizeNewButton.Enable = false;
        end

        % Button pushed function: analysisDataPathButton
        function analysisDataPathButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            app.DataEditField.Value = [path, filename];
            app.AnalyzeOrigButton.Enable = false;
            app.AnalyizeNewButton.Enable = false;
        end

        % Button pushed function: ImportButton
        function ImporAOResultstButtonPushed(app, event)
            rawDataPath = app.DataEditField.Value;
            varsPath    = app.VarsEditField.Value;
            fprintf("GUI: Trying to load results and vars\n");
            rawData     = app.ao.loadRawDataToAO(rawDataPath);
            vars        = load(varsPath);
            if ~isempty(rawData) && ~isempty(vars) 
                app.AnalyzeOrigButton.Enable = true;
                app.AnalyizeNewButton.Enable = true;
                app.aoData.importedVars = vars;
                app.aoData.importedData = rawData;
                app.setAOVarsToGUI(vars.uVars);
                fprintf("GUI: Successfully loaded results and vars.\n");
            else
                app.AnalyzeOrigButton.Enable = false;
                app.AnalyizeNewButton.Enable = false;
                fprintf("GUI: Invalid files\n");
            end
        end

        % Button pushed function: AnalyzeOrigButton
        function AnalyzeOrigButtonPushed(app, event)
            vars = app.aoData.importedVars;
            
            app.setAOVarsToGUI(app.aoData.importedVars.uVars); %not sure this is convinient to the user
            
            app.collectAOGraphicVars();
            app.collectAOFileSystemVars();
             
            vars.uVars.figs       = app.aoData.uVars.figs; %plot analysed data according to user request
            vars.uVars.fileSystem = app.aoData.uVars.fileSystem; %save analysed data according to user request
            
            app.ao.setMeasVars(vars.uVars);
            app.aoData.curRes  = app.ao.analyseLoadedData();
            app.aoData.curVars = app.ao.getAOVars();
        end

        % Button pushed function: AnalyizeNewButton
        function AnalyizeNewButtonPushed(app, event)
            app.collectAOVars();
            app.ao.setMeasVars(app.aoData.uVars);
            app.aoData.curRes  = app.ao.analyseLoadedData();
            app.aoData.curVars = app.ao.getAOVars();
        end

        % Value changed function: AODepthIdxSpinner
        function AODepthIdxSpinnerValueChanged(app, event)
            app.updateAOZIdxSpinnerLabel();
            app.updateAOFigs();
        end

        % Callback function
        function StagesCalibToolButtonPushed(app, event)
             app.s2dStagesRecon  = true; %TODO: integrate stages calib tool to this gui.
             app.consStagesRecon = true;
             h = stagesCalib(app);
             waitfor(h);
             applyImportedLimits(app);
        end

        % Button pushed function: S3DImportLimitsfromfileButton
        function S3DImportLimitsfromfileButtonPushed(app, event)
            [filename, path] = uigetfile('*.mat');
            app.importedLimits = load([path, filename]);
            applyImportedLimits(app);
        end

        % Menu selected function: PMTSaturationInspector
        function PMTSaturationInspectorMenuSelected(app, event)
            PMTSat;
        end

        % Menu selected function: ScanAssistantMenu
        function ScanAssistantMenuSelected(app, event)
             h = stagesCalib(app);
             waitfor(h);
             applyImportedLimits(app);
        end

        % Value changed function: S3DNavPlainDropDown
        function S3DNavPlainDropDownValueChanged(app, event)
            app.updateS3DNavIdxSpinnerLimits();           
            app.updateS3DNavigator();
        end

        % Value changed function: S3DNavIdxSpinner
        function S3DNavIdxSpinnerValueChanged(app, event)
            app.updateS3DNavIdxLabel();
            app.updateS3DNavigator();
        end

        % Value changed function: S3DRepeatSpinner
        function S3DRepeatSpinnerValueChanged(app, event)
            app.updateS3DNavIdxLabel();
            app.updateS3DNavigator();
        end

        % Callback function
        function S3DCurDepthCoorSpinnerValueChanged(app, event)
            app.updateS3DMainAxisZSpinnerLabel();
            app.updateS3DCurFigures();
        end

        % Button pushed function: S3DRunButton
        function S3DRunButtonPushed(app, event)
            app.collectS3DVars();
            
            app.s3D.setUserVars(app.s3DData.uVars);
            app.s3DData.curVars = app.s3D.getVars();
            app.s2DData.curVars = app.s2D.getVars();
            app.aoData.curVars  = app.ao.getVars();
            
            app.updateS3DGUIComponents();
            
            app.updateAOPlotChSpinnerLimits();
            app.updateAOPlotZIdxSpinnerLimits();
            app.updateFrameSpinnerLimits();
            
            app.s3D.configureScan()
            app.s3DData.curRes  = app.s3D.scan3D();
            app.aoData.curRes   = app.ao.getResult;
        end

        % Button pushed function: S3DImportResultsButton
        function ImportS2DscanResultsButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', 'Select Scan Results *.mat file');
            if isempty(filename)
                fprintf("GUI: No loaded data\n");
                return;
            end
            h = waitbar(0,'Loading Results. Please wait...');
            data = load([path,filename]);
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', 'Select Scan Variables *.mat file');
            if isempty(filename)
                fprintf("GUI: No loaded vars\n");
                close(h);
                return;
            end
            waitbar(.5,h,'Loading Variables. Please wait');
            vars = load([path,filename]);
            waitbar(1,h,'Done');
            close(h)

            if isfield(vars, 'uVars')
                app.setS3DVarsToGUI(vars.uVars);
                app.setAOVarsToGUI(vars.uVars.ao);
                app.s3DData.curVars = vars;
                app.s3DData.curData = data;
                vars.uVars.figs = app.collectS3DGraphicsVars();
                app.s3D.setLoaded3DData(data, vars);
                app.updateS3DGUIComponents();
            else
                fprintf("GUI: Failed to load variables. No uVars field found.\n");
            end
        end

        % Value changed function: S3DRepeatsEditField
        function S3DRepeatsEditFieldValueChanged(app, event)
            app.updateRepeatsSpinnerLimits();
        end

        % Button pushed function: dirBrowseButton
        function dirBrowseButtonPushed(app, event)
            app.PathEditField.Value = uigetdir('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements');
        end

        % Selection change function: MeasMethod
        function MeasMethodSelectionChanged(app, event)
            selectedTab = app.MeasMethod.SelectedTab;
            switch(selectedTab.Title)               
                case 'Consistency'
                    app.MainAxisTab.Parent          = app.InvisiblePlotTabs;
                    app.MainPlaneTab.Parent         = app.InvisiblePlotTabs;
                    app.MainAxisLogTab.Parent       = app.InvisiblePlotTabs;
                    app.MainPlaneLogTab.Parent      = app.InvisiblePlotTabs;
                    app.MainAxisAveragedTab.Parent  = app.InvisiblePlotTabs;
                    app.MainPlaneAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.NavigatorTab.Parent         = app.InvisiblePlotTabs;
                    app.NavigatorAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.DeepViewPlotTab.Parent      = app.InvisiblePlotTabs;
                    
                    app.ConsistencyPlotTab.Parent   = app.ScanPlotTab;
                    app.CurrentFrameTab.Parent      = app.ScanPlotTab;
                    app.FramesNavigatorTab.Parent   = app.ScanPlotTab;
                    
                    app.BPTab.Parent            = app.InvisiblePlotTabs;
                    app.SinogramTab.Parent      = app.InvisiblePlotTabs;
                    app.OANavBPTab.Parent       = app.InvisiblePlotTabs;
                    app.OANavSinogramTab.Parent = app.InvisiblePlotTabs;
                    
                    app.PEUSRawTab.Parent            = app.InvisiblePlotTabs;
                    app.PEUSCorrectedTab.Parent      = app.InvisiblePlotTabs;
                    
                    app.CorrectedOATab.Parent        = app.InvisiblePlotTabs;
                    
                    app.MeasNameEditField.Value = 'Consistency';
                case 'Scan 3D'
                    app.MainAxisTab.Parent          = app.InvisiblePlotTabs;%InvisiblePlotTabs
                    app.MainAxisLogTab.Parent       = app.InvisiblePlotTabs;%InvisiblePlotTabs
                    app.MainPlaneTab.Parent         = app.ScanPlotTab;
                    app.MainPlaneLogTab.Parent      = app.ScanPlotTab;
                    app.MainAxisAveragedTab.Parent  = app.InvisiblePlotTabs;%InvisiblePlotTabs
                    app.MainPlaneAveragedTab.Parent = app.ScanPlotTab;
                                      
                    app.NavigatorTab.Parent         = app.ScanPlotTab;
                    app.NavigatorAveragedTab.Parent = app.ScanPlotTab;
                    
                    app.DeepViewPlotTab.Parent      = app.InvisiblePlotTabs;
                    
                    app.ConsistencyPlotTab.Parent   = app.InvisiblePlotTabs;
                    app.CurrentFrameTab.Parent      = app.InvisiblePlotTabs;
                    app.FramesNavigatorTab.Parent   = app.InvisiblePlotTabs;
                    
                    app.BPTab.Parent            = app.InvisiblePlotTabs;
                    app.SinogramTab.Parent      = app.InvisiblePlotTabs;
                    app.OANavBPTab.Parent       = app.InvisiblePlotTabs;
                    app.OANavSinogramTab.Parent = app.InvisiblePlotTabs;
                    
                    app.PEUSRawTab.Parent             = app.InvisiblePlotTabs;
                    app.PEUSCorrectedTab.Parent       = app.InvisiblePlotTabs;
                    
                    app.CorrectedOATab.Parent         = app.InvisiblePlotTabs;
                    
                    app.MeasNameEditField.Value = 'Scan3D';
                    
%                     tabs = app.ScanPlotTab.Children;
%                     modified_tabs = [tabs(2);tabs(3);tabs(1)];
%                     app.ScanPlotTab.Children = modified_tabs;
                    
                    
                case 'Scan 2D'
                    app.MainAxisTab.Parent          = app.InvisiblePlotTabs;
                    app.MainAxisLogTab.Parent       = app.InvisiblePlotTabs;
                    app.MainPlaneTab.Parent         = app.ScanPlotTab;
                    app.MainPlaneLogTab.Parent      = app.ScanPlotTab;
                    app.MainAxisAveragedTab.Parent  = app.InvisiblePlotTabs;
                    app.MainPlaneAveragedTab.Parent = app.ScanPlotTab;
                    
                    app.NavigatorTab.Parent         = app.InvisiblePlotTabs;
                    app.NavigatorAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.DeepViewPlotTab.Parent      = app.InvisiblePlotTabs;
                    
                    app.ConsistencyPlotTab.Parent   = app.InvisiblePlotTabs;
                    app.CurrentFrameTab.Parent      = app.InvisiblePlotTabs;
                    app.FramesNavigatorTab.Parent   = app.InvisiblePlotTabs;

                    app.BPTab.Parent            = app.InvisiblePlotTabs;
                    app.SinogramTab.Parent      = app.InvisiblePlotTabs;
                    app.OANavBPTab.Parent       = app.InvisiblePlotTabs;
                    app.OANavSinogramTab.Parent = app.InvisiblePlotTabs;
                    
                    app.PEUSRawTab.Parent             = app.InvisiblePlotTabs;
                    app.PEUSCorrectedTab.Parent       = app.InvisiblePlotTabs;
                    
                    app.CorrectedOATab.Parent         = app.InvisiblePlotTabs;
                    
                    app.MeasNameEditField.Value = 'Scan2D';
                    
                case 'Single Measurement'
                    app.MeasNameEditField.Value = 'AcoustoOptics';
                case 'Deep View'
                    app.MainAxisTab.Parent          = app.ScanPlotTab;
                    app.MainAxisLogTab.Parent       = app.ScanPlotTab;
                    app.MainPlaneTab.Parent         = app.ScanPlotTab;
                    app.MainPlaneLogTab.Parent      = app.ScanPlotTab;
                    app.MainAxisAveragedTab.Parent  = app.ScanPlotTab;
                    app.MainPlaneAveragedTab.Parent = app.ScanPlotTab;
                    
                    app.NavigatorTab.Parent         = app.InvisiblePlotTabs;
                    app.NavigatorAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.DeepViewPlotTab.Parent      = app.ScanPlotTab;
                    
                    app.ConsistencyPlotTab.Parent   = app.InvisiblePlotTabs;
                    app.CurrentFrameTab.Parent      = app.InvisiblePlotTabs;
                    app.FramesNavigatorTab.Parent   = app.InvisiblePlotTabs;
                    
                    app.BPTab.Parent            = app.InvisiblePlotTabs;
                    app.SinogramTab.Parent      = app.InvisiblePlotTabs;
                    app.OANavBPTab.Parent       = app.InvisiblePlotTabs;
                    app.OANavSinogramTab.Parent = app.InvisiblePlotTabs;
                    
                    app.PEUSRawTab.Parent             = app.InvisiblePlotTabs;
                    app.PEUSCorrectedTab.Parent       = app.InvisiblePlotTabs;
                    
                    app.CorrectedOATab.Parent         = app.InvisiblePlotTabs;
                    
                    app.MeasNameEditField.Value = 'DeepView';
                case 'OptoAcoustics'
                    app.MainAxisTab.Parent          = app.InvisiblePlotTabs;
                    app.MainAxisLogTab.Parent       = app.InvisiblePlotTabs;
                    app.MainPlaneTab.Parent         = app.InvisiblePlotTabs;
                    app.MainPlaneLogTab.Parent      = app.InvisiblePlotTabs;
                    app.MainAxisAveragedTab.Parent  = app.InvisiblePlotTabs;
                    app.MainPlaneAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.NavigatorTab.Parent         = app.InvisiblePlotTabs;
                    app.NavigatorAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.DeepViewPlotTab.Parent      = app.InvisiblePlotTabs;
                    
                    app.ConsistencyPlotTab.Parent   = app.InvisiblePlotTabs;
                    app.CurrentFrameTab.Parent      = app.InvisiblePlotTabs;
                    app.FramesNavigatorTab.Parent   = app.InvisiblePlotTabs;
                    
                    app.BPTab.Parent            = app.ScanPlotTab;
                    app.SinogramTab.Parent      = app.ScanPlotTab;
                    app.OANavBPTab.Parent       = app.ScanPlotTab;
                    app.OANavSinogramTab.Parent = app.ScanPlotTab;
                    
                    app.PEUSRawTab.Parent             = app.InvisiblePlotTabs;
                    app.PEUSCorrectedTab.Parent       = app.InvisiblePlotTabs;
                    
                    app.CorrectedOATab.Parent         = app.InvisiblePlotTabs;
                    
                    app.MeasNameEditField.Value = 'OA';
                    
                case 'Pulse-Echo US'
                    app.MainAxisTab.Parent          = app.InvisiblePlotTabs;
                    app.MainAxisLogTab.Parent       = app.InvisiblePlotTabs;
                    app.MainPlaneTab.Parent         = app.InvisiblePlotTabs;
                    app.MainPlaneLogTab.Parent      = app.InvisiblePlotTabs;
                    app.MainAxisAveragedTab.Parent  = app.InvisiblePlotTabs;
                    app.MainPlaneAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.NavigatorTab.Parent         = app.InvisiblePlotTabs;
                    app.NavigatorAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.DeepViewPlotTab.Parent      = app.InvisiblePlotTabs;
                    
                    app.ConsistencyPlotTab.Parent   = app.InvisiblePlotTabs;
                    app.CurrentFrameTab.Parent      = app.InvisiblePlotTabs;
                    app.FramesNavigatorTab.Parent   = app.InvisiblePlotTabs;
                    
                    app.BPTab.Parent            = app.InvisiblePlotTabs;
                    app.SinogramTab.Parent      = app.InvisiblePlotTabs;
                    app.OANavBPTab.Parent       = app.InvisiblePlotTabs;
                    app.OANavSinogramTab.Parent = app.InvisiblePlotTabs;
                    
                    app.PEUSRawTab.Parent             = app.ScanPlotTab;
                    app.PEUSCorrectedTab.Parent       = app.ScanPlotTab;
                    
                    app.CorrectedOATab.Parent         = app.InvisiblePlotTabs;
                    
                    app.MeasNameEditField.Value = 'PEUS';
                case 'Int System'
                    app.MainAxisTab.Parent          = app.InvisiblePlotTabs;
                    app.MainAxisLogTab.Parent       = app.InvisiblePlotTabs;
                    app.MainPlaneTab.Parent         = app.ScanPlotTab;
                    app.MainPlaneLogTab.Parent      = app.ScanPlotTab;
                    app.MainAxisAveragedTab.Parent  = app.InvisiblePlotTabs;
                    app.MainPlaneAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.NavigatorTab.Parent         = app.InvisiblePlotTabs;
                    app.NavigatorAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.DeepViewPlotTab.Parent      = app.InvisiblePlotTabs;
                    
                    app.ConsistencyPlotTab.Parent   = app.InvisiblePlotTabs;
                    app.CurrentFrameTab.Parent      = app.InvisiblePlotTabs;
                    app.FramesNavigatorTab.Parent   = app.InvisiblePlotTabs;
                    
                    app.BPTab.Parent            = app.ScanPlotTab;
                    app.SinogramTab.Parent      = app.ScanPlotTab;
                    app.OANavBPTab.Parent       = app.ScanPlotTab;
                    app.OANavSinogramTab.Parent = app.ScanPlotTab;
                                 
                    app.PEUSRawTab.Parent             = app.ScanPlotTab;
                    app.PEUSCorrectedTab.Parent       = app.ScanPlotTab;
                    
                    app.CorrectedOATab.Parent         = app.ScanPlotTab;

                    app.MeasNameEditField.Value = 'IntSys';
            end
        end

        % Button pushed function: RunButtonConsistency
        function RunButtonConsistencyPushed(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
            app.updatePlotZSpinner();
            app.uVarsConsisUpdate();
            
            
            if app.initConnsistency
                instrreset
                app.consistency.init();
                app.initConnsistency = false;
            elseif app.consStagesRecon
                instrreset % might be a problem with AFG
                app.consistency.restartStages();
            end
            app.consistency.startScan(app.uVarsConsistency);
        end

        % Value changed function: FrameToPlotSpinner
        function FrameToPlotSpinnerValueChanged(app, event)
            app.updateConsFrameLabel();
            app.updateNavFramePlots();
        end

        % Callback function
        function ZcoordinateSpinnerValueChanged(app, event)
           disp("Error - ZCoordinateSpinnerValueChanged function calles")
        end

        % Value changed function: SettoplotSpinner
        function SettoplotSpinnerValueChanged(app, event)
            app.updateNavSetPlots();
        end

        % Callback function
        function ZCoordinateSpinnerValueChanged(app, event)
           disp("Error - ZCoordinateSpinnerValueChanged function calles")
        end

        % Selection change function: GlobalParameters
        function GlobalParametersSelectionChanged(app, event)
            
        end

        % Callback function
        function InButtonPushed(app, event)
            zoom(ax, 'on')
        end

        % Callback function
        function ZoomOutButtonPushed(app, event)
            zoom(ax, 'out')
        end

        % Callback function
        function setUpperLimitsButtonPushed(app, event)
        if strcmp(app.acoustoOpticsPlotsTab.SelectedTab, 'Time Table')
                return
        end
        ax = app.acoustoOpticsPlotsTab.SelectedTab.Children;
        if strcmp(ax.Type, 'axes')
            xlim(ax, [app.StartLimitUpper.Value, app.EndLimitUpper.Value]);
        end
        end

        % Callback function
        function setLowerLimitsButtonPushed(app, event)
            ax = app.SingleMeasurementPlots_2.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, [app.StartLimitLower.Value, app.EndLimitLower.Value]);
            end
        end

        % Callback function
        function resetUpperAxLimitsButtonPushed(app, event)
            if strcmp(app.acoustoOpticsPlotsTab.SelectedTab, 'Time Table')
                return
            end
            ax = app.acoustoOpticsPlotsTab.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, 'auto');
                ylim(ax, 'auto');
            end
        end

        % Callback function
        function resetLowerAxLimitsButtonPushed(app, event)
            if app.ScanPlotTab.Visible
                panel = app.ScanPlotTab;
            elseif app.ConsistencyPlotTab.Visible
                panel = app.ConsistencyPlotTab;
            else
                disp("No visible plot")
                return
            end
            ax = panel.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, 'auto');
                ylim(ax, 'auto');
            end
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
% %             app.updatePlotZSpinner();
        end

        % Value changed function: SineFreqEditField
        function SineFreqEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: CyclesPerPulseEditField
        function CyclesPerPulseEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: SquenceFreqEditField
        function SquenceFreqEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
%             app.updatePlotZSpinner(); 
        end

        % Value changed function: SpeedinMediumEditField
        function SpeedinMediumEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: ZCoorConsSpinner
        function ZCoorConsSpinnerValueChanged(app, event)
            app.updateConsZLabel();
            app.updateConsChAndPos();
        end

        % Value changing function: ZCoorConsSpinner
        function ZCoorConsSpinnerValueChanging(app, event)
            app.updateConsZLabel();
            app.updateConsChAndPos();
        end

        % Value changed function: FrameAOSpinner
        function FrameAOSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Value changed function: SignalChannelSpinner
        function ChannelAOSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Value changed function: FreqEnviromentEditField
        function FreqEnviromentAOEditFieldValueChanged(app, event)
            app.updateAOFigs();
        end

        % Callback function
        function ImportResultsButtonPushed(app, event)
            [path] = uigetdir('D:\Results\');
            h = waitbar(0,'Loading Results. Please wait...');
            data = load([path, '\Results']);
            waitbar(.5,h,'Loading Variables. Please wait');
            vars = load([path, '\Vars']);
            waitbar(1,h,'Done');
            close(h)
            
            app.updatePhiRep(data.res.phiRep, data.res.phiRepStd, ones(1,3));
            app.updatePhi(data.res.phi, data.res.phiStd);
            
            app.updateS3DGeneralData(...
                    vars.curVars.acoustoOptics.algoVars.len.depthVec,...
                    vars.curVars.stages.xVec,...
                    vars.curVars.stages.yVec,...
                    vars.curVars.scan.repeats,...
                    vars.curVars.scan.framesPerSignal);
            
            app.updatePhiRep(data.res.phiRep, data.res.phiRepStd, ones(1,3));
            app.updatePhi(data.res.phi, data.res.phiStd);
            notify(app, 'updatePhiRepEvent');
            notify(app, 'updatePhiEvent');
            
        end

        % Button pushed function: CloseIOPortButton
        function CloseIOPortButtonPushed(app, event)
            app.ao.IO.close();
            fprintf("Closing IO\n")
        end

        % Button pushed function: TurnOffAFGButton
        function TurnOffAFGButtonPushed(app, event)
            app.ao.fGen.disableAllOutputs();
        end

        % Value changed function: LimitLiveAOCheckBox
        function LimitLiveAOCheckBoxValueChanged(app, event)
            value = app.LimitLiveAOCheckBox.Value;
            if value
                app.NEditField.Enable = true;
            else
                app.NEditField.Enable = false;
            end
        end

        % Button pushed function: ImportVarsButton
        function ImportVarsToGUIButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements','*.mat');
            vars = load([path,filename]);
            if isfield(vars, 'uVars')
                app.setAOVarsToGUI(vars.uVars); 
            else
                app.setAOVarsToGUI(vars);
            end
        end

        % Button pushed function: ExportVarsButton
        function ExportVarsFromGUIButtonPushed(app, event)
            app.collectAOVars();
            % Saved as field called uVars in order to comply with AO vars saving procedure.
            vars.uVars = app.aoData.uVars;
            vars.uVars.figs = [];
            [filename, path] = uiputfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            save([path, filename], '-struct', 'vars', '-v7.3');
        end

        % Value changed function: AOMeasHereCheckBox
        function AOMeasHereCheckBoxValueChanged(app, event)
            value = app.AOMeasHereCheckBox.Value;
            if value
                app.AOXpositionEditField.Enable = false;
                app.AOYpositionEditField.Enable = false;
            else
                app.AOXpositionEditField.Enable = true;
                app.AOYpositionEditField.Enable = true;
            end
        end

        % Callback function
        function SamplingMethodDropDownValueChanged(app, event)
            value = app.SamplingMethodDropDown.Value;
            if strcmp(value ,'TS')
                set(app.TimetosampleEditField,'Enable','on');
                set(app.secLabel,'Enable','on');
                set(app.PeriodtoSampleEditField,'Enable','off');
                set(app.Label_2,'Enable','off');
            else
                set(app.TimetosampleEditField,'Enable','off');
                set(app.secLabel,'Enable','off');
                set(app.PeriodtoSampleEditField,'Enable','on');
                set(app.Label_2,'Enable','on');           
            end
        end

        % Button pushed function: S3DExportScanVarsButton
        function S3DExportScanVarsButtonPushed(app, event)
            app.collectS3DVars();
            % Saved as field called uVars in order to comply with AO vars saving procedure.
            vars.uVars = app.s2dData.uVars;
            vars.uVars.figs = [];
            vars.uVars.ao.figs = [];
            [filename, path] = uiputfile('*.mat', 'Save Vars As', 'C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements');
            save([path, filename], '-struct', 'vars', '-v7.3');
        end

        % Button pushed function: S3DImportScanVarsButton
        function S3DImportScanVarsButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements','*.mat');
            vars = load([path,filename]);
            if isfield(vars, 'uVars')
                app.setS2DVarsToGUI(vars.uVars);
                app.setAOVarsToGUI(vars.uVars.ao);
            else
                fprintf("GUI: Failed to load variables. No uVars field found.\n");
            end
        end

        % Callback function
        function S1DScanAxisDropDownValueChanged(app, event)
            switch app.S1DScanAxisDropDown.Value
                case 'X'
                    app.S1DDepthAxisDropDown.Items = {'Y', 'Z'};
                    app.S1DDepthAxisDropDown.Value = 'Z';
                case 'Y'
                    app.S1DDepthAxisDropDown.Items = {'X', 'Z'};
                    app.S1DDepthAxisDropDown.Value = 'Z';
                case 'Z'
                    app.S1DDepthAxisDropDown.Items = {'X', 'Y'};
                    app.S1DDepthAxisDropDown.Value = 'Y';   
            end  
        end

        % Value changed function: S3DFirstAxisDropDown
        function S3DFirstAxisDropDownValueChanged(app, event)
            value = app.S3DFirstAxisDropDown.Value;
            switch app.DepthAxisDropDown.Value
                case 'X' 
                     switch value
                         case 'Y'
                             app.S3DSecondAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S3DSecondAxisDropDown.Value = 'Y';
                     end
                 case 'Y' 
                     switch value
                         case 'X'
                             app.S3DSecondAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S3DSecondAxisDropDown.Value = 'X';
                     end
                 case 'Z' 
                     switch value
                         case 'X'
                             app.S3DSecondAxisDropDown.Value = 'Y';
                         case 'Y' 
                             app.S3DSecondAxisDropDown.Value = 'X';
                     end
            end            
        end

        % Value changed function: S3DSecondAxisDropDown
        function S3DSecondAxisDropDownValueChanged(app, event)
            value = app.S3DSecondAxisDropDown.Value;
            switch app.DepthAxisDropDown.Value
                case 'X' 
                     switch value
                         case 'Y'
                             app.S3DFirstAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S3DFirstAxisDropDown.Value = 'Y';
                     end
                 case 'Y' 
                     switch value
                         case 'X'
                             app.S3DFirstAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S3DFirstAxisDropDown.Value = 'X';
                     end
                 case 'Z' 
                     switch value
                         case 'X'
                             app.S3DFirstAxisDropDown.Value = 'Y';
                         case 'Y' 
                             app.S3DFirstAxisDropDown.Value = 'X';
                     end
            end
        end

        % Callback function
        function Perform2DscanCheckBoxValueChanged(app, event)
            value = app.Perform3DscanCheckBox.Value;
            if value
                app.S3DSecondStrideEditField.Enable = false;
                app.S3DSecondEndEditField.Enable = false;        
            else 
                app.S3DSecondStrideEditField.Enable = true;
                app.S3DSecondEndEditField.Enable = true; 
            end
        end

        % Value changed function: 
        % NormalizeColorstoDisplayedPlaneCheckBox
        function NormalizeColorstoDisplayedPlaneCheckBoxValueChanged(app, event)
            value = app.NormalizeColorstoDisplayedPlaneCheckBox.Value;
            app.s3DGraphics.setColorsScale(value);
            app.updateS3DNavigator();
        end

        % Value changed function: DepthAxisDropDown
        function DepthAxisDropDownValueChanged(app, event)
            value = app.DepthAxisDropDown.Value;
            switch value
                case 'X'
%                     app.stage1Label.Text = 'Y';
%                     app.stage2Label.Text = 'Z';
                    app.stagesData.stage1Ax = 'Y';
                    app.stagesData.stage2Ax = 'Z';
                    
                    app.S2DFirstAxisDropDown.Items  = {'Y', 'Z', 'X'};
                    app.S2DSecondAxisDropDown.Items = {'Y', 'Z', 'X'};
                    app.S3DFirstAxisDropDown.Items  = {'Y', 'Z'};
                    app.S3DSecondAxisDropDown.Items = {'Y', 'Z'};
                    app.DVFirstAxisDropDown.Items   = {'Y', 'Z'};
                    app.DVSecondAxisDropDown.Items  = {'Y', 'Z'};
                    
                    app.S2DFirstAxisDropDown.Value  = 'Y';
                    app.S2DSecondAxisDropDown.Value = 'Z';
                    app.S3DFirstAxisDropDown.Value  = 'Y';
                    app.S3DSecondAxisDropDown.Value = 'Z';
                    app.DVFirstAxisDropDown.Value   = 'Y';
                    app.DVSecondAxisDropDown.Value  = 'Z';
                    
                case 'Y'
%                     app.stage1Label.Text = 'X';
%                     app.stage2Label.Text = 'Z';
                    app.stagesData.stage1Ax = 'X';
                    app.stagesData.stage2Ax = 'Z';
                    
                    app.S2DFirstAxisDropDown.Items  = {'X', 'Z', 'Y'};
                    app.S2DSecondAxisDropDown.Items = {'X', 'Z', 'Y'};
                    app.S3DFirstAxisDropDown.Items  = {'X', 'Z'};
                    app.S3DSecondAxisDropDown.Items = {'X', 'Z'};
                    app.DVFirstAxisDropDown.Items   = {'X', 'Z'};
                    app.DVSecondAxisDropDown.Items  = {'X', 'Z'};
                    
                    app.S2DFirstAxisDropDown.Value  = 'X';
                    app.S2DSecondAxisDropDown.Value = 'Z';
                    app.S3DFirstAxisDropDown.Value  = 'X';
                    app.S3DSecondAxisDropDown.Value = 'Z';
                    app.DVFirstAxisDropDown.Value   = 'X';
                    app.DVSecondAxisDropDown.Value  = 'Z';
                    
                case 'Z'
%                     app.stage1Label.Text = 'X';
%                     app.stage2Label.Text = 'Y';
                    app.stagesData.stage1Ax = 'X';
                    app.stagesData.stage2Ax = 'Y';
                    
                    app.S2DFirstAxisDropDown.Items  = {'X', 'Y', 'Z'};
                    app.S2DSecondAxisDropDown.Items = {'X', 'Y', 'Z'};
                    app.S3DFirstAxisDropDown.Items  = {'X', 'Y'};
                    app.S3DSecondAxisDropDown.Items = {'X', 'Y'};
                    app.DVFirstAxisDropDown.Items   = {'X', 'Y'};
                    app.DVSecondAxisDropDown.Items  = {'X', 'Y'};
                    
                    app.S2DFirstAxisDropDown.Value  = 'X';
                    app.S2DSecondAxisDropDown.Value = 'Y';
                    app.S3DFirstAxisDropDown.Value  = 'X';
                    app.S3DSecondAxisDropDown.Value = 'Y';
                    app.DVFirstAxisDropDown.Value   = 'X';
                    app.DVSecondAxisDropDown.Value  = 'Y';
            end
        end

        % Button pushed function: SetStagesGeometryButton
        function SetStagesGeometryButtonPushed(app, event)
            app.stage1AxDropDown.Value
            ax = [];
            for i = 1:app.stagesData.numOfStages
                dropName = sprintf("stage%dAxDropDown", i);
                ax = [ax, app.(dropName).Value];
            end

            if length(unique(ax)) < app.stagesData.numOfStages
                msgbox('Check Assignment. 2 Axes allocated to the same stage.');
                return;
            end
            
            app.aoStages.assignStagesAxes(ax);
            
            axOrder = app.aoStages.getAssignment();
            for i = 1:length(axOrder)
                app.stagesData.axOrder{i} = axOrder(i);
            end

            for i=1:app.stagesData.numOfStages
                panelName = sprintf("Stage%dPanel", i);
                app.(panelName).Title = sprintf("Stage %d (%s)", i, app.stagesData.axOrder{i});
            end
            
            app.stagesData.assigned = true;
            app.SetStagesGeometryButton.Enable = false;
            
        end

        % Value changed function: stage1AxDropDown
        function stage1AxDropDownValueChanged(app, event)
            app.stagesData.assigned = false;
            app.SetStagesGeometryButton.Enable = true;
        end

        % Value changed function: stage2AxDropDown
        function stage2AxDropDownValueChanged(app, event)
            app.stagesData.assigned = false;
            app.SetStagesGeometryButton.Enable = true;
        end

        % Value changed function: stage3AxDropDown
        function stage3AxDropDownValueChanged(app, event)
            app.stagesData.assigned = false;
            app.SetStagesGeometryButton.Enable = true;
        end

        % Button pushed function: TestAssignmentButton
        function TestAssignmentButtonPushed(app, event)
            for i = 1:app.stagesData.numOfStages
                dropName = sprintf("stage%dAxDropDown", i);
                ax = app.(dropName).Value;
                if strcmp(ax, 'Not Assigned')
                    app.TestAssignmentButton.Text = sprintf("Stage %s is N/A.", ax);
                    pause(1);
                else
                    app.TestAssignmentButton.Text = sprintf("Stage %s is moving.", ax);
                    app.aoStages.moveStagesForIdentification(i);
                end
            end
            app.TestAssignmentButton.Text = "Test Assignment";
        end

        % Button pushed function: S2DRunButton
        function S2DRunButtonPushed(app, event)
            axScanLabel = app.S2DFirstAxisDropDown.Value;
            axSecondLabel = app.S2DSecondAxisDropDown.Value;
            axThirdLabel = app.S2DThirdAxisDropDown.Value;
            
            if strcmp(axScanLabel, axSecondLabel) || ...
               strcmp(axScanLabel, axThirdLabel) || ...
               strcmp(axSecondLabel, axThirdLabel)
                
               uiwait(msgbox('Wrong Axes Names','S2D Error','modal'));
                
               return
               
            end

            app.collectS2DVars();
            
            app.s2D.setUserVars(app.s2DData.uVars);
            app.s2DData.curVars = app.s2D.getVars();
            app.aoData.curVars  = app.ao.getVars();
            
            app.updateS2DGUIComponents();
            
            app.updateAOPlotChSpinnerLimits();
            app.updateAOPlotZIdxSpinnerLimits();
            app.updateFrameSpinnerLimits();
            
            app.s2D.configureScan();
            app.s2DData.curRes  = app.s2D.scan2D();
            app.aoData.curRes   = app.ao.getResult;
        end

        % Callback function
        function DepthAxisasInexCheckBoxValueChanged(app, event)
            app.updateAOFigs();
        end

        % Button pushed function: PopButton
        function PopButtonPushed(app, event)
            switch app.FiguresToPopDropDown.Value
                case 'Acousto Optics'
                    if app.aoData.uVars.figs.validStruct.extClk
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axExtClk,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.usSignal 
                        f = figure();
                        ax=axes(f);
                        copyobj(app.axUsSignal,ax)
                    end

                    if app.aoData.uVars.figs.validStruct.meas
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axMeasSig,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.signal
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axNetSignal,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.deMul 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axDeMul,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.reshaped 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axReshape,ax)
                    end
                    
                    if app.aoData.uVars.figs.validStruct.qAvgChFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axFittedFFT,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.unFittedFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axAvgFFT,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.fittedFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axNormFFT,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.qAvgChFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axFittedFFT,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.avgFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axAvgFFT,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.normFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axNormFFT,ax)
                    end
                    
                    if app.aoData.uVars.figs.validStruct.phi
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axPhi,ax)
                    end
                    
                    if app.aoData.uVars.figs.validStruct.rawPhi
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axRawPhi,ax)
                    end
                case 'Scan 2D'
                    f = figure();
                    ax=axes(f);
                    copyUIAxes(app.axMainAxis, ax);
                    
                    f = figure();
                    ax=axes(f);
                    copyUIAxes(app.axMainPlane, ax);
                    
                    
                case 'Scan 3D'
                
                case 'Deep View'
                
                case 'OptoAcoustics'
                    
                case 'Pulse-Echo US'
                    
                case 'IntSys'
            end
        end

        % Value changed function: S2DFirstAxisDropDown
        function S2DFirstAxisDropDownValueChanged(app, event)
%             value = app.S2DFirstAxisDropDown.Value;
%             switch app.DepthAxisDropDown.Value
%                 case 'X' 
%                      switch value
%                          case 'Y'
%                              app.S2DSecondAxisDropDown.Value = 'Z';
%                          case 'Z' 
%                              app.S2DSecondAxisDropDown.Value = 'Y';
%                      end
%                  case 'Y' 
%                      switch value
%                          case 'X'
%                              app.S2DSecondAxisDropDown.Value = 'Z';
%                          case 'Z' 
%                              app.S2DSecondAxisDropDown.Value = 'X';
%                      end
%                  case 'Z' 
%                      switch value
%                          case 'X'
%                              app.S2DSecondAxisDropDown.Value = 'Y';
%                          case 'Y' 
%                              app.S2DSecondAxisDropDown.Value = 'X';
%                      end
%             end
        end

        % Value changed function: S2DSecondAxisDropDown
        function S2DSecondAxisDropDownValueChanged(app, event)
%             value = app.S2DSecondAxisDropDown.Value;
%             switch app.DepthAxisDropDown.Value
%                 case 'X' 
%                      switch value
%                          case 'Y'
%                              app.S2DFirstAxisDropDown.Value = 'Z';
%                          case 'Z' 
%                              app.S2DFirstAxisDropDown.Value = 'Y';
%                      end
%                  case 'Y' 
%                      switch value
%                          case 'X'
%                              app.S2DFirstAxisDropDown.Value = 'Z';
%                          case 'Z' 
%                              app.S2DFirstAxisDropDown.Value = 'X';
%                      end
%                  case 'Z' 
%                      switch value
%                          case 'X'
%                              app.S2DFirstAxisDropDown.Value = 'Y';
%                          case 'Y' 
%                              app.S2DFirstAxisDropDown.Value = 'X';
%                      end
%             end
        end

        % Button pushed function: Stage1MoveToPositionButton
        function Stage1MoveToPositionButtonPushed(app, event)
            pos = app.Stage1PositionEditField.Value;
            app.aoStages.moveAbsId(1, pos);
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:app.stagesData.numOfStages) = pos;
        end

        % Button pushed function: Stage2MoveToPositionButton
        function Stage2MoveToPositionButtonPushed(app, event)
            pos = app.Stage2PositionEditField.Value;
            app.aoStages.moveAbsId(2, pos);
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:app.stagesData.numOfStages) = pos;
        end

        % Button pushed function: Stage3MoveToPositionButton
        function Stage3MoveToPositionButtonPushed(app, event)
            pos = app.Stage3PositionEditField.Value;
            app.aoStages.moveAbsId(3, pos);
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:app.stagesData.numOfStages) = pos;
        end

        % Button pushed function: Stage1Increase
        function Stage1IncreaseButtonPushed(app, event)
            step = app.Stage1StrideEditField.Value;
            app.aoStages.moveRelId(1, step)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:app.stagesData.numOfStages) = pos;
        end

        % Button pushed function: Stage2Increase
        function Stage2IncreaseButtonPushed(app, event)
            step = app.Stage2StrideEditField.Value;
            app.aoStages.moveRelId(2, step)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:app.stagesData.numOfStages) = pos;
        end

        % Button pushed function: Stage3Increase
        function Stage3IncreaseButtonPushed(app, event)
            step = app.Stage3StrideEditField.Value;
            app.aoStages.moveRelId(3, step)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:app.stagesData.numOfStages) = pos; 
        end

        % Button pushed function: Stage1Decrease
        function Stage1DecreaseButtonPushed(app, event)
            step = app.Stage1StrideEditField.Value;
            app.aoStages.moveRelId(1, -step)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:app.stagesData.numOfStages) = pos;
        end

        % Button pushed function: Stage2Decrease
        function Stage2DecreaseButtonPushed(app, event)
            step = app.Stage2StrideEditField.Value;
            app.aoStages.moveRelId(2, -step)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:app.stagesData.numOfStages) = pos;
        end

        % Button pushed function: Stage3Decrease
        function Stage3DecreaseButtonPushed(app, event)
            step = app.Stage3StrideEditField.Value;
            app.aoStages.moveRelId(3, -step)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:app.stagesData.numOfStages) = pos;
        end

        % Button pushed function: Stage1SetStartButton
        function Stage1SetStartButtonPushed(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(2,1) = pos(1);
        end

        % Button pushed function: Stage2SetStartButton
        function Stage2SetStartButtonPushed2(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(2,2) = pos(2);
        end

        % Button pushed function: Stage3SetStartButton
        function Stage3SetStartButtonPushed(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(2,3) = pos(3);
        end

        % Button pushed function: Stage1SetStepButton
        function Stage1SetStepButtonPushed(app, event)
            app.stagesTable.Data(3,1) = app.Stage1StrideEditField.Value;
        end

        % Button pushed function: Stage2SetStepButton
        function Stage2SetStepButtonPushed(app, event)
            app.stagesTable.Data(3,2) = app.Stage2StrideEditField.Value;
        end

        % Button pushed function: Stage3SetStepButton
        function Stage3SetStepButtonPushed(app, event)
            app.stagesTable.Data(3,3) = app.Stage3StrideEditField.Value;
        end

        % Button pushed function: Stage1SetEndButton
        function Stage1SetEndButtonPushed(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(4,1) = pos(1);
        end

        % Button pushed function: Stage2SetEndButton
        function Stage2SetEndButtonPushed(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(4,2) = pos(2);
        end

        % Button pushed function: Stage3SetEndButton
        function Stage3SetEndButtonPushed(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(4,3) = pos(3);
        end

        % Button pushed function: AllStagesHomeButton
        function AllStagesHomeButtonPushed(app, event)
            app.aoStages.allHome();
        end

        % Button pushed function: ExportToFileButton
        function ExportToFileButtonPushed(app, event)
            [file,path] = uiputfile('*.mat', 'Save Stages Configurations');

            confs.stage1Ax = app.stagesData.stage1Ax;
            confs.stage2Ax = app.stagesData.stage2Ax;
            
            confs.startPos1 = app.stagesTable.Data(2,1);
            confs.startPos2 = app.stagesTable.Data(2,2);
            confs.endPos1   = app.stagesTable.Data(3,1);
            confs.endPos2   = app.stagesTable.Data(3,2);
            confs.stride1   = app.stagesTable.Data(4,1);
            confs.stride2   = app.stagesTable.Data(4,2);

            save([path, file], '-struct','confs');
        end

        % Button pushed function: CopytoScanButton
        function CopytoScanButtonPushed(app, event)
            value = app.ExporttoscanDropDown.Value;
            switch(value)
                case 'Scan 2D'
                    if strcmp(app.S2DFirstAxisDropDown.Value, app.stagesData.stage1Ax)
                        app.S2DFirstStartEditField.Value  = app.stagesTable.Data(2,1);
                        app.S2DFirstStrideEditField.Value = app.stagesTable.Data(3,1);
                        app.S2DFirstEndEditField.Value    = app.stagesTable.Data(4,1);
                        app.S2DSecondPosEditField.Value   = app.stagesTable.Data(2,2);
                    else
                        app.S2DFirstStartEditField.Value  = app.stagesTable.Data(2,2);
                        app.S2DFirstStrideEditField.Value = app.stagesTable.Data(3,2);
                        app.S2DFirstEndEditField.Value    = app.stagesTable.Data(4,2);
                        app.S2DSecondPosEditField.Value   = app.stagesTable.Data(2,1);
                    end
                case 'Scan 3D'
                    if strcmp(app.S3DFirstAxisDropDown.Value, app.stagesData.stage1Ax)
                        app.S3DFirstStartEditField.Value  = app.stagesTable.Data(2,1);
                        app.S3DFirstStrideEditField.Value = app.stagesTable.Data(3,1);
                        app.S3DFirstEndEditField.Value    = app.stagesTable.Data(4,1);
                                                
                        app.S3DSecondStartEditField.Value  = app.stagesTable.Data(2,2);
                        app.S3DSecondStrideEditField.Value = app.stagesTable.Data(3,2);
                        app.S3DSecondEndEditField.Value    = app.stagesTable.Data(4,2);
                    else
                        app.S3DFirstStartEditField.Value  = app.stagesTable.Data(2,2);
                        app.S3DFirstStrideEditField.Value = app.stagesTable.Data(3,2);
                        app.S3DFirstEndEditField.Value    = app.stagesTable.Data(4,2);
                                                
                        app.S3DSecondStartEditField.Value  = app.stagesTable.Data(2,1);
                        app.S3DSecondStrideEditField.Value = app.stagesTable.Data(3,1);
                        app.S3DSecondEndEditField.Value    = app.stagesTable.Data(4,1);
                    end
                    
                case 'Deep View'
                    if strcmp(app.DVFirstAxisDropDown.Value, app.stagesData.stage1Ax)
                        app.DVFirstStartEditField.Value  = app.stagesTable.Data(2,1);
                        app.DVFirstStrideEditField.Value = app.stagesTable.Data(3,1);
                        app.DVFirstEndEditField.Value    = app.stagesTable.Data(4,1);
                        app.DVSecondPosEditField.Value   = app.stagesTable.Data(2,2);
                    else
                        app.DVFirstStartEditField.Value  = app.stagesTable.Data(2,2);
                        app.DVFirstStrideEditField.Value = app.stagesTable.Data(3,2);
                        app.DVFirstEndEditField.Value    = app.stagesTable.Data(4,2);
                        app.DVSecondPosEditField.Value   = app.stagesTable.Data(2,1);
                    end 
            end
        end

        % Button pushed function: RefreshPositionButton
        function RefreshPositionButtonPushed(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:app.stagesData.numOfStages) = pos;
        end

        % Value changed function: ExporttoscanDropDown
        function ExporttoscanDropDownValueChanged(app, event)
            value = app.ExporttoscanDropDown.Value;
            
        end

        % Value changed function: DVFirstAxisDropDown
        function DVFirstAxisDropDownValueChanged(app, event)
            value = app.DVFirstAxisDropDown.Value;
            switch app.DepthAxisDropDown.Value
                case 'X' 
                     switch value
                         case 'Y'
                             app.DVSecondAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.DVSecondAxisDropDown.Value = 'Y';
                     end
                 case 'Y' 
                     switch value
                         case 'X'
                             app.DVSecondAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.DVSecondAxisDropDown.Value = 'X';
                     end
                 case 'Z' 
                     switch value
                         case 'X'
                             app.DVSecondAxisDropDown.Value = 'Y';
                         case 'Y' 
                             app.DVSecondAxisDropDown.Value = 'X';
                     end
            end
            
        end

        % Value changed function: DVSecondAxisDropDown
        function DVSecondAxisDropDownValueChanged(app, event)
            value = app.DVSecondAxisDropDown.Value;
            switch app.DepthAxisDropDown.Value
                case 'X' 
                     switch value
                         case 'Y'
                             app.DVFirstAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.DVFirstAxisDropDown.Value = 'Y';
                     end
                 case 'Y' 
                     switch value
                         case 'X'
                             app.DVFirstAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.DVFirstAxisDropDown.Value = 'X';
                     end
                 case 'Z' 
                     switch value
                         case 'X'
                             app.DVFirstAxisDropDown.Value = 'Y';
                         case 'Y' 
                             app.DVFirstAxisDropDown.Value = 'X';
                     end
            end
        end

        % Value changed function: dispFullFFTCheckBox
        function dispFullFFTCheckBoxValueChanged(app, event)
            value = app.dispFullFFTCheckBox.Value;
            if value
                app.FreqEnviromentEditField.Enable = false;
            else
                app.FreqEnviromentEditField.Enable = true;
            end
        end

        % Button pushed function: DVRunButton
        function DVRunButtonPushed(app, event)
            app.collectS2DVars();
            
            app.s2D.setUserVars(app.s2DData.uVars);
            app.s2DData.curVars = app.s2D.getVars();
            app.aoData.curVars  = app.ao.getVars();
            
            app.updateS2DGUIComponents();
            
            app.updateAOPlotChSpinnerLimits();
            app.updateAOPlotZIdxSpinnerLimits();
            app.updateFrameSpinnerLimits();
            
            app.s2D.configureScan();
            app.s2DData.curRes  = app.s2D.scan2D();
            app.aoData.curRes   = app.ao.getResult;
        end

        % Button pushed function: OARunScanButton
        function OARunScanButtonPushed(app, event)
            app.collectOAVars();
            app.oa.setVars(app.oaData.uVars);
            app.oa.configPeripherals();
            app.oaData.curVars = app.oa.getVars();
            app.OANavIdxSpinner.Enable = true;
            app.updateOANAvigatorSpinner();
            app.oaData.curRes    = app.oa.scan();
        end

        % Button pushed function: OARunSingleButton
        function OARunSingleButtonPushed(app, event)
            app.collectOAVars();
            app.oa.setVars(app.oaData.uVars);
            app.oa.configPeripherals();
            app.oaData.curVars = app.oa.getVars();
            app.OANavIdxSpinner.Enable = false;
            app.oaData.curRes    = app.oa.runOA();
        end

        % Button pushed function: OARunLiveButton
        function OARunLiveButtonPushed(app, event)
            app.collectOAVars();
            app.oa.setVars(app.oaData.uVars);
            app.oa.configPeripherals();
            app.oaData.curVars = app.oa.getVars();
            app.OANavIdxSpinner.Enable = false;
            app.oaData.curRes    = app.oa.runLiveOA();
        end

        % Value changed function: OANavIdxSpinner
        function OANavIdxSpinnerValueChanged(app, event)
            idx =  app.OANavIdxSpinner.Value;
            app.updateOANavigatorLabel();
            app.oaGraphics.updateNavigator(idx);
        end

        % Button pushed function: PEUSRunScanButton
        function PEUSRunScanButtonPushed(app, event)
            app.collectPEUSVars();
            app.peus.setVars(app.peusData.uVars);
            app.peus.configureScan();
            app.peusData.curVars = app.peus.getVars();
            app.peusData.curRes  = app.peus.scan();
        end

        % Value changed function: MeasurementtimeimitEditField
        function MeasurementtimeimitEditFieldValueChanged2(app, event)
            value = app.MeasurementtimeimitEditField.Value;
            app.ao.setMeasLimit(value);
        end

        % Button pushed function: RunIntImagingButton
        function RunIntImagingButtonPushed(app, event)
            app.collectIntSysVars();
            app.intSys.setUserVars(uVars);
            app.intSys.configureScan();
            app.intSysData.curVars = app.intSys.getVars();
            app.intSysData.curRes = app.intSys.scan();
        end

        % Button pushed function: ResetDaqButton
        function ResetDAQResetDaqButtonPushed(app, event)
            app.oa.daq.resetDAQ();
        end

        % Button pushed function: ReferenceAllButton
        function ReferenceAllButtonPushed(app, event)
            app.aoStages.referenceAll();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            
        end

        % Value changed function: SeparateChannelSpinner
        function SeparateChannelSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Value changed function: NumberOfChannelsEditField
        function NumberOfChannelsEditFieldValueChanged(app, event)
%             value = app.NumberOfChannelsEditField.Value;
            
        end

        % Button pushed function: MonitorButton
        function MonitorButtonPushed(app, event)
            
        end

        % Button pushed function: SetLimitsButtonStage1
        function SetLimitsButtonStage1Pushed(app, event)
            app.aoStages.setLimits(app.stagesData.axOrder{1}, app.HighLimitStage1.Value, app.LowLimitStage1.Value);
        end

        % Button pushed function: SetLimitsButtonStage2
        function SetLimitsButtonStage2Pushed(app, event)
            app.aoStages.setLimits(app.stagesData.axOrder{2}, app.HighLimitStage2.Value, app.LowLimitStage2.Value);
        end

        % Button pushed function: SetLimitsButtonStage2_2
        function SetLimitsButtonStage2_2Pushed(app, event)
            app.aoStages.setLimits(app.stagesData.axOrder{3}, app.HighLimitStage3.Value, app.LowLimitStage3.Value);
        end

        % Button pushed function: HomeStage1
        function HomeStage1ButtonPushed(app, event)
            app.aoStages.home(1);
        end

        % Button pushed function: HomeStage2
        function HomeStage2ButtonPushed(app, event)
            app.aoStages.home(2);
        end

        % Button pushed function: HomeStage3
        function HomeStage3ButtonPushed(app, event)
            app.aoStages.home(3);
        end

        % Selection change function: acoustoOpticsPlotsTab
        function acoustoOpticsPlotsTabSelectionChanged(app, event)
            selectedTab = app.acoustoOpticsPlotsTab.SelectedTab;
            if strcmp(selectedTab.Title, 'AO Vars')
                app.collectAlgoVars();
                app.algo.setMeasLimit(app.MeasurementtimeimitEditField.Value);
                app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
                app.setAlgoValuesTable();
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.collectAOVars();
            
            app.ao.calibrate(app.aoData.uVars)

            app.aoData.curVars = app.ao.getVars();
            
            app.updateAOPlotChSpinnerLimits();
            app.updateAOPlotZIdxSpinnerLimits();
            app.updateFrameSpinnerLimits();
        end

        % Button pushed function: OfflineCutButton
        function OfflineCutButtonPushed(app, event)
            
        end

        % Button pushed function: SaveCurrentMeasButton
        function SaveCurrentMeasButtonPushed(app, event)
            fsVars = app.collectAOFileSystemVars();
            app.ao.saveCurrentMeasManual(fsVars);
        end

        % Button pushed function: ReCalcDataButton
        function ReCalcDataButtonPushed(app, event)
            app.collectAOVars();
            app.ao.reCalcData(app.aoData.uVars);
            
            app.aoData.curVars = app.ao.getVars();
            
            app.updateAOPlotChSpinnerLimits();
            app.updateAOPlotZIdxSpinnerLimits();
            app.updateFrameSpinnerLimits();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AOIUIFigure = uifigure('Visible', 'off');
            app.AOIUIFigure.Name = 'AOI';
            app.AOIUIFigure.CloseRequestFcn = createCallbackFcn(app, @AOIUIFigureCloseRequest, true);
            app.AOIUIFigure.SizeChangedFcn = createCallbackFcn(app, @AOIUIFigureSizeChanged, true);
            app.FileMenu = uimenu(app.AOIUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadCondifurationsMenu = uimenu(app.FileMenu);
            app.LoadCondifurationsMenu.Text = 'Load Condifurations';
            app.SaveConfigurationsMenu = uimenu(app.FileMenu);
            app.SaveConfigurationsMenu.Text = 'Save Configurations';
            app.ToolsMenu = uimenu(app.AOIUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.DebugMenu = uimenu(app.ToolsMenu);
            app.DebugMenu.Text = 'Debug';
            app.DeactivatePeripheralsMenu = uimenu(app.DebugMenu);
            app.DeactivatePeripheralsMenu.Text = 'Deactivate Peripherals ';
            app.ExtraFiguresMenu = uimenu(app.DebugMenu);
            app.ExtraFiguresMenu.Text = 'Extra Figures';
            app.PMTSaturationInspector = uimenu(app.ToolsMenu);
            app.PMTSaturationInspector.MenuSelectedFcn = createCallbackFcn(app, @PMTSaturationInspectorMenuSelected, true);
            app.PMTSaturationInspector.Text = 'PMT Saturation Inspector';
            app.ScanAssistantMenu = uimenu(app.ToolsMenu);
            app.ScanAssistantMenu.MenuSelectedFcn = createCallbackFcn(app, @ScanAssistantMenuSelected, true);
            app.ScanAssistantMenu.Text = 'Scan Assistant';
            app.FindMyinstrumentPortMenu = uimenu(app.ToolsMenu);
            app.FindMyinstrumentPortMenu.Text = 'Find My instrument Port';
            app.InstrumentsMenu = uimenu(app.AOIUIFigure);
            app.InstrumentsMenu.Text = 'Instruments';
            app.StagesMenu = uimenu(app.InstrumentsMenu);
            app.StagesMenu.Text = 'Stages';
            app.ScanningAssistantMenu = uimenu(app.StagesMenu);
            app.ScanningAssistantMenu.Text = 'Scanning Assistant';
            app.AFGMenu = uimenu(app.InstrumentsMenu);
            app.AFGMenu.Text = 'AFG';
            app.OscilloscopeMenu = uimenu(app.InstrumentsMenu);
            app.OscilloscopeMenu.Text = 'Oscilloscope';
            app.ResetInstrumentsCommunicationMenu = uimenu(app.InstrumentsMenu);
            app.ResetInstrumentsCommunicationMenu.Text = 'Reset Instruments Communication';
            app.HelpMenu = uimenu(app.AOIUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.AcoustoOpticSystemLabel = uilabel(app.AOIUIFigure);
            app.AcoustoOpticSystemLabel.Text = 'AcoustoOptic System';
            app.ScanPlotTab = uitabgroup(app.AOIUIFigure);
            app.MainAxisTab = uitab(app.ScanPlotTab);
            app.MainAxisTab.Title = 'Main Axis';
            app.axMainAxis = uiaxes(app.MainAxisTab);
            title(app.axMainAxis, 'Title')
            xlabel(app.axMainAxis, 'X')
            ylabel(app.axMainAxis, 'Y')
            app.MainAxisLogTab = uitab(app.ScanPlotTab);
            app.MainAxisLogTab.Title = 'Main Axis (Log)';
            app.axMainAxisLog = uiaxes(app.MainAxisLogTab);
            title(app.axMainAxisLog, 'Title')
            xlabel(app.axMainAxisLog, 'X')
            ylabel(app.axMainAxisLog, 'Y')
            app.MainPlaneTab = uitab(app.ScanPlotTab);
            app.MainPlaneTab.Title = 'Main Plain';
            app.axMainPlane = uiaxes(app.MainPlaneTab);
            title(app.axMainPlane, 'Title')
            xlabel(app.axMainPlane, 'X')
            ylabel(app.axMainPlane, 'Y')
            app.MainPlaneLogTab = uitab(app.ScanPlotTab);
            app.MainPlaneLogTab.Title = 'MainPlain (Log)';
            app.axMainPlaneLog = uiaxes(app.MainPlaneLogTab);
            title(app.axMainPlaneLog, 'Title')
            xlabel(app.axMainPlaneLog, 'X')
            ylabel(app.axMainPlaneLog, 'Y')
            app.NavigatorTab = uitab(app.ScanPlotTab);
            app.NavigatorTab.Title = 'Navigator';
            app.axNav = uiaxes(app.NavigatorTab);
            title(app.axNav, 'Title')
            xlabel(app.axNav, 'X')
            ylabel(app.axNav, 'Y')
            app.MainAxisAveragedTab = uitab(app.ScanPlotTab);
            app.MainAxisAveragedTab.Title = 'Main Axis (Averaged)';
            app.axMainAxisAvg = uiaxes(app.MainAxisAveragedTab);
            title(app.axMainAxisAvg, 'Title')
            xlabel(app.axMainAxisAvg, 'X')
            ylabel(app.axMainAxisAvg, 'Y')
            app.MainPlaneAveragedTab = uitab(app.ScanPlotTab);
            app.MainPlaneAveragedTab.Title = 'Main Plain (Averaged)';
            app.axMainPlaneAvg = uiaxes(app.MainPlaneAveragedTab);
            title(app.axMainPlaneAvg, 'Title')
            xlabel(app.axMainPlaneAvg, 'X')
            ylabel(app.axMainPlaneAvg, 'Y')
            app.NavigatorAveragedTab = uitab(app.ScanPlotTab);
            app.NavigatorAveragedTab.Title = 'Navigator (Averaged)';
            app.axNavAvg = uiaxes(app.NavigatorAveragedTab);
            title(app.axNavAvg, 'Title')
            xlabel(app.axNavAvg, 'X')
            ylabel(app.axNavAvg, 'Y')
            app.DeepViewPlotTab = uitab(app.ScanPlotTab);
            app.DeepViewPlotTab.Title = 'Deep View';
            app.axDeepView = uiaxes(app.DeepViewPlotTab);
            title(app.axDeepView, 'Title')
            xlabel(app.axDeepView, 'X')
            ylabel(app.axDeepView, 'Y')
            app.ConsistencyPlotTab = uitab(app.ScanPlotTab);
            app.ConsistencyPlotTab.Title = 'Consistency';
            app.axConsistency = uiaxes(app.ConsistencyPlotTab);
            title(app.axConsistency, 'Title')
            xlabel(app.axConsistency, 'X')
            ylabel(app.axConsistency, 'Y')
            app.CurrentFrameTab = uitab(app.ScanPlotTab);
            app.CurrentFrameTab.Title = 'Current Frame';
            app.axCurFrame = uiaxes(app.CurrentFrameTab);
            title(app.axCurFrame, 'Title')
            xlabel(app.axCurFrame, 'X')
            ylabel(app.axCurFrame, 'Y')
            app.FramesNavigatorTab = uitab(app.ScanPlotTab);
            app.FramesNavigatorTab.Title = 'Frames Navigator';
            app.axFrameNav = uiaxes(app.FramesNavigatorTab);
            title(app.axFrameNav, 'Title')
            xlabel(app.axFrameNav, 'X')
            ylabel(app.axFrameNav, 'Y')
            app.BPTab = uitab(app.ScanPlotTab);
            app.BPTab.Title = 'BP';
            app.axOABP = uiaxes(app.BPTab);
            title(app.axOABP, 'Title')
            xlabel(app.axOABP, 'X')
            ylabel(app.axOABP, 'Y')
            app.SinogramTab = uitab(app.ScanPlotTab);
            app.SinogramTab.Title = 'Sinogram';
            app.axOASinogram = uiaxes(app.SinogramTab);
            title(app.axOASinogram, 'Title')
            xlabel(app.axOASinogram, 'X')
            ylabel(app.axOASinogram, 'Y')
            app.OANavSinogramTab = uitab(app.ScanPlotTab);
            app.OANavSinogramTab.Title = 'OA Nav. Sinogram';
            app.axOANavSinogram = uiaxes(app.OANavSinogramTab);
            title(app.axOANavSinogram, 'Title')
            xlabel(app.axOANavSinogram, 'X')
            ylabel(app.axOANavSinogram, 'Y')
            app.OANavBPTab = uitab(app.ScanPlotTab);
            app.OANavBPTab.Title = 'OA Nav. BP';
            app.axOANavBP = uiaxes(app.OANavBPTab);
            title(app.axOANavBP, 'Title')
            xlabel(app.axOANavBP, 'X')
            ylabel(app.axOANavBP, 'Y')
            app.PEUSRawTab = uitab(app.ScanPlotTab);
            app.PEUSRawTab.Title = 'PEUS - Raw';
            app.axPEUSRaw = uiaxes(app.PEUSRawTab);
            title(app.axPEUSRaw, 'Title')
            xlabel(app.axPEUSRaw, 'X')
            ylabel(app.axPEUSRaw, 'Y')
            app.PEUSCorrectedTab = uitab(app.ScanPlotTab);
            app.PEUSCorrectedTab.Title = 'PEUS - Corrected';
            app.axPEUSCorrected = uiaxes(app.PEUSCorrectedTab);
            title(app.axPEUSCorrected, 'Title')
            xlabel(app.axPEUSCorrected, 'X')
            ylabel(app.axPEUSCorrected, 'Y')
            app.CorrectedOATab = uitab(app.ScanPlotTab);
            app.CorrectedOATab.Title = 'Corrected OA';
            app.axCorrectedOA = uiaxes(app.CorrectedOATab);
            title(app.axCorrectedOA, 'Title')
            xlabel(app.axCorrectedOA, 'X')
            ylabel(app.axCorrectedOA, 'Y')
            app.SimulationSavePanel = uipanel(app.AOIUIFigure);
            app.SimulationSavePanel.Title = 'Simulation Name';
            app.MeasNameLabel = uilabel(app.SimulationSavePanel);
            app.MeasNameLabel.Text = 'Meas. Name:';
            app.MeasNameEditField = uieditfield(app.SimulationSavePanel, 'text');
            app.MeasNameEditField.Value = 'Scan';
            app.PathLabel = uilabel(app.SimulationSavePanel);
            app.PathLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.SimulationSavePanel, 'text');
            app.PathEditField.Value = 'D:\Results';
            app.saveResultsandVarsCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveResultsandVarsCheckBox.Text = 'Save Results and Vars';
            app.saveFigsCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveFigsCheckBox.Text = 'Save Figs';
            app.saveRawDataCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveRawDataCheckBox.Text = 'Raw Data';
            app.saveNetSignalCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveNetSignalCheckBox.Text = 'Net Signal';
            app.saveDeMultiplexedCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveDeMultiplexedCheckBox.Text = 'DeMultiplexed';
            app.saveReshapedCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveReshapedCheckBox.Text = 'Reshaped';
            app.saveFFTCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveFFTCheckBox.Text = 'FFT';
            app.SavedDataSpecificationsLabel = uilabel(app.SimulationSavePanel);
            app.SavedDataSpecificationsLabel.Text = 'Saved Data Specifications:';
            app.saveUSCmplxCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveUSCmplxCheckBox.Text = 'US Cmplx.';
            app.dirBrowseButton = uibutton(app.SimulationSavePanel, 'push');
            app.dirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @dirBrowseButtonPushed, true);
            app.dirBrowseButton.Text = '...';
            app.SaveCurrentMeasButton = uibutton(app.SimulationSavePanel, 'push');
            app.SaveCurrentMeasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentMeasButtonPushed, true);
            app.SaveCurrentMeasButton.Text = 'Save Current Meas';
            app.MeasMethod = uitabgroup(app.AOIUIFigure);
            app.MeasMethod.SelectionChangedFcn = createCallbackFcn(app, @MeasMethodSelectionChanged, true);
            app.SingleMeasurementTab = uitab(app.MeasMethod);
            app.SingleMeasurementTab.Title = 'Single Measurement';
            app.AORunButton = uibutton(app.SingleMeasurementTab, 'push');
            app.AORunButton.ButtonPushedFcn = createCallbackFcn(app, @AORunButtonPushed, true);
            app.AORunButton.Text = 'Run';
            app.AOLiveRunButton = uibutton(app.SingleMeasurementTab, 'push');
            app.AOLiveRunButton.ButtonPushedFcn = createCallbackFcn(app, @AOLiveRunButtonPushed, true);
            app.AOLiveRunButton.Text = 'Run Live';
            app.StagesPanel_3 = uipanel(app.SingleMeasurementTab);
            app.StagesPanel_3.Title = 'Stages';
            app.mmLabel_17 = uilabel(app.StagesPanel_3);
            app.mmLabel_17.Text = '[mm]';
            app.mmLabel_18 = uilabel(app.StagesPanel_3);
            app.mmLabel_18.Text = '[mm]';
            app.XpositionEditField_2Label = uilabel(app.StagesPanel_3);
            app.XpositionEditField_2Label.Enable = 'off';
            app.XpositionEditField_2Label.Text = 'X position';
            app.AOXpositionEditField = uieditfield(app.StagesPanel_3, 'numeric');
            app.AOXpositionEditField.Enable = 'off';
            app.AOXpositionEditField.Value = 100;
            app.YpositionEditField_2Label = uilabel(app.StagesPanel_3);
            app.YpositionEditField_2Label.Enable = 'off';
            app.YpositionEditField_2Label.Text = 'Y position';
            app.AOYpositionEditField = uieditfield(app.StagesPanel_3, 'numeric');
            app.AOYpositionEditField.Enable = 'off';
            app.AOYpositionEditField.Value = 86.5;
            app.AOMeasHereCheckBox = uicheckbox(app.StagesPanel_3);
            app.AOMeasHereCheckBox.ValueChangedFcn = createCallbackFcn(app, @AOMeasHereCheckBoxValueChanged, true);
            app.AOMeasHereCheckBox.Text = 'Measure at current pos';
            app.AOMeasHereCheckBox.Value = true;
            app.LiveAcoustoOpticsPanel = uipanel(app.SingleMeasurementTab);
            app.LiveAcoustoOpticsPanel.Title = 'Live Acousto Optics';
            app.LimitLiveAOCheckBox = uicheckbox(app.LiveAcoustoOpticsPanel);
            app.LimitLiveAOCheckBox.ValueChangedFcn = createCallbackFcn(app, @LimitLiveAOCheckBoxValueChanged, true);
            app.LimitLiveAOCheckBox.Text = 'LimitLiveAO';
            app.LimitLiveAOCheckBox.Value = true;
            app.NEditFieldLabel = uilabel(app.LiveAcoustoOpticsPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.LiveAcoustoOpticsPanel, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.Value = 1;
            app.ImportsavedResultsPanel = uipanel(app.SingleMeasurementTab);
            app.ImportsavedResultsPanel.Title = 'Import saved Results';
            app.DataEditFieldLabel = uilabel(app.ImportsavedResultsPanel);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.ImportsavedResultsPanel, 'text');
            app.DataEditField.Editable = 'off';
            app.analysisDataPathButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.analysisDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @analysisDataPathButtonPushed, true);
            app.analysisDataPathButton.Text = '...';
            app.AnalyzeOrigButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.AnalyzeOrigButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeOrigButtonPushed, true);
            app.AnalyzeOrigButton.Enable = 'off';
            app.AnalyzeOrigButton.Tooltip = {'Analyze loaded data with original variables.'};
            app.AnalyzeOrigButton.Text = 'Analyze Orig';
            app.VarsEditFieldLabel = uilabel(app.ImportsavedResultsPanel);
            app.VarsEditFieldLabel.Text = 'Vars';
            app.VarsEditField = uieditfield(app.ImportsavedResultsPanel, 'text');
            app.VarsEditField.Editable = 'off';
            app.analysisVarsPathButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.analysisVarsPathButton.ButtonPushedFcn = createCallbackFcn(app, @analysisVarsPathButtonPushed, true);
            app.analysisVarsPathButton.Text = '...';
            app.ImportButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImporAOResultstButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.AnalyizeNewButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.AnalyizeNewButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyizeNewButtonPushed, true);
            app.AnalyizeNewButton.Enable = 'off';
            app.AnalyizeNewButton.Tooltip = {'Analyse with variables appear in this GUI'};
            app.AnalyizeNewButton.Text = 'Analyize New';
            app.UseVirtualDataCheckBox = uicheckbox(app.ImportsavedResultsPanel);
            app.UseVirtualDataCheckBox.Text = 'Use Virtual Data';
            app.NoiesStdEditFieldLabel = uilabel(app.ImportsavedResultsPanel);
            app.NoiesStdEditFieldLabel.Text = 'Noies Std.';
            app.NoiesStdEditField = uieditfield(app.ImportsavedResultsPanel, 'numeric');
            app.ImportVarsButton = uibutton(app.SingleMeasurementTab, 'push');
            app.ImportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportVarsToGUIButtonPushed, true);
            app.ImportVarsButton.Text = 'Import Vars';
            app.ExportVarsButton = uibutton(app.SingleMeasurementTab, 'push');
            app.ExportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportVarsFromGUIButtonPushed, true);
            app.ExportVarsButton.Text = 'Export Vars';
            app.ReCalcDataButton = uibutton(app.SingleMeasurementTab, 'push');
            app.ReCalcDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReCalcDataButtonPushed, true);
            app.ReCalcDataButton.Text = 'Re-Calc Data';
            app.Scan2DTab = uitab(app.MeasMethod);
            app.Scan2DTab.Title = 'Scan 2D';
            app.S2DGridPanel = uipanel(app.Scan2DTab);
            app.S2DGridPanel.Title = 'Stages';
            app.S2DFirstScanAxisLabel = uilabel(app.S2DGridPanel);
            app.S2DFirstScanAxisLabel.Text = 'First Axis (Scan):';
            app.AxisDropDownLabel_2 = uilabel(app.S2DGridPanel);
            app.AxisDropDownLabel_2.Text = 'Axis';
            app.S2DFirstAxisDropDown = uidropdown(app.S2DGridPanel);
            app.S2DFirstAxisDropDown.Items = {'Y', 'X', 'Z'};
            app.S2DFirstAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @S2DFirstAxisDropDownValueChanged, true);
            app.S2DFirstAxisDropDown.Value = 'Y';
            app.mmLabel_20 = uilabel(app.S2DGridPanel);
            app.mmLabel_20.Text = '[mm]';
            app.StartEditFieldLabel_2 = uilabel(app.S2DGridPanel);
            app.StartEditFieldLabel_2.Text = 'Start';
            app.S2DFirstStartEditField = uieditfield(app.S2DGridPanel, 'numeric');
            app.S2DFirstStartEditField.Value = 95;
            app.mmLabel_21 = uilabel(app.S2DGridPanel);
            app.mmLabel_21.Text = '[mm]';
            app.StrideEditFieldLabel_2 = uilabel(app.S2DGridPanel);
            app.StrideEditFieldLabel_2.Text = 'Stride';
            app.S2DFirstStrideEditField = uieditfield(app.S2DGridPanel, 'numeric');
            app.S2DFirstStrideEditField.Value = 1;
            app.mmLabel_22 = uilabel(app.S2DGridPanel);
            app.mmLabel_22.Text = '[mm]';
            app.EndEditFieldLabel_2 = uilabel(app.S2DGridPanel);
            app.EndEditFieldLabel_2.Text = 'End';
            app.S2DFirstEndEditField = uieditfield(app.S2DGridPanel, 'numeric');
            app.S2DFirstEndEditField.Value = 105;
            app.SecondAxisLabel = uilabel(app.S2DGridPanel);
            app.SecondAxisLabel.Text = 'Second Axis:';
            app.AxisDropDown_2Label_2 = uilabel(app.S2DGridPanel);
            app.AxisDropDown_2Label_2.Text = 'Axis';
            app.S2DSecondAxisDropDown = uidropdown(app.S2DGridPanel);
            app.S2DSecondAxisDropDown.Items = {'Y', 'X', 'Z'};
            app.S2DSecondAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @S2DSecondAxisDropDownValueChanged, true);
            app.S2DSecondAxisDropDown.Value = 'Z';
            app.mmLabel_23 = uilabel(app.S2DGridPanel);
            app.mmLabel_23.Text = '[mm]';
            app.PosLabel = uilabel(app.S2DGridPanel);
            app.PosLabel.Text = 'Pos';
            app.S2DSecondPosEditField = uieditfield(app.S2DGridPanel, 'numeric');
            app.S2DSecondPosEditField.Value = 100;
            app.DontmovenonscanningstagesCheckBox = uicheckbox(app.S2DGridPanel);
            app.DontmovenonscanningstagesCheckBox.Text = 'Dont move non-scanning stages';
            app.ThirdAxisLabel = uilabel(app.S2DGridPanel);
            app.ThirdAxisLabel.Text = 'Third Axis';
            app.mmLabel_52 = uilabel(app.S2DGridPanel);
            app.mmLabel_52.Text = '[mm]';
            app.PosLabel_6 = uilabel(app.S2DGridPanel);
            app.PosLabel_6.Text = 'Pos';
            app.S2DThirdPosEditField = uieditfield(app.S2DGridPanel, 'numeric');
            app.S2DThirdPosEditField.Value = 100;
            app.AxisDropDown_2Label_4 = uilabel(app.S2DGridPanel);
            app.AxisDropDown_2Label_4.Text = 'Axis';
            app.S2DThirdAxisDropDown = uidropdown(app.S2DGridPanel);
            app.S2DThirdAxisDropDown.Items = {'Y', 'X', 'Z'};
            app.S2DThirdAxisDropDown.Value = 'X';
            app.S2DRunButton = uibutton(app.Scan2DTab, 'push');
            app.S2DRunButton.ButtonPushedFcn = createCallbackFcn(app, @S2DRunButtonPushed, true);
            app.S2DRunButton.Text = 'Run';
            app.S2DRepeatsPanel = uipanel(app.Scan2DTab);
            app.S2DRepeatsPanel.Title = 'Repeats';
            app.RepeatsEditFieldLabel_2 = uilabel(app.S2DRepeatsPanel);
            app.RepeatsEditFieldLabel_2.Text = 'Repeats';
            app.S2DRepeatsEditField = uieditfield(app.S2DRepeatsPanel, 'numeric');
            app.S2DRepeatsEditField.Value = 1;
            app.Scan3DTab = uitab(app.MeasMethod);
            app.Scan3DTab.Title = 'Scan 3D';
            app.s3DGridPanel = uipanel(app.Scan3DTab);
            app.s3DGridPanel.Title = 'Stages';
            app.mmLabel_4 = uilabel(app.s3DGridPanel);
            app.mmLabel_4.Text = '[mm]';
            app.mmLabel_5 = uilabel(app.s3DGridPanel);
            app.mmLabel_5.Text = '[mm]';
            app.mmLabel_6 = uilabel(app.s3DGridPanel);
            app.mmLabel_6.Text = '[mm]';
            app.mmLabel_7 = uilabel(app.s3DGridPanel);
            app.mmLabel_7.Text = '[mm]';
            app.mmLabel_8 = uilabel(app.s3DGridPanel);
            app.mmLabel_8.Text = '[mm]';
            app.mmLabel_9 = uilabel(app.s3DGridPanel);
            app.mmLabel_9.Text = '[mm]';
            app.StartEditFieldLabel = uilabel(app.s3DGridPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.S3DFirstStartEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DFirstStartEditField.Value = 100;
            app.EndEditFieldLabel = uilabel(app.s3DGridPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.S3DFirstEndEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DFirstEndEditField.Value = 105;
            app.StartEditField_2Label = uilabel(app.s3DGridPanel);
            app.StartEditField_2Label.Text = 'Start';
            app.S3DSecondStartEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DSecondStartEditField.Value = 43;
            app.EndEditField_2Label = uilabel(app.s3DGridPanel);
            app.EndEditField_2Label.Text = 'End';
            app.S3DSecondEndEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DSecondEndEditField.Value = 45;
            app.StrideEditFieldLabel = uilabel(app.s3DGridPanel);
            app.StrideEditFieldLabel.Text = 'Stride';
            app.S3DFirstStrideEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DFirstStrideEditField.Value = 1;
            app.StrideEditField_2Label = uilabel(app.s3DGridPanel);
            app.StrideEditField_2Label.Text = 'Stride';
            app.S3DSecondStrideEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DSecondStrideEditField.Value = 1;
            app.AxisDropDownLabel = uilabel(app.s3DGridPanel);
            app.AxisDropDownLabel.Text = 'Axis';
            app.S3DFirstAxisDropDown = uidropdown(app.s3DGridPanel);
            app.S3DFirstAxisDropDown.Items = {'Y', 'X', 'Z'};
            app.S3DFirstAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @S3DFirstAxisDropDownValueChanged, true);
            app.S3DFirstAxisDropDown.Value = 'X';
            app.S3DFirstScanAxisLabel = uilabel(app.s3DGridPanel);
            app.S3DFirstScanAxisLabel.Text = 'First Scan Axis:';
            app.S3DSecondScanAxisLabel = uilabel(app.s3DGridPanel);
            app.S3DSecondScanAxisLabel.Text = 'Second Scan Axis:';
            app.AxisDropDown_2Label = uilabel(app.s3DGridPanel);
            app.AxisDropDown_2Label.Text = 'Axis';
            app.S3DSecondAxisDropDown = uidropdown(app.s3DGridPanel);
            app.S3DSecondAxisDropDown.Items = {'Y', 'X', 'Z'};
            app.S3DSecondAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @S3DSecondAxisDropDownValueChanged, true);
            app.S3DSecondAxisDropDown.Value = 'Y';
            app.S3DSecondScanAxisLabel_2 = uilabel(app.s3DGridPanel);
            app.S3DSecondScanAxisLabel_2.Text = 'Third Axis:';
            app.AxisDropDownLabel_4 = uilabel(app.s3DGridPanel);
            app.AxisDropDownLabel_4.Text = 'Axis';
            app.S3DThirdAxisDropDown = uidropdown(app.s3DGridPanel);
            app.S3DThirdAxisDropDown.Items = {'X', 'Y', 'Z'};
            app.S3DThirdAxisDropDown.Value = 'Z';
            app.PosEditFieldLabel = uilabel(app.s3DGridPanel);
            app.PosEditFieldLabel.Text = 'Pos:';
            app.S3DThirdPosEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.mmLabel_53 = uilabel(app.s3DGridPanel);
            app.mmLabel_53.Text = '[mm]';
            app.S3DDontMoveNonScanninStages = uicheckbox(app.s3DGridPanel);
            app.S3DDontMoveNonScanninStages.Text = 'Dont move non-scanning stages';
            app.S3DRunButton = uibutton(app.Scan3DTab, 'push');
            app.S3DRunButton.ButtonPushedFcn = createCallbackFcn(app, @S3DRunButtonPushed, true);
            app.S3DRunButton.Text = 'Run';
            app.S3DRepeatsPanel = uipanel(app.Scan3DTab);
            app.S3DRepeatsPanel.Title = 'Repeats';
            app.RepeatsEditFieldLabel = uilabel(app.S3DRepeatsPanel);
            app.RepeatsEditFieldLabel.Text = 'Repeats';
            app.S3DRepeatsEditField = uieditfield(app.S3DRepeatsPanel, 'numeric');
            app.S3DRepeatsEditField.ValueChangedFcn = createCallbackFcn(app, @S3DRepeatsEditFieldValueChanged, true);
            app.S3DRepeatsEditField.Value = 1;
            app.S3DImportResultsButton = uibutton(app.Scan3DTab, 'push');
            app.S3DImportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportS2DscanResultsButtonPushed, true);
            app.S3DImportResultsButton.Text = 'Import Results';
            app.S3DExportScanVarsButton = uibutton(app.Scan3DTab, 'push');
            app.S3DExportScanVarsButton.ButtonPushedFcn = createCallbackFcn(app, @S3DExportScanVarsButtonPushed, true);
            app.S3DExportScanVarsButton.Text = 'Export Scan Vars';
            app.S3DImportScanVarsButton = uibutton(app.Scan3DTab, 'push');
            app.S3DImportScanVarsButton.ButtonPushedFcn = createCallbackFcn(app, @S3DImportScanVarsButtonPushed, true);
            app.S3DImportScanVarsButton.Text = 'Import Scan Vars';
            app.S3DImportLimitsfromfileButton = uibutton(app.Scan3DTab, 'push');
            app.S3DImportLimitsfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @S3DImportLimitsfromfileButtonPushed, true);
            app.S3DImportLimitsfromfileButton.Text = 'Import Limits from file';
            app.OptoAcousticsTab = uitab(app.MeasMethod);
            app.OptoAcousticsTab.Title = 'OptoAcoustics';
            app.OARunScanButton = uibutton(app.OptoAcousticsTab, 'push');
            app.OARunScanButton.ButtonPushedFcn = createCallbackFcn(app, @OARunScanButtonPushed, true);
            app.OARunScanButton.Text = 'Run Scan';
            app.OARunSingleButton = uibutton(app.OptoAcousticsTab, 'push');
            app.OARunSingleButton.ButtonPushedFcn = createCallbackFcn(app, @OARunSingleButtonPushed, true);
            app.OARunSingleButton.Text = 'Run Single';
            app.StartSinglePosEditFieldLabel = uilabel(app.OptoAcousticsTab);
            app.StartSinglePosEditFieldLabel.Text = 'Start\Single Pos';
            app.StartSinglePosEditField = uieditfield(app.OptoAcousticsTab, 'numeric');
            app.StartSinglePosEditField.Value = 190;
            app.StrideEditFieldLabel_5 = uilabel(app.OptoAcousticsTab);
            app.StrideEditFieldLabel_5.Text = 'Stride';
            app.StrideEditField = uieditfield(app.OptoAcousticsTab, 'numeric');
            app.StrideEditField.Value = 1;
            app.EndEditFieldLabel_5 = uilabel(app.OptoAcousticsTab);
            app.EndEditFieldLabel_5.Text = 'End';
            app.EndEditField = uieditfield(app.OptoAcousticsTab, 'numeric');
            app.EndEditField.Value = 200;
            app.FirstAxisEditFieldLabel = uilabel(app.OptoAcousticsTab);
            app.FirstAxisEditFieldLabel.Text = 'First Axis';
            app.FirstAxisEditField = uieditfield(app.OptoAcousticsTab, 'text');
            app.FirstAxisEditField.Value = 'X';
            app.SecondAxisEditFieldLabel = uilabel(app.OptoAcousticsTab);
            app.SecondAxisEditFieldLabel.Text = 'Second Axis';
            app.SecondAxisEditField = uieditfield(app.OptoAcousticsTab, 'text');
            app.SecondAxisEditField.Value = 'Y';
            app.ScanAxisEditFieldLabel = uilabel(app.OptoAcousticsTab);
            app.ScanAxisEditFieldLabel.Text = 'Scan Axis';
            app.ScanAxisEditField = uieditfield(app.OptoAcousticsTab, 'text');
            app.ScanAxisEditField.Value = 'Z';
            app.OARunLiveButton = uibutton(app.OptoAcousticsTab, 'push');
            app.OARunLiveButton.ButtonPushedFcn = createCallbackFcn(app, @OARunLiveButtonPushed, true);
            app.OARunLiveButton.Text = 'Run Live';
            app.mmLabel_47 = uilabel(app.OptoAcousticsTab);
            app.mmLabel_47.Text = '[mm]';
            app.mmLabel_48 = uilabel(app.OptoAcousticsTab);
            app.mmLabel_48.Text = '[mm]';
            app.mmLabel_49 = uilabel(app.OptoAcousticsTab);
            app.mmLabel_49.Text = '[mm]';
            app.PulseEchoUSTab = uitab(app.MeasMethod);
            app.PulseEchoUSTab.Title = 'Pulse-Echo US';
            app.DistancefromImageCenterLabel = uilabel(app.PulseEchoUSTab);
            app.DistancefromImageCenterLabel.Text = {'Distance from'; 'Image Center'};
            app.PEUSDistancefromImageCenterEditField = uieditfield(app.PulseEchoUSTab, 'numeric');
            app.PEUSDistancefromImageCenterEditField.Value = 9.6;
            app.ImageWidthEditField_2Label = uilabel(app.PulseEchoUSTab);
            app.ImageWidthEditField_2Label.Text = 'Image Width';
            app.PEUSImageWidthEditField = uieditfield(app.PulseEchoUSTab, 'numeric');
            app.PEUSImageWidthEditField.Value = 30;
            app.cmLabel_4 = uilabel(app.PulseEchoUSTab);
            app.cmLabel_4.Text = '[cm]';
            app.mmLabel_50 = uilabel(app.PulseEchoUSTab);
            app.mmLabel_50.Text = '[mm]';
            app.StartEditField_2Label_2 = uilabel(app.PulseEchoUSTab);
            app.StartEditField_2Label_2.Text = 'Start';
            app.PEUSStartEditField = uieditfield(app.PulseEchoUSTab, 'numeric');
            app.PEUSStartEditField.Value = 65;
            app.StrideEditField_2Label_2 = uilabel(app.PulseEchoUSTab);
            app.StrideEditField_2Label_2.Text = 'Stride';
            app.PEUSStrideEditField = uieditfield(app.PulseEchoUSTab, 'numeric');
            app.PEUSStrideEditField.Value = 1;
            app.EndEditField_2Label_2 = uilabel(app.PulseEchoUSTab);
            app.EndEditField_2Label_2.Text = 'End';
            app.PEUSEndEditField = uieditfield(app.PulseEchoUSTab, 'numeric');
            app.PEUSEndEditField.Value = 95;
            app.ScanAxisEditField_3Label = uilabel(app.PulseEchoUSTab);
            app.ScanAxisEditField_3Label.Text = 'Scan Axis';
            app.PEUSScanAxisEditField = uieditfield(app.PulseEchoUSTab, 'text');
            app.PEUSScanAxisEditField.Value = 'X';
            app.DepthAxisEditFieldLabel = uilabel(app.PulseEchoUSTab);
            app.DepthAxisEditFieldLabel.Text = 'Depth Axis';
            app.PEUSDepthAxisEditField = uieditfield(app.PulseEchoUSTab, 'text');
            app.PEUSDepthAxisEditField.Value = 'Y';
            app.ThirdsAxisEditFieldLabel = uilabel(app.PulseEchoUSTab);
            app.ThirdsAxisEditFieldLabel.Text = 'Thirds Axis';
            app.PEUSThirdsAxisEditField = uieditfield(app.PulseEchoUSTab, 'text');
            app.PEUSThirdsAxisEditField.Value = 'Z';
            app.mmLabel_43 = uilabel(app.PulseEchoUSTab);
            app.mmLabel_43.Text = '[mm]';
            app.mmLabel_44 = uilabel(app.PulseEchoUSTab);
            app.mmLabel_44.Text = '[mm]';
            app.mmLabel_45 = uilabel(app.PulseEchoUSTab);
            app.mmLabel_45.Text = '[mm]';
            app.PEUSRunScanButton = uibutton(app.PulseEchoUSTab, 'push');
            app.PEUSRunScanButton.ButtonPushedFcn = createCallbackFcn(app, @PEUSRunScanButtonPushed, true);
            app.PEUSRunScanButton.Text = 'Run Scan';
            app.PEUSDontmovesecondscanaxisCheckBox = uicheckbox(app.PulseEchoUSTab);
            app.PEUSDontmovesecondscanaxisCheckBox.Text = 'Dont move second scan axis';
            app.IntSystemTab = uitab(app.MeasMethod);
            app.IntSystemTab.Title = 'Int System';
            app.AOScanAxisEditFieldLabel = uilabel(app.IntSystemTab);
            app.AOScanAxisEditFieldLabel.Text = 'AO Scan Axis';
            app.AOScanAxisEditField = uieditfield(app.IntSystemTab, 'text');
            app.AOScanAxisEditField.Value = 'X';
            app.AODepthAxisEditFieldLabel = uilabel(app.IntSystemTab);
            app.AODepthAxisEditFieldLabel.Text = 'AO Depth Axis';
            app.AODepthAxisEditField = uieditfield(app.IntSystemTab, 'text');
            app.AODepthAxisEditField.Value = 'Y';
            app.OAScanAxisEditFieldLabel = uilabel(app.IntSystemTab);
            app.OAScanAxisEditFieldLabel.Text = 'OA Scan Axis';
            app.OAScanAxisEditField = uieditfield(app.IntSystemTab, 'text');
            app.OAScanAxisEditField.Value = 'Z';
            app.AOScanStartEditFieldLabel = uilabel(app.IntSystemTab);
            app.AOScanStartEditFieldLabel.Text = 'AO Scan Start';
            app.AOScanStartEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.AOScanStrideEditFieldLabel = uilabel(app.IntSystemTab);
            app.AOScanStrideEditFieldLabel.Text = 'AO Scan Stride';
            app.AOScanStrideEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.AOScanEndEditFieldLabel = uilabel(app.IntSystemTab);
            app.AOScanEndEditFieldLabel.Text = 'AO Scan End';
            app.AOScanEndEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.OAScanStartEditFieldLabel = uilabel(app.IntSystemTab);
            app.OAScanStartEditFieldLabel.Text = 'OA Scan Start';
            app.OAScanStartEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.OAScanStrideEditFieldLabel = uilabel(app.IntSystemTab);
            app.OAScanStrideEditFieldLabel.Text = 'OA Scan Stride';
            app.OAScanStrideEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.OAScanEndEditFieldLabel = uilabel(app.IntSystemTab);
            app.OAScanEndEditFieldLabel.Text = 'OA Scan End';
            app.OAScanEndEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.ImageWidthEditField_2Label_2 = uilabel(app.IntSystemTab);
            app.ImageWidthEditField_2Label_2.Text = 'Image Width';
            app.IntSysImageWidthEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.DistanceFromImageCenterLabel = uilabel(app.IntSystemTab);
            app.DistanceFromImageCenterLabel.Text = {'Distance From'; 'Image Center'};
            app.IntSysDistanceFromImageCenterEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.cmLabel_6 = uilabel(app.IntSystemTab);
            app.cmLabel_6.Text = '[cm]';
            app.cmLabel_7 = uilabel(app.IntSystemTab);
            app.cmLabel_7.Text = '[cm]';
            app.RunIntImagingButton = uibutton(app.IntSystemTab, 'push');
            app.RunIntImagingButton.ButtonPushedFcn = createCallbackFcn(app, @RunIntImagingButtonPushed, true);
            app.RunIntImagingButton.Text = 'Run Int Imaging';
            app.ConsistencyTab = uitab(app.MeasMethod);
            app.ConsistencyTab.Title = 'Consistency';
            app.RunButtonConsistency = uibutton(app.ConsistencyTab, 'push');
            app.RunButtonConsistency.ButtonPushedFcn = createCallbackFcn(app, @RunButtonConsistencyPushed, true);
            app.RunButtonConsistency.Text = 'Run';
            app.StagesPanel_2 = uipanel(app.ConsistencyTab);
            app.StagesPanel_2.Title = 'Stages';
            app.mmLabel_10 = uilabel(app.StagesPanel_2);
            app.mmLabel_10.Text = '[mm]';
            app.mmLabel_11 = uilabel(app.StagesPanel_2);
            app.mmLabel_11.Text = '[mm]';
            app.XpositionEditFieldLabel = uilabel(app.StagesPanel_2);
            app.XpositionEditFieldLabel.Text = 'X position';
            app.XpositionEditField = uieditfield(app.StagesPanel_2, 'numeric');
            app.XpositionEditField.Value = 124;
            app.YpositionEditFieldLabel = uilabel(app.StagesPanel_2);
            app.YpositionEditFieldLabel.Text = 'Y position';
            app.YpositionEditField = uieditfield(app.StagesPanel_2, 'numeric');
            app.YpositionEditField.Value = 90;
            app.TimeFramePropertiesPanel = uipanel(app.ConsistencyTab);
            app.TimeFramePropertiesPanel.Title = 'Time Frame Properties';
            app.sLabel = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel.Text = '[s]';
            app.sLabel_2 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_2.Text = '[s]';
            app.sLabel_3 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_3.Text = '[s]';
            app.sLabel_4 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_4.Text = '[s]';
            app.MaxTimeFrameDurationEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.MaxTimeFrameDurationEditFieldLabel.Text = 'Max Time Frame Duration';
            app.MaxTimeFrameDurationEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.MaxTimeFrameDurationEditField.Value = 8;
            app.MinTimeFrameDurationEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.MinTimeFrameDurationEditFieldLabel.Text = 'Min Time Frame Duration';
            app.MinTimeFrameDurationEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.MinTimeFrameDurationEditField.Value = 2;
            app.TimeStrideEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.TimeStrideEditFieldLabel.Text = 'Time Stride';
            app.TimeStrideEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.TimeStrideEditField.Value = 2;
            app.SetsperTimeFrameEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.SetsperTimeFrameEditFieldLabel.Text = 'Sets per Time Frame';
            app.SetsperTimeFrameEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.SetsperTimeFrameEditField.Value = 20;
            app.ImportResultsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ImportResultsButton_2.Enable = 'off';
            app.ImportResultsButton_2.Text = 'Import Results';
            app.ExportScanVarsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ExportScanVarsButton_2.Text = 'Export Scan Vars';
            app.ImportScanVarsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ImportScanVarsButton_2.Text = 'Import Scan Vars';
            app.FiguresPanel_2 = uipanel(app.ConsistencyTab);
            app.FiguresPanel_2.Title = 'Figures';
            app.FrameToPlotSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.FrameToPlotSpinnerLabel.Text = 'Frame To Plot';
            app.FrameToPlotSpinner = uispinner(app.FiguresPanel_2);
            app.FrameToPlotSpinner.Limits = [1 2];
            app.FrameToPlotSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameToPlotSpinnerValueChanged, true);
            app.FrameToPlotSpinner.Value = 1;
            app.SettoplotSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.SettoplotSpinnerLabel.Text = 'Set to plot';
            app.SettoplotSpinner = uispinner(app.FiguresPanel_2);
            app.SettoplotSpinner.Limits = [1 2];
            app.SettoplotSpinner.ValueChangedFcn = createCallbackFcn(app, @SettoplotSpinnerValueChanged, true);
            app.SettoplotSpinner.Value = 1;
            app.ZCoorSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.ZCoorSpinnerLabel.Text = 'Z Coor';
            app.ZCoorConsSpinner = uispinner(app.FiguresPanel_2);
            app.ZCoorConsSpinner.ValueChangingFcn = createCallbackFcn(app, @ZCoorConsSpinnerValueChanging, true);
            app.ZCoorConsSpinner.ValueChangedFcn = createCallbackFcn(app, @ZCoorConsSpinnerValueChanged, true);
            app.ZCoorConsSpinner.Value = 1;
            app.sLabel_6 = uilabel(app.FiguresPanel_2);
            app.sLabel_6.Text = '0 [s]';
            app.mmLabel_15 = uilabel(app.FiguresPanel_2);
            app.mmLabel_15.Text = '00.00[mm]';
            app.DeepViewTab = uitab(app.MeasMethod);
            app.DeepViewTab.Title = 'Deep View';
            app.DVGridPanel = uipanel(app.DeepViewTab);
            app.DVGridPanel.Title = 'Stages';
            app.DVFirstScanAxisLabel = uilabel(app.DVGridPanel);
            app.DVFirstScanAxisLabel.Text = 'First Scan Axis:';
            app.AxisDropDownLabel_3 = uilabel(app.DVGridPanel);
            app.AxisDropDownLabel_3.Text = 'Axis';
            app.DVFirstAxisDropDown = uidropdown(app.DVGridPanel);
            app.DVFirstAxisDropDown.Items = {'Y', 'X'};
            app.DVFirstAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @DVFirstAxisDropDownValueChanged, true);
            app.DVFirstAxisDropDown.Value = 'Y';
            app.mmLabel_25 = uilabel(app.DVGridPanel);
            app.mmLabel_25.Text = '[mm]';
            app.StartEditFieldLabel_3 = uilabel(app.DVGridPanel);
            app.StartEditFieldLabel_3.Text = 'Start';
            app.DVFirstStartEditField = uieditfield(app.DVGridPanel, 'numeric');
            app.DVFirstStartEditField.Value = 5;
            app.mmLabel_26 = uilabel(app.DVGridPanel);
            app.mmLabel_26.Text = '[mm]';
            app.StrideEditFieldLabel_3 = uilabel(app.DVGridPanel);
            app.StrideEditFieldLabel_3.Text = 'Stride';
            app.DVFirstStrideEditField = uieditfield(app.DVGridPanel, 'numeric');
            app.DVFirstStrideEditField.Value = 1;
            app.mmLabel_27 = uilabel(app.DVGridPanel);
            app.mmLabel_27.Text = '[mm]';
            app.EndEditFieldLabel_3 = uilabel(app.DVGridPanel);
            app.EndEditFieldLabel_3.Text = 'End';
            app.DVFirstEndEditField = uieditfield(app.DVGridPanel, 'numeric');
            app.DVFirstEndEditField.Value = 20;
            app.DVSecondScanAxisLabel = uilabel(app.DVGridPanel);
            app.DVSecondScanAxisLabel.Text = 'Second Scan Axis:';
            app.AxisDropDown_2Label_3 = uilabel(app.DVGridPanel);
            app.AxisDropDown_2Label_3.Text = 'Axis';
            app.DVSecondAxisDropDown = uidropdown(app.DVGridPanel);
            app.DVSecondAxisDropDown.Items = {'Y', 'X'};
            app.DVSecondAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @DVSecondAxisDropDownValueChanged, true);
            app.DVSecondAxisDropDown.Value = 'X';
            app.mmLabel_28 = uilabel(app.DVGridPanel);
            app.mmLabel_28.Text = '[mm]';
            app.PosLabel_2 = uilabel(app.DVGridPanel);
            app.PosLabel_2.Text = 'Pos';
            app.DVSecondPosEditField = uieditfield(app.DVGridPanel, 'numeric');
            app.DVSecondPosEditField.Value = 5;
            app.DVFiguresPanel = uipanel(app.DeepViewTab);
            app.DVFiguresPanel.Title = 'Figures';
            app.mmLabel_29 = uilabel(app.DVFiguresPanel);
            app.mmLabel_29.Text = '00.00[mm]';
            app.DepthCoorMainAxisLabel_4 = uilabel(app.DVFiguresPanel);
            app.DepthCoorMainAxisLabel_4.Text = {'Depth Coor'; '(Main Axis)'};
            app.DVCurDepthCoorSpinner = uispinner(app.DVFiguresPanel);
            app.DVCurDepthCoorSpinner.Limits = [0 1];
            app.DVCurDepthCoorSpinner.Enable = 'off';
            app.DVCurDepthCoorSpinner.Value = 1;
            app.TimePanel = uipanel(app.DeepViewTab);
            app.TimePanel.Title = 'Time';
            app.sLabel_7 = uilabel(app.TimePanel);
            app.sLabel_7.Text = '[s]';
            app.StartEditFieldLabel_4 = uilabel(app.TimePanel);
            app.StartEditFieldLabel_4.Text = 'Start';
            app.DVTimeStartEditField = uieditfield(app.TimePanel, 'numeric');
            app.DVTimeStartEditField.Value = 5;
            app.sLabel_8 = uilabel(app.TimePanel);
            app.sLabel_8.Text = '[s]';
            app.StrideEditFieldLabel_4 = uilabel(app.TimePanel);
            app.StrideEditFieldLabel_4.Text = 'Stride';
            app.DVTimeStrideEditField = uieditfield(app.TimePanel, 'numeric');
            app.DVTimeStrideEditField.Value = 1;
            app.sLabel_9 = uilabel(app.TimePanel);
            app.sLabel_9.Text = '[s]';
            app.EndEditFieldLabel_4 = uilabel(app.TimePanel);
            app.EndEditFieldLabel_4.Text = 'End';
            app.DVTimeEndEditField = uieditfield(app.TimePanel, 'numeric');
            app.DVTimeEndEditField.Value = 20;
            app.DVRunButton = uibutton(app.DeepViewTab, 'push');
            app.DVRunButton.ButtonPushedFcn = createCallbackFcn(app, @DVRunButtonPushed, true);
            app.DVRunButton.Text = 'Run';
            app.Tab_2 = uitab(app.MeasMethod);
            app.Tab_2.Title = 'Tab';
            app.GlobalParameters = uitabgroup(app.AOIUIFigure);
            app.GlobalParameters.SelectionChangedFcn = createCallbackFcn(app, @GlobalParametersSelectionChanged, true);
            app.SignalExcitationTab = uitab(app.GlobalParameters);
            app.SignalExcitationTab.Title = 'Signal Excitation';
            app.SamplingPanel = uipanel(app.SignalExcitationTab);
            app.SamplingPanel.Title = 'Sampling';
            app.MHzLabel_2 = uilabel(app.SamplingPanel);
            app.MHzLabel_2.Text = '[MHz]';
            app.FrequencyEditFieldLabel = uilabel(app.SamplingPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 20;
            app.secLabel = uilabel(app.SamplingPanel);
            app.secLabel.Text = '[sec]';
            app.TimetosampleEditFieldLabel = uilabel(app.SamplingPanel);
            app.TimetosampleEditFieldLabel.Text = 'Time to sample';
            app.TimetosampleEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.TimetosampleEditField.Value = 0.5;
            app.secLabel_2 = uilabel(app.SamplingPanel);
            app.secLabel_2.Text = '[sec]';
            app.FrameTimeEditFieldLabel = uilabel(app.SamplingPanel);
            app.FrameTimeEditFieldLabel.Text = 'Frame Time';
            app.FrameTimeEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.FrameTimeEditField.Value = 0.002;
            app.secLabel_3 = uilabel(app.SamplingPanel);
            app.secLabel_3.Text = '[sec]';
            app.MeasurementtimeimitEditFieldLabel = uilabel(app.SamplingPanel);
            app.MeasurementtimeimitEditFieldLabel.Text = 'Measurement time imit';
            app.MeasurementtimeimitEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.MeasurementtimeimitEditField.ValueChangedFcn = createCallbackFcn(app, @MeasurementtimeimitEditFieldValueChanged2, true);
            app.MeasurementtimeimitEditField.Value = 0.5;
            app.Label_18 = uilabel(app.SamplingPanel);
            app.Label_18.Text = '[#]';
            app.NumberOfChannelsEditFieldLabel = uilabel(app.SamplingPanel);
            app.NumberOfChannelsEditFieldLabel.Text = 'Number Of Channels';
            app.NumberOfChannelsEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.NumberOfChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfChannelsEditFieldValueChanged, true);
            app.NumberOfChannelsEditField.Value = 16;
            app.UltrasoundSignalPropertiesPanel = uipanel(app.SignalExcitationTab);
            app.UltrasoundSignalPropertiesPanel.Title = 'Ultrasound Signal Properties';
            app.MHzLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.MHzLabel.Text = '[MHz]';
            app.Label_13 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.Label_13.Text = '[#]';
            app.KHzLabel_3 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.KHzLabel_3.Text = '[KHz]';
            app.SineFreqEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.SineFreqEditFieldLabel.Text = 'Sine Freq.';
            app.SineFreqEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.SineFreqEditField.ValueChangedFcn = createCallbackFcn(app, @SineFreqEditFieldValueChanged, true);
            app.SineFreqEditField.Value = 1.25;
            app.SquenceFreqEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.SquenceFreqEditFieldLabel.Text = 'Squence Freq.';
            app.SquenceFreqEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.SquenceFreqEditField.ValueChangedFcn = createCallbackFcn(app, @SquenceFreqEditFieldValueChanged, true);
            app.SquenceFreqEditField.Value = 5;
            app.CyclesPerPulseEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.CyclesPerPulseEditFieldLabel.Text = 'Cycles Per Pulse';
            app.CyclesPerPulseEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.CyclesPerPulseEditField.ValueChangedFcn = createCallbackFcn(app, @CyclesPerPulseEditFieldValueChanged, true);
            app.CyclesPerPulseEditField.Value = 1;
            app.Label_19 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.Label_19.Text = '[%]';
            app.USPowerEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.USPowerEditFieldLabel.Text = 'US Power';
            app.USPowerEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.USPowerEditField.Limits = [0 100];
            app.USPowerEditField.Value = 100;
            app.GeometryPanel = uipanel(app.SignalExcitationTab);
            app.GeometryPanel.Title = 'Geometry';
            app.TransducerdistanceLabel = uilabel(app.GeometryPanel);
            app.TransducerdistanceLabel.Text = 'Transducer distance';
            app.TransducerdistanceEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.msLabel = uilabel(app.GeometryPanel);
            app.msLabel.Text = '[m/s]';
            app.SpeedinMediumEditFieldLabel = uilabel(app.GeometryPanel);
            app.SpeedinMediumEditFieldLabel.Text = 'Speed in Medium';
            app.SpeedinMediumEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.SpeedinMediumEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedinMediumEditFieldValueChanged, true);
            app.SpeedinMediumEditField.Value = 1500;
            app.cmLabel_3 = uilabel(app.GeometryPanel);
            app.cmLabel_3.Text = '[cm]';
            app.GeneralOnlineOperationPanel = uipanel(app.SignalExcitationTab);
            app.GeneralOnlineOperationPanel.Title = 'General Online Operation';
            app.UseTimeFrameCheckBox = uicheckbox(app.GeneralOnlineOperationPanel);
            app.UseTimeFrameCheckBox.Text = 'Use Time Frame';
            app.UseTimeFrameCheckBox.Value = true;
            app.HadamardCodeCheckBox = uicheckbox(app.GeneralOnlineOperationPanel);
            app.HadamardCodeCheckBox.Text = 'Hadamard Code';
            app.HadamardCodeCheckBox.Value = true;
            app.HighResAOCheckBox = uicheckbox(app.GeneralOnlineOperationPanel);
            app.HighResAOCheckBox.Text = 'High Res AO';
            app.SeparateChCheckBox = uicheckbox(app.GeneralOnlineOperationPanel);
            app.SeparateChCheckBox.Text = 'Separate Ch. ';
            app.ContSpeckleCheckBox = uicheckbox(app.GeneralOnlineOperationPanel);
            app.ContSpeckleCheckBox.Text = 'Cont. Speckle';
            app.ContHadamardCheckBox = uicheckbox(app.GeneralOnlineOperationPanel);
            app.ContHadamardCheckBox.Text = 'Cont. Hadamard';
            app.UseGPUCheckBox = uicheckbox(app.GeneralOnlineOperationPanel);
            app.UseGPUCheckBox.Text = 'Use GPU';
            app.UseGPUCheckBox.Value = true;
            app.ACCouplingCheckBox = uicheckbox(app.GeneralOnlineOperationPanel);
            app.ACCouplingCheckBox.Text = 'AC Coupling';
            app.ACCouplingCheckBox.Value = true;
            app.AutoCalibrationCheckBox = uicheckbox(app.GeneralOnlineOperationPanel);
            app.AutoCalibrationCheckBox.Text = 'Auto. Calibration';
            app.CalcMuEffCheckBox = uicheckbox(app.GeneralOnlineOperationPanel);
            app.CalcMuEffCheckBox.Text = 'CalcMuEff';
            app.UseCalibrationCheckBox = uicheckbox(app.GeneralOnlineOperationPanel);
            app.UseCalibrationCheckBox.Text = 'Use Calibration';
            app.FittingEnviromentsPanel = uipanel(app.SignalExcitationTab);
            app.FittingEnviromentsPanel.Title = 'Fitting Enviroments';
            app.DCEditFieldLabel = uilabel(app.FittingEnviromentsPanel);
            app.DCEditFieldLabel.Text = 'DC';
            app.envDC = uieditfield(app.FittingEnviromentsPanel, 'numeric');
            app.envDC.Value = 100;
            app.USEditFieldLabel = uilabel(app.FittingEnviromentsPanel);
            app.USEditFieldLabel.Text = 'US';
            app.envUS = uieditfield(app.FittingEnviromentsPanel, 'numeric');
            app.envUS.Value = 78;
            app.KHzLabel_6 = uilabel(app.FittingEnviromentsPanel);
            app.KHzLabel_6.Text = '[KHz]';
            app.KHzLabel_7 = uilabel(app.FittingEnviromentsPanel);
            app.KHzLabel_7.Text = '[KHz]';
            app.QuickPlotControlPanel = uipanel(app.SignalExcitationTab);
            app.QuickPlotControlPanel.Title = 'Quick Plot Control';
            app.SignalChannelSpinnerLabel = uilabel(app.QuickPlotControlPanel);
            app.SignalChannelSpinnerLabel.Text = 'Signal Channel';
            app.SignalChannelSpinner = uispinner(app.QuickPlotControlPanel);
            app.SignalChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelAOSpinnerValueChanged, true);
            app.SignalChannelSpinner.Value = 1;
            app.FrameLabel = uilabel(app.QuickPlotControlPanel);
            app.FrameLabel.Text = 'Frame';
            app.FrameAOSpinner = uispinner(app.QuickPlotControlPanel);
            app.FrameAOSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameAOSpinnerValueChanged, true);
            app.FrameAOSpinner.Value = 1;
            app.SeparateChannelSpinnerLabel = uilabel(app.QuickPlotControlPanel);
            app.SeparateChannelSpinnerLabel.Text = 'Separate Channel';
            app.SeparateChannelSpinner = uispinner(app.QuickPlotControlPanel);
            app.SeparateChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @SeparateChannelSpinnerValueChanged, true);
            app.SeparateChannelSpinner.Value = 1;
            app.mmLabelPlotPos = uilabel(app.QuickPlotControlPanel);
            app.mmLabelPlotPos.Text = '00.00[mm]';
            app.DepthCoorLabel = uilabel(app.QuickPlotControlPanel);
            app.DepthCoorLabel.Text = 'Depth Coor';
            app.AODepthIdxSpinner = uispinner(app.QuickPlotControlPanel);
            app.AODepthIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @AODepthIdxSpinnerValueChanged, true);
            app.AODepthIdxSpinner.Value = 106;
            app.ArtifactsPanel = uipanel(app.SignalExcitationTab);
            app.ArtifactsPanel.Title = 'Artifacts';
            app.CutArtifactsCheckBox = uicheckbox(app.ArtifactsPanel);
            app.CutArtifactsCheckBox.Text = 'Cut Artifacts';
            app.IndexesEditFieldLabel = uilabel(app.ArtifactsPanel);
            app.IndexesEditFieldLabel.Text = 'Indexes';
            app.IndexesEditField = uieditfield(app.ArtifactsPanel, 'text');
            app.IndexesEditField.Value = '200:220';
            app.OfflineCutButton = uibutton(app.ArtifactsPanel, 'push');
            app.OfflineCutButton.ButtonPushedFcn = createCallbackFcn(app, @OfflineCutButtonPushed, true);
            app.OfflineCutButton.Text = 'Offline Cut';
            app.CalibrationPanel = uipanel(app.SignalExcitationTab);
            app.CalibrationPanel.Title = 'Calibration';
            app.DurationEditFieldLabel = uilabel(app.CalibrationPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.secLabel_4 = uilabel(app.CalibrationPanel);
            app.secLabel_4.Text = '[sec]';
            app.CalibrateButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate!';
            app.MuEffCalculationPanel = uipanel(app.SignalExcitationTab);
            app.MuEffCalculationPanel.Title = 'MuEff Calculation';
            app.CalculateButton = uibutton(app.MuEffCalculationPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.IndexesEditField_2Label = uilabel(app.MuEffCalculationPanel);
            app.IndexesEditField_2Label.Text = 'Indexes:';
            app.muEffIndexesEditField = uieditfield(app.MuEffCalculationPanel, 'text');
            app.muEffIndexesEditField.Value = '10:37';
            app.ConfigurationDropDownLabel = uilabel(app.MuEffCalculationPanel);
            app.ConfigurationDropDownLabel.Text = 'Configuration:';
            app.ConfigurationDropDown = uidropdown(app.MuEffCalculationPanel);
            app.ConfigurationDropDown.Items = {'Uniform', 'Two Fibers', 'Point'};
            app.ConfigurationDropDown.Value = 'Uniform';
            app.PeripheralsTab = uitab(app.GlobalParameters);
            app.PeripheralsTab.Title = 'Peripherals';
            app.FunctionGeneratorPanel = uipanel(app.PeripheralsTab);
            app.FunctionGeneratorPanel.Title = 'Function Generator';
            app.SamplesClkFreqEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.SamplesClkFreqEditFieldLabel.Text = 'Samples Clk Freq.';
            app.SamplesClkFreqEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.SamplesClkFreqEditField.Value = 100;
            app.MSsLabel = uilabel(app.FunctionGeneratorPanel);
            app.MSsLabel.Text = '[MS/s]';
            app.Label_17 = uilabel(app.FunctionGeneratorPanel);
            app.Label_17.Text = '[%]';
            app.DutyCycleEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.DutyCycleEditField.Value = 50;
            app.TurnOffAFGButton = uibutton(app.PeripheralsTab, 'push');
            app.TurnOffAFGButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOffAFGButtonPushed, true);
            app.TurnOffAFGButton.Text = 'Turn Off AFG';
            app.CloseIOPortButton = uibutton(app.PeripheralsTab, 'push');
            app.CloseIOPortButton.ButtonPushedFcn = createCallbackFcn(app, @CloseIOPortButtonPushed, true);
            app.CloseIOPortButton.Text = 'Close IO Port';
            app.StopButton = uibutton(app.PeripheralsTab, 'push');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop!';
            app.DAQPanel = uipanel(app.PeripheralsTab);
            app.DAQPanel.Title = 'DAQ';
            app.SamplingRateEditFieldLabel = uilabel(app.DAQPanel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.DAQPanel, 'numeric');
            app.SamplingRateEditField.Value = 40000000;
            app.Label_14 = uilabel(app.DAQPanel);
            app.Label_14.Text = '[#]';
            app.Label_15 = uilabel(app.DAQPanel);
            app.Label_15.Text = '[#]';
            app.Label_16 = uilabel(app.DAQPanel);
            app.Label_16.Text = '[#]';
            app.sLabel_10 = uilabel(app.DAQPanel);
            app.sLabel_10.Text = '[s]';
            app.HzLabel_2 = uilabel(app.DAQPanel);
            app.HzLabel_2.Text = '[Hz]';
            app.SamplesEditFieldLabel = uilabel(app.DAQPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.DAQPanel, 'numeric');
            app.SamplesEditField.Value = 2030;
            app.AveragesEditFieldLabel = uilabel(app.DAQPanel);
            app.AveragesEditFieldLabel.Text = 'Averages';
            app.AveragesEditField = uieditfield(app.DAQPanel, 'numeric');
            app.AveragesEditField.Value = 40;
            app.FramesEditFieldLabel = uilabel(app.DAQPanel);
            app.FramesEditFieldLabel.Text = 'Frames';
            app.FramesEditField = uieditfield(app.DAQPanel, 'numeric');
            app.FramesEditField.Value = 1;
            app.DelayEditFieldLabel = uilabel(app.DAQPanel);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.DAQPanel, 'numeric');
            app.OsciloscopePanel = uipanel(app.PeripheralsTab);
            app.OsciloscopePanel.Title = 'Osciloscope';
            app.ChannelNoEditFieldLabel = uilabel(app.OsciloscopePanel);
            app.ChannelNoEditFieldLabel.Text = 'Channel No.';
            app.PEUSChannelNoEditField = uieditfield(app.OsciloscopePanel, 'numeric');
            app.PEUSChannelNoEditField.Value = 1;
            app.TrigChannelEditFieldLabel = uilabel(app.OsciloscopePanel);
            app.TrigChannelEditFieldLabel.Text = 'Trig Channel';
            app.PEUSTrigChannelEditField = uieditfield(app.OsciloscopePanel, 'numeric');
            app.PEUSTrigChannelEditField.Value = 4;
            app.NumofAvgEditFieldLabel = uilabel(app.OsciloscopePanel);
            app.NumofAvgEditFieldLabel.Text = 'Num of Avg.';
            app.PEUSNumofAvgEditField = uieditfield(app.OsciloscopePanel, 'numeric');
            app.PEUSNumofAvgEditField.Value = 64;
            app.RepetitionRateEditFieldLabel = uilabel(app.OsciloscopePanel);
            app.RepetitionRateEditFieldLabel.Text = 'Repetition Rate';
            app.PEUSRepetitionRateEditField = uieditfield(app.OsciloscopePanel, 'numeric');
            app.PEUSRepetitionRateEditField.Value = 100;
            app.IntValEditFieldLabel = uilabel(app.OsciloscopePanel);
            app.IntValEditFieldLabel.Text = 'Int Val.';
            app.PEUSIntValEditField = uieditfield(app.OsciloscopePanel, 'numeric');
            app.PEUSIntValEditField.Value = 100;
            app.HzLabel = uilabel(app.OsciloscopePanel);
            app.HzLabel.Text = '[Hz]';
            app.BackProjectionPanel = uipanel(app.PeripheralsTab);
            app.BackProjectionPanel.Title = 'BackProjection';
            app.mmLabel_46 = uilabel(app.BackProjectionPanel);
            app.mmLabel_46.Text = '[mm]';
            app.GeometryDropDownLabel = uilabel(app.BackProjectionPanel);
            app.GeometryDropDownLabel.Text = 'Geometry';
            app.GeometryDropDown = uidropdown(app.BackProjectionPanel);
            app.GeometryDropDown.Items = {'Circular'};
            app.GeometryDropDown.Value = 'Circular';
            app.ImageWidthEditFieldLabel = uilabel(app.BackProjectionPanel);
            app.ImageWidthEditFieldLabel.Text = 'Image Width';
            app.ImageWidthEditField = uieditfield(app.BackProjectionPanel, 'numeric');
            app.ImageWidthEditField.Value = 30;
            app.BPModeDropDownLabel = uilabel(app.BackProjectionPanel);
            app.BPModeDropDownLabel.Text = 'BP Mode';
            app.BPModeDropDown = uidropdown(app.BackProjectionPanel);
            app.BPModeDropDown.Items = {'Direct', 'Derivative', 'Full'};
            app.BPModeDropDown.Value = 'Direct';
            app.ResetDaqButton = uibutton(app.PeripheralsTab, 'push');
            app.ResetDaqButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDAQResetDaqButtonPushed, true);
            app.ResetDaqButton.Text = 'Reset Daq';
            app.DigitizerPanel = uipanel(app.PeripheralsTab);
            app.DigitizerPanel.Title = 'Digitizer';
            app.CroppedSamplsEditFieldLabel = uilabel(app.DigitizerPanel);
            app.CroppedSamplsEditFieldLabel.Text = 'Cropped Sampls ';
            app.CroppedSamplsEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.MonitorButton = uibutton(app.DigitizerPanel, 'push');
            app.MonitorButton.ButtonPushedFcn = createCallbackFcn(app, @MonitorButtonPushed, true);
            app.MonitorButton.Text = 'Monitor';
            app.UseusersetcropsamplesCheckBox = uicheckbox(app.DigitizerPanel);
            app.UseusersetcropsamplesCheckBox.Text = 'Use user set crop samples';
            app.PlotsDataTab = uitab(app.GlobalParameters);
            app.PlotsDataTab.Title = 'Plots & Data';
            app.TabGroup3 = uitabgroup(app.PlotsDataTab);
            app.AOTab = uitab(app.TabGroup3);
            app.AOTab.Title = 'AO';
            app.AcoustoOpticsPanel = uipanel(app.AOTab);
            app.AcoustoOpticsPanel.Title = 'Acousto Optics';
            app.ExternalClkCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.ExternalClkCheckBox.Text = 'External Clk';
            app.UltrasoundSignalCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.UltrasoundSignalCheckBox.Text = 'Ultrasound Signal';
            app.MeasuredCheckBox_2 = uicheckbox(app.AcoustoOpticsPanel);
            app.MeasuredCheckBox_2.Text = 'Measured';
            app.SignalCheckBox_2 = uicheckbox(app.AcoustoOpticsPanel);
            app.SignalCheckBox_2.Text = 'Signal';
            app.ReshapedCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.ReshapedCheckBox.Text = 'Reshaped';
            app.PhiCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.PhiCheckBox.Text = 'Phi';
            app.PhiCheckBox.Value = true;
            app.FittedPowerFFTCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.FittedPowerFFTCheckBox.Text = 'Fitted Power FFT ';
            app.FittedPowerFFTCheckBox.Value = true;
            app.DemultiplexedCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.DemultiplexedCheckBox.Text = 'Demultiplexed';
            app.FreqEnviromentEditFieldLabel = uilabel(app.AcoustoOpticsPanel);
            app.FreqEnviromentEditFieldLabel.Enable = 'off';
            app.FreqEnviromentEditFieldLabel.Text = 'Freq Enviroment';
            app.FreqEnviromentEditField = uieditfield(app.AcoustoOpticsPanel, 'numeric');
            app.FreqEnviromentEditField.ValueChangedFcn = createCallbackFcn(app, @FreqEnviromentAOEditFieldValueChanged, true);
            app.FreqEnviromentEditField.Enable = 'off';
            app.FreqEnviromentEditField.Value = 250;
            app.KHzLabel_9 = uilabel(app.AcoustoOpticsPanel);
            app.KHzLabel_9.Enable = 'off';
            app.KHzLabel_9.Text = 'KHz';
            app.dispFullFFTCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.dispFullFFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @dispFullFFTCheckBoxValueChanged, true);
            app.dispFullFFTCheckBox.Text = 'disp Full FFT';
            app.dispFullFFTCheckBox.Value = true;
            app.ExcitationSignalsLabel = uilabel(app.AcoustoOpticsPanel);
            app.ExcitationSignalsLabel.Text = 'Excitation Signals';
            app.TimeDomainSignalsLabel = uilabel(app.AcoustoOpticsPanel);
            app.TimeDomainSignalsLabel.Text = 'Time Domain Signals';
            app.FrequencyDomainLabel = uilabel(app.AcoustoOpticsPanel);
            app.FrequencyDomainLabel.Text = 'Frequency Domain';
            app.SpatialReconLabel = uilabel(app.AcoustoOpticsPanel);
            app.SpatialReconLabel.Text = 'Spatial Recon';
            app.PhiRawCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.PhiRawCheckBox.Text = 'Phi Raw';
            app.PhiRawCheckBox.Value = true;
            app.RawFFTCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.RawFFTCheckBox.Text = 'RawFFT';
            app.RawFFTCheckBox.Value = true;
            app.FittingModelCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.FittingModelCheckBox.Text = 'Fitting Model';
            app.FittingModelCheckBox.Value = true;
            app.FinalFFTCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.FinalFFTCheckBox.Text = 'Final FFT';
            app.FinalFFTCheckBox.Value = true;
            app.FFTDisplayPropertiesLabel = uilabel(app.AcoustoOpticsPanel);
            app.FFTDisplayPropertiesLabel.Text = 'FFT Display Properties';
            app.CroppedCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.CroppedCheckBox.Text = 'Cropped';
            app.PhiLogCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.PhiLogCheckBox.Text = 'Phi Log';
            app.PhiLogCheckBox.Value = true;
            app.TimeTablePanel = uipanel(app.AOTab);
            app.TimeTablePanel.Title = 'Time Table';
            app.DisplayTimeTableCheckBox = uicheckbox(app.TimeTablePanel);
            app.DisplayTimeTableCheckBox.Text = 'Display Time Table';
            app.DisplayTimeTableCheckBox.Value = true;
            app.ExportDataPanel = uipanel(app.AOTab);
            app.ExportDataPanel.Title = 'Export Data';
            app.MeasuredCheckBox = uicheckbox(app.ExportDataPanel);
            app.MeasuredCheckBox.Text = 'Measured';
            app.SignalCheckBox = uicheckbox(app.ExportDataPanel);
            app.SignalCheckBox.Text = 'Signal';
            app.DeMultiplexedCheckBox = uicheckbox(app.ExportDataPanel);
            app.DeMultiplexedCheckBox.Text = 'De-Multiplexed';
            app.ReshapedExportCheckBox = uicheckbox(app.ExportDataPanel);
            app.ReshapedExportCheckBox.Text = 'Reshaped';
            app.FFTCheckBox = uicheckbox(app.ExportDataPanel);
            app.FFTCheckBox.Text = 'FFT';
            app.ComplexComponentsUSCheckBox = uicheckbox(app.ExportDataPanel);
            app.ComplexComponentsUSCheckBox.Text = 'Complex Components (US)';
            app.OthersTab = uitab(app.TabGroup3);
            app.OthersTab.Title = 'Others';
            app.OAAxespropertiesPanel = uipanel(app.OthersTab);
            app.OAAxespropertiesPanel.Title = 'OA Axes properties';
            app.FirstReconAxLabel = uilabel(app.OAAxespropertiesPanel);
            app.FirstReconAxLabel.Text = 'First Recon Ax';
            app.OAFirstScanAxTypeDropDown = uidropdown(app.OAAxespropertiesPanel);
            app.OAFirstScanAxTypeDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.OAFirstScanAxTypeDropDown.Value = 'Normal';
            app.SecondReconAxLabel = uilabel(app.OAAxespropertiesPanel);
            app.SecondReconAxLabel.Text = 'Second Recon Ax';
            app.OASecondScanAxTypeDropDown = uidropdown(app.OAAxespropertiesPanel);
            app.OASecondScanAxTypeDropDown.Items = {'Normal', 'Centered', 'Zero', 'Index'};
            app.OASecondScanAxTypeDropDown.Value = 'Normal';
            app.ScanAxLabel = uilabel(app.OAAxespropertiesPanel);
            app.ScanAxLabel.Text = 'Scan Ax';
            app.OAScanAxTypeDropDown = uidropdown(app.OAAxespropertiesPanel);
            app.OAScanAxTypeDropDown.Items = {'Normal', 'Centered', 'Zero', 'Index'};
            app.OAScanAxTypeDropDown.Value = 'Normal';
            app.TimeAxLabel = uilabel(app.OAAxespropertiesPanel);
            app.TimeAxLabel.Text = 'Time Ax';
            app.OATimeAxTypeDropDown = uidropdown(app.OAAxespropertiesPanel);
            app.OATimeAxTypeDropDown.Items = {'Normal', 'Sample'};
            app.OATimeAxTypeDropDown.Value = 'Normal';
            app.OAScanNavigatorPanel = uipanel(app.OthersTab);
            app.OAScanNavigatorPanel.Title = 'OA Scan Navigator';
            app.NavPlainDropDownLabel_4 = uilabel(app.OAScanNavigatorPanel);
            app.NavPlainDropDownLabel_4.Text = 'Nav Plain';
            app.OANavPlainDropDown = uidropdown(app.OAScanNavigatorPanel);
            app.OANavPlainDropDown.Items = {'X', 'Y', 'Z'};
            app.OANavPlainDropDown.Enable = 'off';
            app.OANavPlainDropDown.Value = 'Z';
            app.NavIdxSpinnerLabel_5 = uilabel(app.OAScanNavigatorPanel);
            app.NavIdxSpinnerLabel_5.Text = 'Nav Idx';
            app.OANavIdxSpinner = uispinner(app.OAScanNavigatorPanel);
            app.OANavIdxSpinner.Limits = [0 Inf];
            app.OANavIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @OANavIdxSpinnerValueChanged, true);
            app.OANavIdxSpinner.Enable = 'off';
            app.OANavIdxSpinner.Value = 1;
            app.mmLabel_42 = uilabel(app.OAScanNavigatorPanel);
            app.mmLabel_42.Text = '00.00[mm]';
            app.OANormalizeColorstoDisplayedPlaneCheckBox = uicheckbox(app.OAScanNavigatorPanel);
            app.OANormalizeColorstoDisplayedPlaneCheckBox.Text = 'Normalize Colors to Displayed Plane';
            app.PEUSAxespropertiesPanel = uipanel(app.OthersTab);
            app.PEUSAxespropertiesPanel.Title = 'PEUS Axes properties';
            app.ScanReconAxLabel = uilabel(app.PEUSAxespropertiesPanel);
            app.ScanReconAxLabel.Text = 'Scan Recon Ax';
            app.PEUSFirstScanAxTypeDropDown = uidropdown(app.PEUSAxespropertiesPanel);
            app.PEUSFirstScanAxTypeDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.PEUSFirstScanAxTypeDropDown.Value = 'Normal';
            app.DepthReconAxLabel = uilabel(app.PEUSAxespropertiesPanel);
            app.DepthReconAxLabel.Text = 'Depth Recon Ax';
            app.PEUSSecondScanAxTypeDropDown = uidropdown(app.PEUSAxespropertiesPanel);
            app.PEUSSecondScanAxTypeDropDown.Items = {'Normal', 'Centered', 'Zero', 'Index'};
            app.PEUSSecondScanAxTypeDropDown.Value = 'Normal';
            app.ScanPropertiesPanel = uipanel(app.OthersTab);
            app.ScanPropertiesPanel.Title = 'Scan Properties';
            app.NavPlainDropDownLabel_5 = uilabel(app.ScanPropertiesPanel);
            app.NavPlainDropDownLabel_5.Text = 'Nav Plain';
            app.S3DNavPlainDropDown = uidropdown(app.ScanPropertiesPanel);
            app.S3DNavPlainDropDown.Items = {'X', 'Y', 'Z'};
            app.S3DNavPlainDropDown.ValueChangedFcn = createCallbackFcn(app, @S3DNavPlainDropDownValueChanged, true);
            app.S3DNavPlainDropDown.Enable = 'off';
            app.S3DNavPlainDropDown.Value = 'Z';
            app.ReapeatSpinnerLabel = uilabel(app.ScanPropertiesPanel);
            app.ReapeatSpinnerLabel.Text = 'Reapeat';
            app.S3DRepeatSpinner = uispinner(app.ScanPropertiesPanel);
            app.S3DRepeatSpinner.Limits = [1 Inf];
            app.S3DRepeatSpinner.ValueChangedFcn = createCallbackFcn(app, @S3DRepeatSpinnerValueChanged, true);
            app.S3DRepeatSpinner.Enable = 'off';
            app.S3DRepeatSpinner.Value = 1;
            app.NavIdxSpinnerLabel_6 = uilabel(app.ScanPropertiesPanel);
            app.NavIdxSpinnerLabel_6.Text = 'Nav Idx';
            app.S3DNavIdxSpinner = uispinner(app.ScanPropertiesPanel);
            app.S3DNavIdxSpinner.LowerLimitInclusive = 'off';
            app.S3DNavIdxSpinner.Limits = [0 Inf];
            app.S3DNavIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @S3DNavIdxSpinnerValueChanged, true);
            app.S3DNavIdxSpinner.Enable = 'off';
            app.S3DNavIdxSpinner.Value = 1;
            app.s3DNavIdxLabel = uilabel(app.ScanPropertiesPanel);
            app.s3DNavIdxLabel.Text = '00.00[mm]';
            app.NormalizeColorstoDisplayedPlaneCheckBox = uicheckbox(app.ScanPropertiesPanel);
            app.NormalizeColorstoDisplayedPlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeColorstoDisplayedPlaneCheckBoxValueChanged, true);
            app.NormalizeColorstoDisplayedPlaneCheckBox.Text = 'Normalize Colors to Displayed Plane';
            app.s3DMainAxisIdxLabel = uilabel(app.ScanPropertiesPanel);
            app.s3DMainAxisIdxLabel.Text = '00.00[mm]';
            app.MainAxisDepthCoorLabel = uilabel(app.ScanPropertiesPanel);
            app.MainAxisDepthCoorLabel.Text = 'Main Axis Depth Coor';
            app.S2DCurDepthCoorSpinner = uispinner(app.ScanPropertiesPanel);
            app.S2DCurDepthCoorSpinner.Limits = [0 1];
            app.S2DCurDepthCoorSpinner.Enable = 'off';
            app.S2DCurDepthCoorSpinner.Value = 1;
            app.GeneralTab = uitab(app.TabGroup3);
            app.GeneralTab.Title = 'General';
            app.PopoutFiguresPanel = uipanel(app.GeneralTab);
            app.PopoutFiguresPanel.Title = 'Pop out Figures';
            app.PopButton = uibutton(app.PopoutFiguresPanel, 'push');
            app.PopButton.ButtonPushedFcn = createCallbackFcn(app, @PopButtonPushed, true);
            app.PopButton.Text = 'Pop!';
            app.FiguresToPopDropDown = uidropdown(app.PopoutFiguresPanel);
            app.FiguresToPopDropDown.Items = {'Acousto Optics', 'Scan 2D', 'Scan 3D', 'Deep View'};
            app.FiguresToPopDropDown.Value = 'Acousto Optics';
            app.ExternalFiguresCheckBox = uicheckbox(app.PopoutFiguresPanel);
            app.ExternalFiguresCheckBox.Text = 'External Figures';
            app.ReOpenFiguresCheckBox = uicheckbox(app.PopoutFiguresPanel);
            app.ReOpenFiguresCheckBox.Text = 'Re-Open Figures';
            app.AxespropertiesPanel = uipanel(app.GeneralTab);
            app.AxespropertiesPanel.Title = 'Axes properties';
            app.FirstScanAxDropDownLabel = uilabel(app.AxespropertiesPanel);
            app.FirstScanAxDropDownLabel.Text = 'First Scan Ax';
            app.AOFirstScanAxDropDown = uidropdown(app.AxespropertiesPanel);
            app.AOFirstScanAxDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.AOFirstScanAxDropDown.Value = 'Normal';
            app.SecondScanAxDropDownLabel = uilabel(app.AxespropertiesPanel);
            app.SecondScanAxDropDownLabel.Text = 'Second Scan Ax';
            app.AOSecondScanAxDropDown = uidropdown(app.AxespropertiesPanel);
            app.AOSecondScanAxDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.AOSecondScanAxDropDown.Value = 'Normal';
            app.DepthDropDownLabel = uilabel(app.AxespropertiesPanel);
            app.DepthDropDownLabel.Text = 'Depth';
            app.AODepthDropDown = uidropdown(app.AxespropertiesPanel);
            app.AODepthDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.AODepthDropDown.Value = 'Index';
            app.DropDown = uidropdown(app.AxespropertiesPanel);
            app.DropDown.Items = {'AO', 'S2D', 'S3D', 'DV'};
            app.DropDown.Value = 'AO';
            app.RefreshButton = uibutton(app.AxespropertiesPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.NotificationsScansPanel = uipanel(app.GeneralTab);
            app.NotificationsScansPanel.Title = 'Notifications (Scans)';
            app.SendmeemailwhendoneCheckBox = uicheckbox(app.NotificationsScansPanel);
            app.SendmeemailwhendoneCheckBox.Text = 'Send me email when done';
            app.emailEditFieldLabel = uilabel(app.NotificationsScansPanel);
            app.emailEditFieldLabel.Text = 'email:';
            app.emailEditField = uieditfield(app.NotificationsScansPanel, 'text');
            app.KeepmeupdatedviaTelegramCheckBox = uicheckbox(app.NotificationsScansPanel);
            app.KeepmeupdatedviaTelegramCheckBox.Text = 'Keep me updated via Telegram';
            app.ChatIDEditFieldLabel = uilabel(app.NotificationsScansPanel);
            app.ChatIDEditFieldLabel.Text = 'Chat ID';
            app.ChatIDEditField = uieditfield(app.NotificationsScansPanel, 'text');
            app.ChatIDEditField.Value = '-512325870';
            app.StagesTab = uitab(app.GlobalParameters);
            app.StagesTab.Title = 'Stages';
            app.AxisAllocationPanel = uipanel(app.StagesTab);
            app.AxisAllocationPanel.Title = 'Axis Allocation';
            app.DepthAxisDropDownLabel = uilabel(app.AxisAllocationPanel);
            app.DepthAxisDropDownLabel.Text = 'Depth Axis';
            app.DepthAxisDropDown = uidropdown(app.AxisAllocationPanel);
            app.DepthAxisDropDown.Items = {'Z', 'X', 'Y'};
            app.DepthAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @DepthAxisDropDownValueChanged, true);
            app.DepthAxisDropDown.Value = 'X';
            app.stage1AxLabel = uilabel(app.AxisAllocationPanel);
            app.stage1AxLabel.Text = 'stage 1 Ax';
            app.stage1AxDropDown = uidropdown(app.AxisAllocationPanel);
            app.stage1AxDropDown.Items = {'X', 'Y', 'Z'};
            app.stage1AxDropDown.ValueChangedFcn = createCallbackFcn(app, @stage1AxDropDownValueChanged, true);
            app.stage1AxDropDown.Value = 'X';
            app.stage2AxLabel = uilabel(app.AxisAllocationPanel);
            app.stage2AxLabel.Text = 'stage 2 Ax';
            app.stage2AxDropDown = uidropdown(app.AxisAllocationPanel);
            app.stage2AxDropDown.Items = {'X', 'Y', 'Z'};
            app.stage2AxDropDown.ValueChangedFcn = createCallbackFcn(app, @stage2AxDropDownValueChanged, true);
            app.stage2AxDropDown.Value = 'Y';
            app.SetStagesGeometryButton = uibutton(app.AxisAllocationPanel, 'push');
            app.SetStagesGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @SetStagesGeometryButtonPushed, true);
            app.SetStagesGeometryButton.Text = {'Set Stages'; 'Geometry'};
            app.TestAssignmentButton = uibutton(app.AxisAllocationPanel, 'push');
            app.TestAssignmentButton.ButtonPushedFcn = createCallbackFcn(app, @TestAssignmentButtonPushed, true);
            app.TestAssignmentButton.Text = 'Test Assignment';
            app.stage3AxLabel = uilabel(app.AxisAllocationPanel);
            app.stage3AxLabel.Text = 'stage 3 Ax';
            app.stage3AxDropDown = uidropdown(app.AxisAllocationPanel);
            app.stage3AxDropDown.Items = {'X', 'Y', 'Z'};
            app.stage3AxDropDown.ValueChangedFcn = createCallbackFcn(app, @stage3AxDropDownValueChanged, true);
            app.stage3AxDropDown.Value = 'Z';
            app.Stage1Panel = uipanel(app.StagesTab);
            app.Stage1Panel.Title = 'Stage 1 (X)';
            app.PosLabel_3 = uilabel(app.Stage1Panel);
            app.PosLabel_3.Text = 'Pos:';
            app.Stage1PositionEditField = uieditfield(app.Stage1Panel, 'numeric');
            app.Stage1PositionEditField.Value = 109;
            app.mmLabel_30 = uilabel(app.Stage1Panel);
            app.mmLabel_30.Text = '[mm]';
            app.Stage1MoveToPositionButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1MoveToPositionButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1MoveToPositionButtonPushed, true);
            app.Stage1MoveToPositionButton.Text = 'Move To Position';
            app.Stage1Increase = uibutton(app.Stage1Panel, 'push');
            app.Stage1Increase.ButtonPushedFcn = createCallbackFcn(app, @Stage1IncreaseButtonPushed, true);
            app.Stage1Increase.Text = '+';
            app.Stage1Decrease = uibutton(app.Stage1Panel, 'push');
            app.Stage1Decrease.ButtonPushedFcn = createCallbackFcn(app, @Stage1DecreaseButtonPushed, true);
            app.Stage1Decrease.Text = '-';
            app.mmLabel_31 = uilabel(app.Stage1Panel);
            app.mmLabel_31.Text = '[mm]';
            app.Stage1SetStartButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1SetStartButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1SetStartButtonPushed, true);
            app.Stage1SetStartButton.Text = 'Set Start';
            app.Stage1SetEndButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1SetEndButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1SetEndButtonPushed, true);
            app.Stage1SetEndButton.Text = 'Set End';
            app.Stage1SetStepButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1SetStepButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1SetStepButtonPushed, true);
            app.Stage1SetStepButton.Text = 'Set Step';
            app.StepLabel = uilabel(app.Stage1Panel);
            app.StepLabel.Text = 'Step:';
            app.Stage1StrideEditField = uieditfield(app.Stage1Panel, 'numeric');
            app.Stage1StrideEditField.Value = 1;
            app.HomeStage1 = uibutton(app.Stage1Panel, 'push');
            app.HomeStage1.ButtonPushedFcn = createCallbackFcn(app, @HomeStage1ButtonPushed, true);
            app.HomeStage1.Text = 'Home';
            app.HighEditFieldLabel = uilabel(app.Stage1Panel);
            app.HighEditFieldLabel.Text = 'High';
            app.HighLimitStage1 = uieditfield(app.Stage1Panel, 'numeric');
            app.HighLimitStage1.Value = 150;
            app.LowEditFieldLabel = uilabel(app.Stage1Panel);
            app.LowEditFieldLabel.Text = 'Low';
            app.LowLimitStage1 = uieditfield(app.Stage1Panel, 'numeric');
            app.mmLabelStage1 = uilabel(app.Stage1Panel);
            app.mmLabelStage1.Text = '[mm]';
            app.SetLimitsButtonStage1 = uibutton(app.Stage1Panel, 'push');
            app.SetLimitsButtonStage1.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButtonStage1Pushed, true);
            app.SetLimitsButtonStage1.Text = 'Set Limits';
            app.stagesTable = uitable(app.StagesTab);
            app.stagesTable.ColumnName = {'Stage 1'; 'Stage 2'; 'Stage 3'};
            app.stagesTable.RowName = {'Position'; 'Start'; 'Stride'; 'End'};
            app.ExportPositionPanel = uipanel(app.StagesTab);
            app.ExportPositionPanel.Title = 'Export Position';
            app.ExportToFileButton = uibutton(app.ExportPositionPanel, 'push');
            app.ExportToFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportToFileButtonPushed, true);
            app.ExportToFileButton.Text = 'Export To File';
            app.ExporttoscanDropDownLabel = uilabel(app.ExportPositionPanel);
            app.ExporttoscanDropDownLabel.Text = 'Export to scan:';
            app.ExporttoscanDropDown = uidropdown(app.ExportPositionPanel);
            app.ExporttoscanDropDown.Items = {'Scan 2D', 'Scan 3D', 'Deep View'};
            app.ExporttoscanDropDown.ValueChangedFcn = createCallbackFcn(app, @ExporttoscanDropDownValueChanged, true);
            app.ExporttoscanDropDown.Value = 'Scan 2D';
            app.CopytoScanButton = uibutton(app.ExportPositionPanel, 'push');
            app.CopytoScanButton.ButtonPushedFcn = createCallbackFcn(app, @CopytoScanButtonPushed, true);
            app.CopytoScanButton.Text = 'Copy to Scan';
            app.AllStagesHomeButton = uibutton(app.StagesTab, 'push');
            app.AllStagesHomeButton.ButtonPushedFcn = createCallbackFcn(app, @AllStagesHomeButtonPushed, true);
            app.AllStagesHomeButton.Text = 'All Stages Home';
            app.RefreshPositionButton = uibutton(app.StagesTab, 'push');
            app.RefreshPositionButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPositionButtonPushed, true);
            app.RefreshPositionButton.Text = 'Refresh Position';
            app.Stage2Panel = uipanel(app.StagesTab);
            app.Stage2Panel.Title = 'Stage 2 (Y)';
            app.PosLabel_4 = uilabel(app.Stage2Panel);
            app.PosLabel_4.Text = 'Pos:';
            app.Stage2PositionEditField = uieditfield(app.Stage2Panel, 'numeric');
            app.Stage2PositionEditField.Value = 52;
            app.mmLabel_32 = uilabel(app.Stage2Panel);
            app.mmLabel_32.Text = '[mm]';
            app.Stage2MoveToPositionButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2MoveToPositionButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2MoveToPositionButtonPushed, true);
            app.Stage2MoveToPositionButton.Text = 'Move To Position';
            app.Stage2Increase = uibutton(app.Stage2Panel, 'push');
            app.Stage2Increase.ButtonPushedFcn = createCallbackFcn(app, @Stage2IncreaseButtonPushed, true);
            app.Stage2Increase.Text = '+';
            app.Stage2Decrease = uibutton(app.Stage2Panel, 'push');
            app.Stage2Decrease.ButtonPushedFcn = createCallbackFcn(app, @Stage2DecreaseButtonPushed, true);
            app.Stage2Decrease.Text = '-';
            app.mmLabel_33 = uilabel(app.Stage2Panel);
            app.mmLabel_33.Text = '[mm]';
            app.StepLabel_2 = uilabel(app.Stage2Panel);
            app.StepLabel_2.Text = 'Step:';
            app.Stage2StrideEditField = uieditfield(app.Stage2Panel, 'numeric');
            app.Stage2StrideEditField.Value = 1;
            app.Stage2SetStartButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2SetStartButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2SetStartButtonPushed2, true);
            app.Stage2SetStartButton.Text = 'Set Start';
            app.Stage2SetEndButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2SetEndButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2SetEndButtonPushed, true);
            app.Stage2SetEndButton.Text = 'Set End';
            app.Stage2SetStepButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2SetStepButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2SetStepButtonPushed, true);
            app.Stage2SetStepButton.Text = 'Set Step';
            app.HomeStage2 = uibutton(app.Stage2Panel, 'push');
            app.HomeStage2.ButtonPushedFcn = createCallbackFcn(app, @HomeStage2ButtonPushed, true);
            app.HomeStage2.Text = 'Home';
            app.HighEditFieldLabel_2 = uilabel(app.Stage2Panel);
            app.HighEditFieldLabel_2.Text = 'High';
            app.HighLimitStage2 = uieditfield(app.Stage2Panel, 'numeric');
            app.HighLimitStage2.Value = 150;
            app.LowEditFieldLabel_2 = uilabel(app.Stage2Panel);
            app.LowEditFieldLabel_2.Text = 'Low';
            app.LowLimitStage2 = uieditfield(app.Stage2Panel, 'numeric');
            app.mmLabelStage2 = uilabel(app.Stage2Panel);
            app.mmLabelStage2.Text = '[mm]';
            app.SetLimitsButtonStage2 = uibutton(app.Stage2Panel, 'push');
            app.SetLimitsButtonStage2.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButtonStage2Pushed, true);
            app.SetLimitsButtonStage2.Text = 'Set Limits';
            app.Stage3Panel = uipanel(app.StagesTab);
            app.Stage3Panel.Title = 'Stage 3 (Z)';
            app.PosLabel_5 = uilabel(app.Stage3Panel);
            app.PosLabel_5.Text = 'Pos:';
            app.Stage3PositionEditField = uieditfield(app.Stage3Panel, 'numeric');
            app.Stage3PositionEditField.Value = 28.9;
            app.mmLabel_34 = uilabel(app.Stage3Panel);
            app.mmLabel_34.Text = '[mm]';
            app.Stage3MoveToPositionButton = uibutton(app.Stage3Panel, 'push');
            app.Stage3MoveToPositionButton.ButtonPushedFcn = createCallbackFcn(app, @Stage3MoveToPositionButtonPushed, true);
            app.Stage3MoveToPositionButton.Text = 'Move To Position';
            app.Stage3Increase = uibutton(app.Stage3Panel, 'push');
            app.Stage3Increase.ButtonPushedFcn = createCallbackFcn(app, @Stage3IncreaseButtonPushed, true);
            app.Stage3Increase.Text = '+';
            app.Stage3Decrease = uibutton(app.Stage3Panel, 'push');
            app.Stage3Decrease.ButtonPushedFcn = createCallbackFcn(app, @Stage3DecreaseButtonPushed, true);
            app.Stage3Decrease.Text = '-';
            app.mmLabel_35 = uilabel(app.Stage3Panel);
            app.mmLabel_35.Text = '[mm]';
            app.StepLabel_3 = uilabel(app.Stage3Panel);
            app.StepLabel_3.Text = 'Step:';
            app.Stage3StrideEditField = uieditfield(app.Stage3Panel, 'numeric');
            app.Stage3StrideEditField.Value = 1;
            app.Stage3SetStartButton = uibutton(app.Stage3Panel, 'push');
            app.Stage3SetStartButton.ButtonPushedFcn = createCallbackFcn(app, @Stage3SetStartButtonPushed, true);
            app.Stage3SetStartButton.Text = 'Set Start';
            app.Stage3SetEndButton = uibutton(app.Stage3Panel, 'push');
            app.Stage3SetEndButton.ButtonPushedFcn = createCallbackFcn(app, @Stage3SetEndButtonPushed, true);
            app.Stage3SetEndButton.Text = 'Set End';
            app.Stage3SetStepButton = uibutton(app.Stage3Panel, 'push');
            app.Stage3SetStepButton.ButtonPushedFcn = createCallbackFcn(app, @Stage3SetStepButtonPushed, true);
            app.Stage3SetStepButton.Text = 'Set Step';
            app.HomeStage3 = uibutton(app.Stage3Panel, 'push');
            app.HomeStage3.ButtonPushedFcn = createCallbackFcn(app, @HomeStage3ButtonPushed, true);
            app.HomeStage3.Text = 'Home';
            app.HighEditFieldLabel_3 = uilabel(app.Stage3Panel);
            app.HighEditFieldLabel_3.Text = 'High';
            app.HighLimitStage3 = uieditfield(app.Stage3Panel, 'numeric');
            app.HighLimitStage3.Value = 150;
            app.LowEditFieldLabel_3 = uilabel(app.Stage3Panel);
            app.LowEditFieldLabel_3.Text = 'Low';
            app.LowLimitStage3 = uieditfield(app.Stage3Panel, 'numeric');
            app.mmLabelStage3 = uilabel(app.Stage3Panel);
            app.mmLabelStage3.Text = '[mm]';
            app.SetLimitsButtonStage2_2 = uibutton(app.Stage3Panel, 'push');
            app.SetLimitsButtonStage2_2.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButtonStage2_2Pushed, true);
            app.SetLimitsButtonStage2_2.Text = 'Set Limits';
            app.ReferenceAllButton = uibutton(app.StagesTab, 'push');
            app.ReferenceAllButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceAllButtonPushed, true);
            app.ReferenceAllButton.Text = 'Reference All';
            app.acoustoOpticsPlotsTab = uitabgroup(app.AOIUIFigure);
            app.acoustoOpticsPlotsTab.SelectionChangedFcn = createCallbackFcn(app, @acoustoOpticsPlotsTabSelectionChanged, true);
            app.TimeTable = uitab(app.acoustoOpticsPlotsTab);
            app.TimeTable.Title = 'Time Table';
            app.UITable2 = uitable(app.TimeTable);
            app.UITable2.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2.RowName = {};
            app.UITable2_2 = uitable(app.TimeTable);
            app.UITable2_2.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2_2.RowName = {};
            app.UITable2_3 = uitable(app.TimeTable);
            app.UITable2_3.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2_3.RowName = {};
            app.AOVarsTab = uitab(app.acoustoOpticsPlotsTab);
            app.AOVarsTab.Title = 'AO Vars';
            app.TabGroup2 = uitabgroup(app.AOVarsTab);
            app.SystemClkTab = uitab(app.TabGroup2);
            app.SystemClkTab.Title = 'System Clk';
            app.UITable = uitable(app.SystemClkTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable.RowName = {};
            app.USSignalTab = uitab(app.TabGroup2);
            app.USSignalTab.Title = 'US Signal';
            app.UITable_2 = uitable(app.USSignalTab);
            app.UITable_2.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_2.RowName = {};
            app.SamplesTab = uitab(app.TabGroup2);
            app.SamplesTab.Title = 'Samples';
            app.UITable_3 = uitable(app.SamplesTab);
            app.UITable_3.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_3.RowName = {};
            app.TimingTab = uitab(app.TabGroup2);
            app.TimingTab.Title = 'Timing';
            app.UITable_4 = uitable(app.TimingTab);
            app.UITable_4.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_4.RowName = {};
            app.DigitizerTab = uitab(app.TabGroup2);
            app.DigitizerTab.Title = 'Digitizer';
            app.UITable_5 = uitable(app.DigitizerTab);
            app.UITable_5.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_5.RowName = {};
            app.LengthTab = uitab(app.TabGroup2);
            app.LengthTab.Title = 'Length';
            app.UITable_6 = uitable(app.LengthTab);
            app.UITable_6.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_6.RowName = {};
            app.FreqTab = uitab(app.TabGroup2);
            app.FreqTab.Title = 'Freq';
            app.UITable_7 = uitable(app.FreqTab);
            app.UITable_7.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_7.RowName = {};
            app.USTab = uitab(app.acoustoOpticsPlotsTab);
            app.USTab.Title = 'US';
            app.axUsSignal = uiaxes(app.USTab);
            title(app.axUsSignal, 'Title')
            xlabel(app.axUsSignal, 'X')
            ylabel(app.axUsSignal, 'Y')
            app.ReshapedTab = uitab(app.acoustoOpticsPlotsTab);
            app.ReshapedTab.Title = 'Reshaped';
            app.axReshape = uiaxes(app.ReshapedTab);
            title(app.axReshape, 'Title')
            xlabel(app.axReshape, 'X')
            ylabel(app.axReshape, 'Y')
            app.SignalTab = uitab(app.acoustoOpticsPlotsTab);
            app.SignalTab.Title = 'Net';
            app.axNetSignal = uiaxes(app.SignalTab);
            title(app.axNetSignal, 'Title')
            xlabel(app.axNetSignal, 'X')
            ylabel(app.axNetSignal, 'Y')
            app.DeMulTab = uitab(app.acoustoOpticsPlotsTab);
            app.DeMulTab.Title = 'DeMul';
            app.axDeMul = uiaxes(app.DeMulTab);
            title(app.axDeMul, 'Title')
            xlabel(app.axDeMul, 'X')
            ylabel(app.axDeMul, 'Y')
            app.MeasSigTab = uitab(app.acoustoOpticsPlotsTab);
            app.MeasSigTab.Title = 'Meas. Sig.';
            app.axMeasSig = uiaxes(app.MeasSigTab);
            title(app.axMeasSig, 'Title')
            xlabel(app.axMeasSig, 'X')
            ylabel(app.axMeasSig, 'Y')
            app.ExtClkTab = uitab(app.acoustoOpticsPlotsTab);
            app.ExtClkTab.Title = 'Ext. Clk';
            app.axExtClk = uiaxes(app.ExtClkTab);
            title(app.axExtClk, 'Title')
            xlabel(app.axExtClk, 'X')
            ylabel(app.axExtClk, 'Y')
            app.CroppedTab = uitab(app.acoustoOpticsPlotsTab);
            app.CroppedTab.Title = 'Cropped';
            app.axCropped = uiaxes(app.CroppedTab);
            title(app.axCropped, 'Title')
            xlabel(app.axCropped, 'X')
            ylabel(app.axCropped, 'Y')
            app.InvisiblePlotTabs = uitabgroup(app.AOIUIFigure);
            app.InvisiblePlotTabs.Visible = 'off';
            app.Tab = uitab(app.InvisiblePlotTabs);
            app.Tab.Title = 'Tab';
            app.PhiTabGroup = uitabgroup(app.AOIUIFigure);
            app.PhiTab = uitab(app.PhiTabGroup);

            app.PhiTab.Title = 'Phi';
            app.axPhi = uiaxes(app.PhiTab);
            title(app.axPhi, 'Title')
            xlabel(app.axPhi, 'X')
            ylabel(app.axPhi, 'Y')
            app.PhiRawTab = uitab(app.PhiTabGroup);
            app.PhiRawTab.Title = 'Phi (Raw)';
            app.axRawPhi = uiaxes(app.PhiRawTab);
            title(app.axRawPhi, 'Title')
            xlabel(app.axRawPhi, 'X')
            ylabel(app.axRawPhi, 'Y')
            app.PhiLogTab = uitab(app.PhiTabGroup);
            app.PhiLogTab.Title = 'Phi (Log)';
            app.axPhiLog = uiaxes(app.PhiLogTab);
            title(app.axPhiLog, 'Title')
            xlabel(app.axPhiLog, 'X')
            ylabel(app.axPhiLog, 'Y')
            app.FFTTabGroup = uitabgroup(app.AOIUIFigure);
            app.FinalSpectrumTab = uitab(app.FFTTabGroup);
            app.FinalSpectrumTab.Title = 'Final Spectrum';
            app.axFittedFFT = uiaxes(app.FinalSpectrumTab);
            title(app.axFittedFFT, 'Title')
            xlabel(app.axFittedFFT, 'X')
            ylabel(app.axFittedFFT, 'Y')
            app.FittedPowerFFTTab = uitab(app.FFTTabGroup);
            app.FittedPowerFFTTab.Title = 'Fitted Power FFT';
            app.axFittedPowerFFT = uiaxes(app.FittedPowerFFTTab);
            title(app.axFittedPowerFFT, 'Title')
            xlabel(app.axFittedPowerFFT, 'X')
            ylabel(app.axFittedPowerFFT, 'Y')
            app.FittingModelTab = uitab(app.FFTTabGroup);
            app.FittingModelTab.Title = 'Fitting Model';
            app.axFittingModel = uiaxes(app.FittingModelTab);
            title(app.axFittingModel, 'Title')
            xlabel(app.axFittingModel, 'X')
            ylabel(app.axFittingModel, 'Y')
            app.RawFFTTab = uitab(app.FFTTabGroup);
            app.RawFFTTab.Title = 'Raw FFT';
            app.axRawFFT = uiaxes(app.RawFFTTab);
            title(app.axRawFFT, 'Title')
            xlabel(app.axRawFFT, 'X')
            ylabel(app.axRawFFT, 'Y')
            app.CalibrationSpectrumTab = uitab(app.FFTTabGroup);
            app.CalibrationSpectrumTab.Title = 'Calibration Spectrum';
            app.axCalibration = uiaxes(app.CalibrationSpectrumTab);
            title(app.axCalibration, 'Title')
            xlabel(app.axCalibration, 'X')
            ylabel(app.axCalibration, 'Y')
            % Show the figure after all components are created
            app.AOIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AOGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AOIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AOIUIFigure)
        end
    end
end